classdef fftAnalysisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        FsValueSpinner                 matlab.ui.control.Spinner
        FsValueSpinnerLabel            matlab.ui.control.Label
        totalMovementCount             matlab.ui.control.Label
        currentMovementCountIndex      matlab.ui.control.Label
        movementCounterOfLabel         matlab.ui.control.Label
        SectionNameDropDown            matlab.ui.control.DropDown
        SectionNameDropDownLabel       matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        PreviousFileButton             matlab.ui.control.Button
        NextFileButton                 matlab.ui.control.Button
        DataSelectorButtonGroup        matlab.ui.container.ButtonGroup
        DataChannelLabelsDropDown      matlab.ui.control.DropDown
        ClassificationLabelsDropDown_2Label  matlab.ui.control.Label
        ClassificationSelectorButtonGroup  matlab.ui.container.ButtonGroup
        ClassificationLabelsDropDown   matlab.ui.control.DropDown
        ClassificationLabelsDropDownLabel  matlab.ui.control.Label
        ClassifyButton                 matlab.ui.control.Button
        MetricSelectorButtonGroup      matlab.ui.container.ButtonGroup
        SelectResponseAmplitudeButton  matlab.ui.control.Button
        SelectPeakVelocityButton       matlab.ui.control.Button
        SelectLatencyButton            matlab.ui.control.Button
        EyeSelectorButtonGroup         matlab.ui.container.ButtonGroup
        RightEyeButton                 matlab.ui.control.ToggleButton
        BinocularButton                matlab.ui.control.ToggleButton
        LeftEyeButton                  matlab.ui.control.ToggleButton
        MetricTable                    matlab.ui.control.Table
        nextMovementButton             matlab.ui.control.Button
        previousMovementButton         matlab.ui.control.Button
        LoadDataButton                 matlab.ui.control.Button
        totalFileCountLabel            matlab.ui.control.Label
        currentFileIndexLabel          matlab.ui.control.Label
        ofLabel                        matlab.ui.control.Label
        CurrentFileSelectionLabel      matlab.ui.control.Label
        BNVPlot                        matlab.ui.control.UIAxes
        PPCSCIPlot                     matlab.ui.control.UIAxes
        SingleFileFFT                  matlab.ui.control.UIAxes
        HorizontalSectionPlot          matlab.ui.control.UIAxes
    end

    properties (Access = private)
        currentEyeSelectionIndex = 3; % Default Eye Selection Index (Binocular) 
        currentFileIndex = 1; % Default Current File Index
        currentFileSectionNames % Table Variable Names Properties
        currentLoadedData % Current Sub-Table Loaded from Main Table
        currentMetrics % Child of TableOfMetrics singular table entry 
        currentTableOfMetrics % Loaded Table for all Metric Analysis of File
        currentMovementIndex = 1; % Default Current Movement Index
        currentSectionIndex = 1; % Current Position within Table Data
        currentTableData % Presently Loaded Table Data
        dataLoadedBoolean = false; % Default Load Boolean
        mapSectionNameSectionCount; % Map/Value of Section Name to Counts
        mapDataChannelClassification; % Map/Value of Channel to Classification
        nextDataFileButtonPressed = false; % Default Selection Boolean 
        nextMovementButtonPressed = false; % Default Selection Boolean
        previousDataFileButtonPressed = false; % Default Selection Boolean
        previousMovementButtonPressed = false; % Default Selection Boolean
        totalSelectedFiles % Default Total Selected File Objects
        totalCurrentMovements % Maximum Movements for Section Observed
        userSelectedFilePath % User Selected Filepath for Analysis
        userSelectedFile % User Selected Filename(s) for Analysis Cell Array

        ensembleBinocularPlot = {}; % Empty Cell for Ensemble Binocular Plotting
        ensembleLeftPlot = {}; % Empty Cell for Ensemble Left Plotting
        ensembleRightPlot = {}; % Empty Cell for Ensemble Right Plotting
        %
        eyeSelectionMap % Map between button groups and row index
        maxSamples
        % horizontalExtractedMovementDataTable %keeping horizontal data present for section & individual data
        % pupilExtractedMovementDataTable % keeping pupil data present for section & individual data
        selectedEyeFiles
        selectedFiles
        controlFiles
        patientFiles
        patientMovementData
        controlMovementData
        patientMovementAverageData
        controlMovementAverageData
        frequency
    end

    methods (Access = private)
        % Plotting Movement Data (Ensemble, Average, etc.)
        function plotData(app)
            % Delete existing plots
            delete(app.ensembleBinocularPlot)
            delete(app.ensembleLeftPlot)
            delete(app.ensembleRightPlot)

            app.ensembleBinocularPlot = {}; 
            app.ensembleLeftPlot = {}; 
            app.ensembleRightPlot = {}; 

            [binocularMovementData,leftMovementData,rightMovementData] = app.extractMovementData;

            plotBinocularData = binocularMovementData;
            plotLeftData = leftMovementData;
            plotRightData = rightMovementData;
            alphaValue = .1;
            lineWidthValue = .5;

            % Ensure Tick Axes are Auto
            xticks(app.EyeMovementFigure,'auto');
            yticks(app.EyeMovementFigure,'auto');

            
            switch app.currentEyeSelectionIndex
                case 1
                    % Plot Binocular
                    app.ensembleBinocularPlots = plot(app.HorizontalSectionPlot,plotBinocularData .* binocularGain,'Color', [0, 0, 1, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
                case 2
                    % Plot Left
                    app.ensembleLeftPlots = plot(app.HorizontalSectionPlot,plotLeftData .* leftEyeGain,'Color', [0, 1, 0, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
                case 3
                    % Plot Right
                    app.ensembleRightPlots = plot(app.HorizontalSectionPlot,plotRightData .* rightEyeGain,'Color', [1, 0, 0, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
            end
        end

        % % Extract movement data from table data %6/17/2024 do not need to extract horizontal and or pupil data
        % function [horizontalExtractedMovementData, pupilExtractedMovementData] = extractMovementData(app)
        % 
        %     % Set default values
        %     extractedMovementData = [];
        % 
        %     % Extract Non-empty cells (cells with movement data)
        %     selectedMovementData = {app.currentTableData.(app.SectionNameDropDown.Value){~cellfun(@isempty,app.currentTableData.(app.SectionNameDropDown.Value))}}';
        % 
        %     % Pull out streams
        %     %edit 6/3/2024, made app.horizontalExtractedMovementData
        %     %horizontalExtractedMovementData =
        % app.tableDataExtractor(selectedMovementData,strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex)," ", "Average")); %07/23/2024
        %     %app.horizontalExtractedMovementDataTable = horizontalExtractedMovementData;
        %     %above line might not be needed
        %     %pupilExtractedMovementData = app.tableDataExtractor(selectedMovementData,strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ", "Pupil")); %commented out 6/17/2024
        %     %app.pupilExtractedMovementDataTable = pupilExtractedMovementData;
        %     %above line might not be needed
        % end
        % 
        % 
        % function extractedTableData = tableDataExtractor(app, cellTableArray, extractionHeader)
        % %     % Determine Largest Sample Length for Plotting
        % % 
        % %     maxSamplesList = cellfun(@max,cellfun(@size,cellTableArray,'UniformOutput',false));
        % %     %app.maxSamples = max(maxSamplesList);%commented out 6/17/2024
        % % 
        % % 
        % %     % Preallocate Space 
        % %     % Number of Rows based on First Cell Array Value
        % %     % Number of Cols based on Size of Cell Array
        %     extractedTableData = nan(app.maxSamples,size(cellTableArray,1));
        %     for cellIndex = 1:size(cellTableArray,1) 
        %         % % Determine if Classification of Specified Data
        %         % % Channel is Good
        %         % if((app.currentTableOfMetrics{cellIndex, app.SectionNameDropDown.Value}{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(app.currentEyeSelectionIndex)) ~= 0) 
        %         extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = cellTableArray{cellIndex}.(extractionHeader);
        %         % end
        %     end       
        % end

        function FFTValues = createFFTData(app, extractedData)
            fouriervalues = fft(extractedData);
            tempfourier = abs(fouriervalues/app.maxSamples); % the fft function includes a scaling factor of the L (number of samples) between original and transformed signals, tempfourier involves rescaling the values by dividing by the number of samples present % abs taken due to fft providing complex values (want only magnitude, not phase) % tempfourier is the two-sided amplitude spectrum
            FFTValues = tempfourier(1:(app.maxSamples/2)+1); % ensures only positive frequencies will be plotted
            FFTValues(2:end-1) = 2* FFTValues(2:end-1); % spectrum in positive frequencies multiplied by 2, first and end values are zero and nyquist frequencies (do not have complex conjugate pair in negative frequency). We multiply by two so that original signal energy is shown (positive frequencies showing half the signal energy and negative frequncies showing the other half)
        end

        % % Create table of NaN tables
        % function metricTable = createMetricTable(app)
        %     % Returns # of tables in each variable of tableData
        %     sectionInstances = values(app.mapSectionNameSectionCount);
        % 
        %     % Creating empty table with same dimensions as tableData
        %     metricTable = array2table(cell(max([sectionInstances{:}]), size(sectionInstances, 2)), "VariableNames",keys(app.mapSectionNameSectionCount));
        % 
        %     % Iterates across sectionInstances (Columns)
        %     for sectionIndex = 1:size(sectionInstances, 2)
        % 
        %         % Iterates down number of movements within tableData
        %         for movementIndex = 1:sectionInstances{sectionIndex} 
        %             % Assign metricTable empty NaN for processing
        %             metricTable{movementIndex, sectionIndex} = {array2table(NaN(3,size(app.MetricTable.ColumnName, 1)), "VariableNames", app.MetricTable.ColumnName')}; 
        % 
        %         end
        %     end
        % end

        % % Creates directory for metric table saving
        % function metricTablePath = createMetricTableDirectory(~, filepath)
        %   % Replace Key Processing Words with "ANALYSIS_METRICS"
        %     metricTablePath = strrep(filepath,"FILTERED_CALIBRATED","ANALYSIS_METRICS");
        %   %check if directory does not exist as - seen within
        %   %stepAnalysisModule
        % end

        % Create Map Container to Map Keys (Section Names) to Values
        % (Movement Counts per Section Name)
        % function createSectionNameMap(app)
        %     % Iterate over each cell in Variable Name
        %     sectionLogicalCells = cellfun(@(tableVariableName) cellfun(@(sectionData) ~isempty(sectionData),app.currentTableData.(tableVariableName),'UniformOutput',false),app.currentFileSectionNames,'UniformOutput',false);
        %     sectionMovementCounts = cellfun(@(logicalCell) sum(cell2mat(logicalCell),1), sectionLogicalCells); %will change determining counts to size of function, logical cells might not be necessary 
        %     % Map Keys - Section Names to Values - Section Movement Counts 
        %     app.mapSectionNameSectionCount = containers.Map(app.currentFileSectionNames,sectionMovementCounts);
        % end

        % Create NaN table of tables for metric analysis
        % function findMetricTable(app,filePath)
        %     % Retrieve FileName
        %     fileName = app.returnFileName;
        % 
        %     % Check if table exists
        %     if(isfile(fullfile(app.createMetricTableDirectory(filePath),fileName)))
        %         loadedMetricTableObject = load(fullfile(app.createMetricTableDirectory(filePath),fileName)); %changed so app.currentTableOfMetrics already finds importedData 6/10/2024
        %         app.currentTableOfMetrics = loadedMetricTableObject.importedData;
        %         app.loadMetricTableData % added 6/12/2024
        %     % Else create NaN metric table
        %     else
        %         app.currentTableOfMetrics = app.createMetricTable;
        %     end
        % end
        
        % Load Next Movement Data within Current Table Data
        function getNextMovement(app)
            % Evaluate Button Presses
            % Expected Next Button Pressed
            if(app.nextMovementButtonPressed)
                % Check Boundaries and Roll Over to 1 if reached
                if(app.currentMovementIndex + 1 <= app.totalCurrentMovements)
                    app.currentMovementIndex = app.currentMovementIndex + 1;
                else
                    app.currentMovementIndex = 1;
                end    

            % Else Previous Button Pressed
            else
                % Check Boundaries and Roll Over to Last File if reached
                if(app.currentMovementIndex - 1 >= 1)
                    app.currentMovementIndex = app.currentMovementIndex - 1;
                else
                    app.currentMovementIndex = app.totalCurrentMovements;
                end
            end

            % Load Subsection of Table Data
            app.loadSectionOfData;

            % Update Metric Table for New Movement Index
            %app.loadMetricTableData;

            % Update Labels
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
        end

        %function getSectionNames(app) %might not be necessary 6/13/2024
            % Load Current Table Variable Names
            %app.currentFileSectionNames = (app.userSelectedFilePath); % section names are file names (left eye, right eye, etc.) 6/18/2024    
            % Update Dropdown Items
            %app.SectionNameDropDown.Items = app.userSelectedFilePath;
            % Set Default Value to First Item
            %app.SectionNameDropDown.Value = app.userSelectedFilePath; %
            %error with these lines 7/22/2024
            % Map Section Names and Movement Counts
            %app.createSectionNameMap;
        %end

        % Table Data FILE Pathing Retrieval
        function getTablePathing(app)
            % Button was Pressed and App has data
            if(~strcmp(app.CurrentFileSelectionLabel.Text,'Current File Selection'))
                % Check Button Press (Next Default)
                if(app.nextDataFileButtonPressed)
                    % Check Boundaries and Roll Over to 1 if reached
                    if(app.currentFileIndex + 1 <= app.totalSelectedFiles)
                        app.currentFileIndex = app.currentFileIndex + 1;
                    else
                        app.currentFileIndex = 1;
                    end
                % Check Previous Button Press
                elseif(app.previousDataFileButtonPressed)
                    % Check Boundaries and Roll Over to Last File if reached
                    if(app.currentFileIndex - 1 >= 1)
                        app.currentFileIndex = app.currentFileIndex - 1;
                    else
                        app.currentFileIndex = app.totalSelectedFiles;
                    end
                % Section Name Change 
                else
                    app.currentFileIndex = 1;
                end
                
                % Update Labels
                app.currentFileIndexLabel.Text = string(app.currentMovementIndex);

            % If no Button Pressed Load via Prompt
            else
                % Select File Pathway and File(s) to Process
                [app.userSelectedFilePath, app.userSelectedFile] = uigetfile('..\REVAMP\revampDATA\t.fig','MultiSelect','on');% changed from calling loadData to uigetfile 6/13/2024 %might need to switch file and file path names in which selected file path is names
                % Proceed If Load Successful
                if(~isempty(app.userSelectedFile))
                    % Populate Default Count Variable
                    app.currentFileIndex = 1;
                    app.currentMovementIndex = 1;%movementIndex is CI patient file index

                    % Evaluate for Single or Multi-File Selection
                    % %6/13/2024 MultiFile Selection not working
                    if(ischar(app.userSelectedFilePath)) %changed to filepath
                        app.totalSelectedFiles = 1;
                    else
                        app.totalSelectedFiles = size(app.userSelectedFilePath,2);
                    end
                    % Set Labels
                    app.currentFileIndexLabel.Text = string(app.currentMovementIndex);
                    app.totalFileCountLabel.Text = string(app.totalSelectedFiles); 

                    % Load Table Data %load participant & control data seperately
                    app.loadTableData; 
                    % Update Load Data Boolean
                    app.dataLoadedBoolean = true;
                end
            end
        end


        function loadTableData(app) % use this as stem for plotting + additional filters?
            % % Load Current Table Path and Filename % commented out 7/22/2024
            % if(ischar(app.userSelectedFilePath)) % single file
            %     %app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile);%commentedout 6/13/2024, not using loadData.loadTableData
            %     currentFigure = openfig(app.userSelectedFilePath);
            %     % Update Labels
            %     app.CurrentFileSelectionLabel.Text = app.userSelectedFilePath;
            % else %multiple files % 7/15/2024 goal make a table of all files
            %     % app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile{app.currentFileIndex});
            %     currentFigure = openfig(char(app.userSelectedFilePath(app.currentMovementIndex)));
            %     % Update Labels
            % app.CurrentFileSelectionLabel.Text = app.userSelectedFilePath{app.currentMovementIndex};
            % end
            % currentFigureChildren = currentFigure.Children;
            % currentFigureData = currentFigureChildren.Children;
            % currentFigureDataLine = currentFigureData(1:end-1);
            % %currentFigureDataTag = get(currentFigureData,'Tag');
            % app.currentTableData = get(currentFigureDataLine,'YData');

            % %Load Map of Section Names and Expected Counts % section commented out 6/13/2024
            %app.getSectionNames; 
            % % Get Metric Table (New if Does Not Exist or Returns File if
            % % Exists)
            % app.findMetricTable(app.userSelectedFilePath);
            % % Load in Metric Table File
            % app.loadMetricTableData; 
            % % Load in Section Data of File (Default)
            %Determine control and patient files, along with the eye
            app.selectedEyeFiles = app.userSelectedFilePath(~(cellfun('isempty',strfind(app.userSelectedFilePath,app.eyeSelectionMap(app.currentEyeSelectionIndex))))); %select files based on left, right, or binocular selection
            app.controlFiles = app.selectedEyeFiles(~(cellfun('isempty',strfind(app.selectedEyeFiles,'Control')))); %determine control & patient files
            app.patientFiles = app.selectedEyeFiles(cellfun('isempty',strfind(app.selectedEyeFiles,'Control'))); % move into loading data section & save data for later FFT application

            app.patientMovementData = app.loadedInData(app.patientFiles); % extraction of data via use of file name & method of ensuring all files are the same size (for cohort level plots)
            app.controlMovementData = app.loadedInData(app.controlFiles);

            app.selectedFiles = horzcat(app.patientMovementData,app.controlMovementData); %for single FFTs plots
            app.selectedEyeFiles = horzcat(app.patientFiles,app.controlFiles);

            app.patientMovementAverageData = mean(app.patientMovementData,2,'omitnan');
            app.controlMovementAverageData = mean(app.controlMovementData,2,'omitnan');

            app.loadSectionOfData;
            
        end

        function loadedInData = loadedInData(app,fileNames) %7/15/2024 creates movement data table
            loadedInData = cell(1,size(fileNames,2));
            
            for index = 1:size(fileNames,2) % for loop to pull data values from figures
                currentFigure = openfig(char(fileNames(index)));
                currentFigureChildren = currentFigure.Children;
                currentFigureData = currentFigureChildren.Children;
                currentFigureDataLine = currentFigureData(1:end-1);
                tempData = get(currentFigureDataLine,'YData');
                loadedInData(index) = {cell(size(tempData,2),1)};
                loadedInData{1,index} = tempData';
            end

            MaxSamplesList = cellfun(@max,cellfun(@size,loadedInData,'UniformOutput',false)); % this and next for loop ensure all files are of same size (make into seperate function / neeed to determine averages seperately)
            MinSamples = min(MaxSamplesList);
            for index = 1:size(loadedInData,2)
            loadedInData{1,index}(MinSamples+1:end)=[];
            end

            MaxSamplesList = cellfun(@max,cellfun(@size,loadedInData,'UniformOutput',false)); % convert to table
            maxData= max(MaxSamplesList); % used max samples elsewhere
            loadedInDataTable = zeros(maxData,size(loadedInData,2));
            for aIndex = 1:size(loadedInData,2)
                loadedtempData = loadedInData{1,aIndex};
                loadedInDataTable(:,aIndex) = loadedtempData(1:end);
            end
            loadedInData = loadedInDataTable;
        end

        % % Load Metric Table Based on Current Movement Index (Row) and
        % % Section Index (Column)
        % function loadMetricTableData(app)
        %    app.currentMetrics = app.currentTableOfMetrics{app.currentMovementIndex,app.currentSectionIndex}; % 6/5/2024 removed .importedData 
        %    app.MetricTable.Data = app.currentMetrics{:};
        % end

        function loadSectionOfData(app) 
            % if contains(app.CurrentFileSelectionLabel.Text,'Average','IgnoreCase',true) %loading data for average files 
            %     app.currentLoadedData = (app.currentTableData)'; %got rid of section index
            %     app.maxSamples = size(app.currentLoadedData,1);
            % 
            % else % loading data for ensemble files
            %     for columnIndex = 1:size(app.currentTableData,1) %re-format data for plotting
            %         app.currentTableData{columnIndex,1} = (app.currentTableData{columnIndex,1})';
            %     end
            %     maxSamplesList = cellfun(@max,cellfun(@size,app.currentTableData,'UniformOutput',false)); % determine maxSamples present
            %     app.maxSamples = max(maxSamplesList);
            %     app.currentLoadedData = app.createEnsembleAverageData(app.currentTableData);
            % end
            app.maxSamples = size(app.selectedFiles(:,1),1); % assume all files same size
            app.currentLoadedData = app.selectedFiles(:,app.currentMovementIndex); % single file iteration based on movement button
            app.CurrentFileSelectionLabel.Text = app.selectedEyeFiles{app.currentMovementIndex}; % file name
            app.frequency = app.FsValueSpinner.Value/app.maxSamples.*(0:(app.maxSamples/2)); %Calculation results in frequency based on sampling rate, provides values from 0 to the Nyquist frequency (Fs/2)
            app.updateBNVPlot
            app.updatePPCSCIPlot
            app.updateMovementPlot; %have individual file fft
            app.updateSectionPlot; % have averages & difference
        end

        function createAverageData = createEnsembleAverageData(app, currentTableData) % creates average from ensemble files % still need to make seperate filter for ensemble files
                maxSamplesList =  cellfun(@max,cellfun(@size,currentTableData,'UniformOutput',false)); % determine maxSamples present    
                tempMaxSamples = max(maxSamplesList);
                extractedEnsembleData = zeros(tempMaxSamples,size(currentTableData,1)); %Pre-Allocate Data
                for fileIndex = 1:size(currentTableData,1) %issue looping
                    extractedEnsembleData(1:maxSamplesList(fileIndex),fileIndex) = currentTableData{fileIndex};
                end
                createAverageData = mean(extractedEnsembleData,2,"omitnan");
        end

       % Determination of frequency & plotting of FFT data to create a magnitude frequency plot
       % 6/18/2024 updating section plot to show total average of all data
        function updateSectionPlot(app)

            % Pull and Plot Averages of All Files
            % for FileIndex = 1:size(app.controlMovementData,2)
            %     temporaryControlData = (app.controlMovementData(1:end,FileIndex));
            %     horizontalSectionData = horizontalSectionData + temporaryHorizontalSectionData; % allows for average to be taken
            % end
            % horizontalSectionData = horizontalSectionData./size(horizontaldata,2); % average horizontal data
            % 
            % for columnIndex = 1:size(pupildata,2)
            %     temporaryPupilSectionData = (pupildata(1:end,columnIndex));
            %     pupilSectionData = pupilSectionData + temporaryPupilSectionData;
            % end
            % pupilSectionData = pupilSectionData./size(pupildata,2); % average pupil data
            
            plot(app.HorizontalSectionPlot, app.frequency, app.createFFTData(app.patientMovementAverageData),'Color','r');
            hold(app.HorizontalSectionPlot,'on')
            plot(app.HorizontalSectionPlot, app.frequency, app.createFFTData(app.controlMovementAverageData),'Color','g');
            plot(app.HorizontalSectionPlot, app.frequency,(app.createFFTData(app.patientMovementAverageData)-app.createFFTData(app.controlMovementAverageData)),'Color','b');
            hold(app.HorizontalSectionPlot,'off')
        end

        function updateBNVPlot(app)
            hold(app.BNVPlot,'on')
            for fftindex = 1:size(app.controlMovementData,2)
                controlFFT = app.createFFTData(app.controlMovementData(:,fftindex));
                plot(app.BNVPlot,app.frequency,controlFFT)
            end
            hold(app.BNVPlot,'off')
        end

        function updatePPCSCIPlot(app)
            hold(app.PPCSCIPlot,'on')
            for fftindex = 1:size(app.patientMovementData,2)
                patientFFT = app.createFFTData(app.patientMovementData(:,fftindex));
                plot(app.PPCSCIPlot,app.frequency,patientFFT)
            end
            hold(app.PPCSCIPlot,'off')
        end

        function updateMovementPlot(app) %added 6/3/2024
           % Pull and Plot Each File
           movementData = app.currentLoadedData;
           singleMovement = app.createFFTData(movementData); 
           plot(app.SingleFileFFT,app.frequency,singleMovement) % currently plots the FFT of data within the file selected
        end

        function fileName = returnFileName(app)
             % Evaluate Single File or Batch
            if(ischar(app.userSelectedFile))
                fileName = app.userSelectedFile;
            else
                fileName = app.userSelectedFile{app.currentFileIndex};
            end
        end

        % Update Labels for New Section
        function resetSectionMovementIndex(app)
            % Load Movement Index
            app.currentMovementIndex = 1;
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
            app.totalCurrentMovements = size(app.selectedEyeFiles,2);%app.mapSectionNameSectionCount(app.SectionNameDropDown.Value); %error 6/18/2024, no value is being give (only 'option 1'), issue with app.mapSectionNameSectionCount %changed 6/18/2024
            app.totalMovementCount.Text = string(app.totalCurrentMovements);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.eyeSelectionMap = containers.Map([1, 2, 3], ["Left", "Right", "Binocular"]); %Changed Combined into Binocular 6/12/2024
            app.mapDataChannelClassification = containers.Map(["Horizontal","Vertical","Pupil"],["HClass","VClass","PClass"]);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Identify Table Pathing to Load
            app.getTablePathing;
            % Reset Labels for Section
            app.resetSectionMovementIndex;
        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
            % Set Boolean for Button Condition
            app.nextDataFileButtonPressed = true;
            % app.getTablePathing;
            % app.resetSectionMovementIndex;
            % app.loadTableData;
            % Reset Button Condition
            app.nextDataFileButtonPressed = false;
        end

        % Button pushed function: nextMovementButton
        function nextMovementButtonPushed(app, event)
            app.nextMovementButtonPressed = true;
            app.getNextMovement;
            app.nextMovementButtonPressed = false;
            app.updateMovementPlot; % 6/3/2024
        end

        % Button pushed function: previousMovementButton
        function previousMovementButtonPushed(app, event)
            app.previousMovementButtonPressed = true;
            app.getNextMovement;
            app.previousMovementButtonPressed = false;
            app.updateMovementPlot; % 6/3/2024
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButtonPushed(app, event)
            app.previousDataFileButtonPressed = true;
            % app.getTablePathing;
            % app.resetSectionMovementIndex
            % app.loadTableData;
            app.previousDataFileButtonPressed = false;
        end

        % Value changed function: SectionNameDropDown
        function SectionNameDropDownValueChanged(app, event)
            app.currentSectionIndex = find(strcmp(app.SectionNameDropDown.Value,keys(app.mapSectionNameSectionCount)));
            app.resetSectionMovementIndex;
            app.loadSectionOfData;
            app.loadMetricTableData;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.dataLoadedBoolean)
                % Retrieve FileName
                fileName = app.returnFileName;
    
                % Save Metric Table Data to ANALYSIS_METRIC Directory
                saveFilePathway = app.createMetricTableDirectory(app.userSelectedFilePath);
                % Check If Directory Does Not Exist
                if(~exist(saveFilePathway,'dir'))
                    mkdir(saveFilePathway);
                end
                saveFunction.saveData(app.MetricTable,fullfile(saveFilePathway,fileName),"-mat");
    
            % Force Data Load
            else
                app.LoadDataButtonPushed;
            end            
        end

        % Selection changed function: EyeSelectorButtonGroup
        function EyeSelectorButtonGroupSelectionChanged(app, event)
           switch app.EyeSelectorButtonGroup.SelectedObject.Text
               case "Left Eye"
                    app.currentEyeSelectionIndex = 1;
               case "Right Eye"
                    app.currentEyeSelectionIndex = 2;
               case "Binocular"
                    app.currentEyeSelectionIndex = 3;
           end
           app.loadTableData;
        end

        % Value changed function: FsValueSpinner
        function FsValueSpinnerValueChanged(app, event)
            app.updateSectionPlot;
            app.updateMovementPlot %6/3/2024
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HorizontalSectionPlot = uiaxes(app.UIFigure);
            title(app.HorizontalSectionPlot, 'PPCS-CI & BNV Average FFT')
            xlabel(app.HorizontalSectionPlot, 'Frequency (Hz)')
            ylabel(app.HorizontalSectionPlot, '|Amplitude|')
            zlabel(app.HorizontalSectionPlot, 'Z')
            app.HorizontalSectionPlot.XLim = [0 5];
            app.SingleFileFFT = uiaxes(app.UIFigure);
            title(app.SingleFileFFT, 'Single File FFT')
            xlabel(app.SingleFileFFT, 'Frequency (Hz)')
            ylabel(app.SingleFileFFT, '|Amplitude|')
            zlabel(app.SingleFileFFT, 'Z')
            app.SingleFileFFT.XLim = [0 5];
            app.PPCSCIPlot = uiaxes(app.UIFigure);
            title(app.PPCSCIPlot, 'PPCS-CI FFT')
            xlabel(app.PPCSCIPlot, 'Frequency(Hz)')
            ylabel(app.PPCSCIPlot, '|Amplitude|')
            zlabel(app.PPCSCIPlot, 'Z')
            app.PPCSCIPlot.XLim = [0 5];
            app.BNVPlot = uiaxes(app.UIFigure);
            title(app.BNVPlot, 'BNV FFT')
            xlabel(app.BNVPlot, 'Frequency (Hz)')
            ylabel(app.BNVPlot, '|Amplitude|')
            zlabel(app.BNVPlot, 'Z')
            app.BNVPlot.XLim = [0 5];
            app.CurrentFileSelectionLabel = uilabel(app.UIFigure);
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            app.ofLabel = uilabel(app.UIFigure);
            app.ofLabel.Text = 'of';
            app.currentFileIndexLabel = uilabel(app.UIFigure);
            app.currentFileIndexLabel.Text = '###';
            app.totalFileCountLabel = uilabel(app.UIFigure);
            app.totalFileCountLabel.Text = '###';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.previousMovementButton = uibutton(app.UIFigure, 'push');
            app.previousMovementButton.ButtonPushedFcn = createCallbackFcn(app, @previousMovementButtonPushed, true);
            app.previousMovementButton.Text = 'PREVIOUS';
            app.nextMovementButton = uibutton(app.UIFigure, 'push');
            app.nextMovementButton.ButtonPushedFcn = createCallbackFcn(app, @nextMovementButtonPushed, true);
            app.nextMovementButton.Text = 'NEXT';
            app.MetricTable = uitable(app.UIFigure);
            app.MetricTable.ColumnName = {'RSI'; 'REI'; 'RA'; 'PVI'; 'PV'; 'FA'; 'RSID'; 'REID'; 'RAD'; 'PDROCI'; 'PDROC'; 'ID'; 'FD'; 'HClass'; 'VClass'; 'PClass'};
            app.MetricTable.RowName = {'Left Eye'; 'Right Eye'; 'Binocular'};
            app.MetricTable.SelectionType = 'row';
            app.EyeSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.EyeSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeSelectorButtonGroupSelectionChanged, true);
            app.EyeSelectorButtonGroup.Title = 'Eye Selector';
            app.LeftEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.LeftEyeButton.Text = 'Left Eye';
            app.BinocularButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.BinocularButton.Text = 'Binocular';
            app.BinocularButton.Value = true;
            app.RightEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.RightEyeButton.Text = 'Right Eye';
            app.MetricSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.MetricSelectorButtonGroup.Title = 'Metric Selector';
            app.SelectLatencyButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectLatencyButton.Text = 'Select Latency';
            app.SelectPeakVelocityButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectPeakVelocityButton.Text = 'Select Peak Velocity';
            app.SelectResponseAmplitudeButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectResponseAmplitudeButton.Text = 'Select Response Amplitude';
            app.ClassificationSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassificationSelectorButtonGroup.Title = 'Classification Selector';
            app.ClassifyButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.ClassifyButton.Text = 'Classify';
            app.ClassificationLabelsDropDownLabel = uilabel(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDownLabel.Text = 'Classification Labels';
            app.ClassificationLabelsDropDown = uidropdown(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDown.Items = {'Good', 'Bad', 'Asymmetrical', 'Transient Saccade', 'Other'};
            app.ClassificationLabelsDropDown.Editable = 'on';
            app.ClassificationLabelsDropDown.Value = 'Good';
            app.DataSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.DataSelectorButtonGroup.Title = 'Data Selector';
            app.ClassificationLabelsDropDown_2Label = uilabel(app.DataSelectorButtonGroup);
            app.ClassificationLabelsDropDown_2Label.Text = 'Data Channel Labels';
            app.DataChannelLabelsDropDown = uidropdown(app.DataSelectorButtonGroup);
            app.DataChannelLabelsDropDown.Items = {'Horizontal', 'Vertical', 'Pupil'};
            app.DataChannelLabelsDropDown.Editable = 'on';
            app.DataChannelLabelsDropDown.Value = 'Horizontal';
            app.NextFileButton = uibutton(app.UIFigure, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.UIFigure, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButtonPushed, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SectionNameDropDownLabel = uilabel(app.UIFigure);
            app.SectionNameDropDownLabel.Text = 'Section  Name';
            app.SectionNameDropDown = uidropdown(app.UIFigure);
            app.SectionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionNameDropDownValueChanged, true);
            app.movementCounterOfLabel = uilabel(app.UIFigure);
            app.movementCounterOfLabel.Text = 'of';
            app.currentMovementCountIndex = uilabel(app.UIFigure);
            app.currentMovementCountIndex.Text = '###';
            app.totalMovementCount = uilabel(app.UIFigure);
            app.totalMovementCount.Text = '###';
            app.FsValueSpinnerLabel = uilabel(app.UIFigure);
            app.FsValueSpinnerLabel.Text = 'Fs Value';
            app.FsValueSpinner = uispinner(app.UIFigure);
            app.FsValueSpinner.ValueChangedFcn = createCallbackFcn(app, @FsValueSpinnerValueChanged, true);
            app.FsValueSpinner.Value = 500;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = fftAnalysisModule

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end