classdef Real_analysis_template < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SectionNameDropDown            matlab.ui.control.DropDown
        SectionNameDropDownLabel       matlab.ui.control.Label
        SaveButton                     matlab.ui.control.Button
        PreviousFileButton             matlab.ui.control.Button
        NextFileButton                 matlab.ui.control.Button
        DataSelectorButtonGroup        matlab.ui.container.ButtonGroup
        DataChannelLabelsDropDown      matlab.ui.control.DropDown
        ClassificationLabelsDropDown_2Label  matlab.ui.control.Label
        ClassificationSelectorButtonGroup  matlab.ui.container.ButtonGroup
        ClassificationLabelsDropDown   matlab.ui.control.DropDown
        ClassificationLabelsDropDownLabel  matlab.ui.control.Label
        ClassifyButton                 matlab.ui.control.Button
        MetricSelectorButtonGroup      matlab.ui.container.ButtonGroup
        SelectResponseAmplitudeButton  matlab.ui.control.Button
        SelectPeakVelocityButton       matlab.ui.control.Button
        SelectLatencyButton            matlab.ui.control.Button
        EyeSelectorButtonGroup         matlab.ui.container.ButtonGroup
        LeftEyeButton                  matlab.ui.control.ToggleButton
        RightEyeButton                 matlab.ui.control.ToggleButton
        CombinedButton                 matlab.ui.control.ToggleButton
        MetricTable                    matlab.ui.control.Table
        NEXTButton                     matlab.ui.control.Button
        PREVIOUSButton                 matlab.ui.control.Button
        LoadDataButton                 matlab.ui.control.Button
        totalFileCountLabel            matlab.ui.control.Label
        currentFileIndexLabel          matlab.ui.control.Label
        ofLabel                        matlab.ui.control.Label
        CurrentFileSelectionLabel      matlab.ui.control.Label
        IndividualVelocityPlot         matlab.ui.control.UIAxes
        VelocityEnsemblePlot           matlab.ui.control.UIAxes
        IndividualMovementPlot         matlab.ui.control.UIAxes
        MovementEnsemblePlot           matlab.ui.control.UIAxes
    end

     properties (Access = private)
        userSelectedFilePath % File Pathway from User Selection
        userSelectedFile % File Name from User Selection

        tableData % Loaded Subject Table Data

        
        selectedMovementData % Movement Data for Movement Plotting

        currentFileIndex = 1; % Position in Stack of Selected Files
        totalSelectedFiles = 1; % Number of Selected Files

        movementLoadDataButtonPressed = false; % Data Movement Button (Default State is False)
        nextDataButtonPressed = false; % Boolean to Evaluate Button Press
        previousDataButtonPressed = false; % Boolean to Evaluate Button Press
        
        maxSamples % Maximum Samples in Movement Data Set Plotted
        dataLoadedBoolean = false; % Boolean Flag for Load Command

        % Table of Gains (Default 1)
        calibrationGainTable;
    end


    methods (Access = private)
        
        function getTablePathing(app)
            % Button was Pressed and App has data
            if(app.movementLoadDataButtonPressed && ~strcmp(app.CurrentFileSelectionLabel.Text,'Current File Selection'))
                % Check Button Press (Next Default)
                if(app.nextDataButtonPressed)
                    % Check Boundaries and Roll Over to 1 if reached
                    if(app.currentFileIndex + 1 <= app.totalSelectedFiles)
                        app.currentFileIndex = app.currentFileIndex + 1;
                    else
                        app.currentFileIndex = 1;
                    end
                else
                    % Check Boundaries and Roll Over to Last File if reached
                    if(app.currentFileIndex - 1 >= 1)
                        app.currentFileIndex = app.currentFileIndex - 1;
                    else
                        app.currentFileIndex = app.totalSelectedFiles;
                    end

                end
            % If no Button Pressed Load via Prompt
            else
                % Select File Pathway and File(s) to Process
                [app.userSelectedFilePath, app.userSelectedFile] = loadData.getTableData();
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Identify Table Pathing to Load
            app.getTablePathing;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.MovementEnsemblePlot = uiaxes(app.UIFigure);
            title(app.MovementEnsemblePlot, 'Ensemble Plot')
            xlabel(app.MovementEnsemblePlot, 'Time (Seconds)')
            ylabel(app.MovementEnsemblePlot, 'Final Position')
            zlabel(app.MovementEnsemblePlot, 'Z')
            app.MovementEnsemblePlot.YAxisLocation = 'right';
            app.IndividualMovementPlot = uiaxes(app.UIFigure);
            title(app.IndividualMovementPlot, 'Movement Plot')
            xlabel(app.IndividualMovementPlot, 'Time (Seconds)')
            ylabel(app.IndividualMovementPlot, 'Final Positoon')
            zlabel(app.IndividualMovementPlot, 'Z')
            app.IndividualMovementPlot.YAxisLocation = 'right';
            app.VelocityEnsemblePlot = uiaxes(app.UIFigure);
            title(app.VelocityEnsemblePlot, 'Average Velocity Plot')
            xlabel(app.VelocityEnsemblePlot, 'Time (Seconds)')
            ylabel(app.VelocityEnsemblePlot, 'Velocity')
            zlabel(app.VelocityEnsemblePlot, 'Z')
            app.IndividualVelocityPlot = uiaxes(app.UIFigure);
            title(app.IndividualVelocityPlot, 'Velocity Plot')
            xlabel(app.IndividualVelocityPlot, 'Time (Seconds)')
            ylabel(app.IndividualVelocityPlot, 'Velocity')
            zlabel(app.IndividualVelocityPlot, 'Z')
            app.CurrentFileSelectionLabel = uilabel(app.UIFigure);
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            app.ofLabel = uilabel(app.UIFigure);
            app.ofLabel.Text = 'of';
            app.currentFileIndexLabel = uilabel(app.UIFigure);
            app.currentFileIndexLabel.Text = '###';
            app.totalFileCountLabel = uilabel(app.UIFigure);
            app.totalFileCountLabel.Text = '###';
            app.LoadDataButton = uibutton(app.UIFigure, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.PREVIOUSButton = uibutton(app.UIFigure, 'push');
            app.PREVIOUSButton.Text = 'PREVIOUS';
            app.NEXTButton = uibutton(app.UIFigure, 'push');
            app.NEXTButton.Text = 'NEXT';
            app.MetricTable = uitable(app.UIFigure);
            app.MetricTable.ColumnName = {'Latency'; 'TimeToPeakVelocity'; 'PeakVelocity'; 'ResponseAmplitude'; 'FinalAmplitude'; 'Classification'};
            app.MetricTable.RowName = {'Combined,LeftEye,RightEye'};
            app.MetricTable.SelectionType = 'row';
            app.EyeSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.EyeSelectorButtonGroup.Title = 'Eye Selector';
            app.CombinedButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.CombinedButton.Text = 'Combined';
            app.CombinedButton.Value = true;
            app.RightEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.RightEyeButton.Text = 'Right Eye';
            app.LeftEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.LeftEyeButton.Text = 'Left Eye';
            app.MetricSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.MetricSelectorButtonGroup.Title = 'Metric Selector';
            app.SelectLatencyButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectLatencyButton.Text = 'Select Latency';
            app.SelectPeakVelocityButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectPeakVelocityButton.Text = 'Select Peak Velocity';
            app.SelectResponseAmplitudeButton = uibutton(app.MetricSelectorButtonGroup, 'push');
            app.SelectResponseAmplitudeButton.Text = 'Select Response Amplitude';
            app.ClassificationSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.ClassificationSelectorButtonGroup.Title = 'Classification Selector';
            app.ClassifyButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.ClassifyButton.Text = 'Classify';
            app.ClassificationLabelsDropDownLabel = uilabel(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDownLabel.Text = 'Classification Labels';
            app.ClassificationLabelsDropDown = uidropdown(app.ClassificationSelectorButtonGroup);
            app.ClassificationLabelsDropDown.Items = {'Good', 'Bad', 'Asymmetrical', 'Transient Saccade', 'Other'};
            app.ClassificationLabelsDropDown.Editable = 'on';
            app.ClassificationLabelsDropDown.Value = 'Good';
            app.DataSelectorButtonGroup = uibuttongroup(app.UIFigure);
            app.DataSelectorButtonGroup.Title = 'Data Selector';
            app.ClassificationLabelsDropDown_2Label = uilabel(app.DataSelectorButtonGroup);
            app.ClassificationLabelsDropDown_2Label.Text = 'Data Channel Labels';
            app.DataChannelLabelsDropDown = uidropdown(app.DataSelectorButtonGroup);
            app.DataChannelLabelsDropDown.Items = {' Horizontal', ' Vertical', ' Pupil'};
            app.DataChannelLabelsDropDown.Editable = 'on';
            app.DataChannelLabelsDropDown.Value = ' Horizontal';
            app.NextFileButton = uibutton(app.UIFigure, 'push');
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.UIFigure, 'push');
            app.PreviousFileButton.Text = 'Previous File';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.Text = 'Save';
            app.SectionNameDropDownLabel = uilabel(app.UIFigure);
            app.SectionNameDropDownLabel.Text = 'Section  Name';
            app.SectionNameDropDown = uidropdown(app.UIFigure);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Real_analysis_template

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end