classdef stepAnalysisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        analysisContainer               matlab.ui.Figure
        NaNifyButton                    matlab.ui.control.StateButton
        TimeScrubberSlider              matlab.ui.control.Slider
        BehaviorTypeButtonGroup         matlab.ui.container.ButtonGroup
        AverageButton                   matlab.ui.control.ToggleButton
        SummationButton                 matlab.ui.control.ToggleButton
        ScaleEnsembleVelocitytoAnalysisCheckBox  matlab.ui.control.CheckBox
        AnalyzedSectionMetricReferencePanel  matlab.ui.container.Panel
        SectionBehaviorTable            matlab.ui.control.Table
        Label                           matlab.ui.control.Label
        SaveIndividualsButton           matlab.ui.control.Button
        MetricSelectorButtonGroup       matlab.ui.container.ButtonGroup
        FAButton                        matlab.ui.control.StateButton
        IDButton                        matlab.ui.control.ToggleButton
        SelectREIButton                 matlab.ui.control.ToggleButton
        SelectPeakVelocityButton        matlab.ui.control.ToggleButton
        SelectRSIButton                 matlab.ui.control.ToggleButton
        SearchWindowSpinner             matlab.ui.control.Spinner
        SearchWindowSpinnerLabel        matlab.ui.control.Label
        SaveEnsemblesButton             matlab.ui.control.Button
        SamplingRateSpinner             matlab.ui.control.Spinner
        SamplingRateSpinnerLabel        matlab.ui.control.Label
        MovementLabel                   matlab.ui.control.Label
        totalMovementCount              matlab.ui.control.Label
        currentMovementCountIndex       matlab.ui.control.Label
        movementCounterOfLabel          matlab.ui.control.Label
        SectionNameDropDown             matlab.ui.control.DropDown
        SectionNameDropDownLabel        matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        PreviousFileButton              matlab.ui.control.Button
        NextFileButton                  matlab.ui.control.Button
        DataSelectorButtonGroup         matlab.ui.container.ButtonGroup
        PlotVersionButton               matlab.ui.control.StateButton
        DataChannelLabelsDropDown       matlab.ui.control.DropDown
        ClassificationLabelsDropDown_2Label  matlab.ui.control.Label
        ClassificationSelectorButtonGroup  matlab.ui.container.ButtonGroup
        ClassificationCommentsDropDown  matlab.ui.control.DropDown
        ClassificationCommentsDropDownLabel  matlab.ui.control.Label
        BadButton                       matlab.ui.control.Button
        GoodButton                      matlab.ui.control.Button
        EyeSelectorButtonGroup          matlab.ui.container.ButtonGroup
        RightEyeButton                  matlab.ui.control.ToggleButton
        BinocularButton                 matlab.ui.control.ToggleButton
        LeftEyeButton                   matlab.ui.control.ToggleButton
        MetricTable                     matlab.ui.control.Table
        nextMovementButton              matlab.ui.control.Button
        previousMovementButton          matlab.ui.control.Button
        LoadDataButton                  matlab.ui.control.Button
        totalFileCountLabel             matlab.ui.control.Label
        currentFileIndexLabel           matlab.ui.control.Label
        ofLabel                         matlab.ui.control.Label
        CurrentFileSelectionLabel       matlab.ui.control.Label
        IndividualVelocityPlot          matlab.ui.control.UIAxes
        VelocityEnsemblePlot            matlab.ui.control.UIAxes
        IndividualMovementPlot          matlab.ui.control.UIAxes
        MovementEnsemblePlot            matlab.ui.control.UIAxes
    end

    properties (Access = private)
        classificationValue % Value for Classification (1 - Good, 0 - Bad)
        currentEyeSelectionIndex = 3; % Default Eye Selection Index (Binocular)
        currentFileIndex = 1; % Default Current File Index
        currentFileSectionNames % Table Variable Names Properties
        currentLoadedData % Current Sub-Table Loaded from Main Table
        currentMetrics % Child of TableOfMetrics singular table entry
        currentMetricSelection = "RSI"; % Default Value to Direct On-Button Press Metric Analysis
        currentMetricTableOffset = 0; % Offset Analysis Result Placement in Metric Table
        currentTableOfMetrics % Loaded Table for all Metric Analysis of File
        currentMovementIndex = 1; % Default Current Movement Index
        currentSectionIndex = 1;% Current Position within Table Data
        currentTableData % Presently Loaded Table Data
        dataLoadedBoolean = false; % Default Load Boolean
        eyeSelectionMap % Map between button groups and row index 
        firstLoadAttemptBool = true; % Boolean to Validate Data Load
        mapSectionNameSectionCount; % Map/Value of Section Name to Counts
        mapDataChannelClassification; % Map/Value of Channel to Classification
        mapMetricIndex; % Map of Current Metrics for Horizontal
        nanPoint1;
        nanPoint2;
        FAPoint1;
        FAPoint2;
        nextDataFileButtonPressed = false; % Default Selection Boolean 
        nextMovementButtonPressed = false; % Default Selection Boolean
        previousDataFileButtonPressed = false; % Default Selection Boolean
        previousMovementButtonPressed = false; % Default Selection Boolean
        previousFigureHandle; % Stores Parent Figure Handle to Call On-Close
        pviXLineMovementTrace % Vertical Line Variable for Metric Visualization (PVI)
        pviXLineVelocityTrace % Vertical Line Variable for Metric Visualization (PVI)
        reiXLineMovementTrace % Vertical Line Variable for Metric Visualization (REI)
        reiXLineVelocityTrace % Vertical Line Variable for Metric Visualization (REI)
        rsiXLineMovementTrace % Vertical Line Variable for Metric Visualization (RSI)
        rsiXLineVelocityTrace % Vertical Line Variable for Metric Visualization (RSI)
        movementVersionTrace % Calculated Variable for Version Comparison
        velocityVersionTrace % Calculated Variable for Version Comparison
        totalSelectedFiles % Default Total Selected File Objects
        totalCurrentMovements % Maximum Movements for Section Observed
        userSelectedFilePath % User Selected Filepath for Analysis
        userSelectedFile % User Selected Filename(s) for Analysis Cell Array        
    end

    methods (Access = private)

        % Format Tag Message for Plotting
        function tagMessage = formatTagMessage(app, rawMessage)
            % Default Selects Current Eye Selection as Tag Message
            tagMessage = app.eyeSelectionMap(app.currentEyeSelectionIndex);

            % If Not Empty Concatenate Tag Message
            if(~isempty(rawMessage))
                tagMessage = strcat(tagMessage, " ", rawMessage);
            end
        end

        % Set Axes for Plotting Data of Given Size
        function formatPlotAxis(app, figureHandle, dataSize)
            % Set Xlim Based on Sampling Rate
            xlim(figureHandle,[0,(dataSize/app.SamplingRateSpinner.Value)])

            % Ensure Tick Axes are Auto
            xticks(figureHandle,'auto');
            yticks(figureHandle,'auto');
        end

        % Plotting Movement Data (Ensemble, Average, etc.)
        function plotData(app,specificFigureHandle, plotData, isAverageTrace, currentEyeIndex, tagMessage)

            % Format Target Axis
            app.formatPlotAxis(specificFigureHandle,round(size(plotData,1)));

            % Time Vector
            timeVector = [linspace(0,size(plotData,1)/app.SamplingRateSpinner.Value,size(plotData,1))];

            % Generate Color and Line Width Formatting
            [colorVal,lineWidthValue] = app.formatPlotColor(currentEyeIndex, isAverageTrace);

            % Attempt to Delete Old Trace(s)
            app.deleteTrace(specificFigureHandle,tagMessage);

            % Plot Data
            plot((specificFigureHandle),timeVector,plotData,'Color', colorVal,'LineWidth',lineWidthValue,'Tag',tagMessage);

            % Hold Axis On for Future Plotting
            hold(specificFigureHandle,"on");
        end

        % Determine Plotting Color
        function [colorValue, lineWidth] = formatPlotColor(~, currentEyeMovementIndex, averageOverrideBoolean)
            % Set Default Color and Transparency (Alpha), LineWidth
            alphaValue = .5;
            lineWidth = .5;

            % Determine Eye Color
            switch currentEyeMovementIndex
                % Left - Green
                case 1
                    colorValue = [0, 1, 0, alphaValue];
                % Right - Red
                case 2
                    colorValue =  [1, 0, 0, alphaValue];
                % Binocular - Blue
                case 3
                    colorValue = [0, 0, 1, alphaValue];
            end

            % Override Color if AverageTrace
            if(averageOverrideBoolean)
                colorValue = [0, 0, 0, 1];
                lineWidth = 1;
            end
        end

        % Plot Version Data on Individual Plots
        function plotVersion(app, buttonState)
            % Delete Old Traces
            app.deleteTrace(app.IndividualMovementPlot,"MovementVersion")
            app.deleteTrace(app.IndividualVelocityPlot,"MovementVersion")

            % If Button State is TRUE plot Version
            if(buttonState)
                % Time Vector
                timeVector = [linspace(0,round(size(app.createVersionData,1)/app.SamplingRateSpinner.Value),size(app.createVersionData,1))];
                % Plot Version Data
                plot(app.IndividualMovementPlot,timeVector,app.createVersionData,'Color',[0 0 0],"Tag","MovementVersion");
                plot(app.IndividualVelocityPlot,timeVector,app.calculateVelocity(app.createVersionData),'Color',[0 0 0],"Tag","MovementVersion");
            end
        end

        % Calculate Velocity (First-Order Diff)
        function calculatedVelocity = calculateVelocity(app, movementData)
            calculatedVelocity = [zeros(1,size(movementData,2));movmean(diff(movementData),5,1,"omitmissing","Endpoints","shrink")] .* app.SamplingRateSpinner.Value;
        end

        % Replot Ensemble (Upper) Plots
        function replotEnsembles(app)

            % Calculate Average Velocity Trace
            averageVelocity = app.calculateVelocity(mean(app.extractEnsembleMovementData,2,"omitnan"));

            % Calculate Gain to Apply to Average from Analyzed Metrics
            if(~app.ScaleEnsembleVelocitytoAnalysisCheckBox.Value || isnan(app.SectionBehaviorTable.Data(1,end)))
                analysisVelocityGain = 1;
            else
                analysisVelocityGain = app.SectionBehaviorTable.Data(1,end)/max(abs(averageVelocity));
            end

            averageVelocity = averageVelocity .* analysisVelocityGain;

            % Plot Positional First

            % Plot Movement Ensemble
            app.plotData(app.MovementEnsemblePlot,app.extractEnsembleMovementData, false,app.currentEyeSelectionIndex,"MovementEnsemble");
    
            % Plot Movement Ensemble Average
            app.plotData(app.MovementEnsemblePlot,mean(app.extractEnsembleMovementData,2,"omitnan"),true,app.currentEyeSelectionIndex,"MovementAverageEnsemble");
    
            % Plot Y Line for 0 - Axis
            app.plotYLine(app.MovementEnsemblePlot);
           
            % Plot Velocity Second
    
            % Plot Velocity Ensemble
            app.plotData(app.VelocityEnsemblePlot,app.calculateVelocity(app.extractEnsembleMovementData), false,app.currentEyeSelectionIndex,"VelocityEnsemble");
    
            % Plot Velocity Ensemble Average
            app.plotData(app.VelocityEnsemblePlot,averageVelocity,true,app.currentEyeSelectionIndex,"VelocityAverageEnsemble");
    
            % Plot YLine for O - Axis
            app.plotYLine(app.VelocityEnsemblePlot)

            % Recalculate Reference Table
            app.generateSectionStatistics;

            % Reset Nan'er
            app.resetNanIndex;
           
        end

        % Caller Function to Calculate ID/FA
        function calculateFixedMetrics(app)
            app.MetricSelectorButtonGroup.SelectedObject = app.IDButton;
            app.MetricSelectorButtonGroupSelectionChanged;
            % app.MetricSelectorButtonGroup.SelectedObject = app.FAButton;
            % app.MetricSelectorButtonGroupSelectionChanged;
            app.defaultMetricSelector;
        end

        % Make Quick Reference Statistics for the Section
        function generateSectionStatistics(app)

            % Pull Index Pairs to Navigate Metric Table
            responseAmplitudeIndex = app.getMetricPlacement("RA");
            finalAmplitudeIndex = app.getMetricPlacement("FA");
            peakVelocityIndex = app.getMetricPlacement("PV");

            % Extract Non-Empty Tables
            nonEmptyTableCellArray = {app.currentTableOfMetrics.(app.SectionNameDropDown.Value){~cellfun(@isempty,app.currentTableOfMetrics.(app.SectionNameDropDown.Value))}}';

            % Check Classification
            classifiedTableCellArray = nonEmptyTableCellArray(cell2mat(cellfun(@(tableData) tableData{app.currentEyeSelectionIndex,app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value)} == 1,nonEmptyTableCellArray,UniformOutput=false)));

            % If No GOOD Classified Data Exists
            if(isempty(classifiedTableCellArray))
                app.generateSectionMetricTable;
            % If GOOD Classifications Exist
            else
                % Calculate Mean
                colNames = app.SectionBehaviorTable.ColumnName';
                app.SectionBehaviorTable.RowName = {'AVG';'STD'};
                raIndex = find(contains(colNames, "RA","IgnoreCase",true));
                faIndex = find(contains(colNames, "FA","IgnoreCase",true));
                pvIndex = find(contains(colNames, "PV","IgnoreCase",true));
                
                app.SectionBehaviorTable.Data(1,raIndex) = round(mean(cell2mat(cellfun(@(metricTableData) metricTableData{responseAmplitudeIndex(1),responseAmplitudeIndex(2)}, classifiedTableCellArray,'UniformOutput',false)),1,'omitnan'),3);
                app.SectionBehaviorTable.Data(1,faIndex) = round(mean(cell2mat(cellfun(@(metricTableData) metricTableData{finalAmplitudeIndex(1),finalAmplitudeIndex(2)}, classifiedTableCellArray,'UniformOutput',false)),1,'omitnan'),3);
                app.SectionBehaviorTable.Data(1,pvIndex) = round(mean(cell2mat(cellfun(@(metricTableData) metricTableData{peakVelocityIndex(1),peakVelocityIndex(2)}, classifiedTableCellArray,'UniformOutput',false)),1,'omitnan'),3);
    
                % Calculate Standard Deviation
                app.SectionBehaviorTable.Data(2,raIndex) = round(std(cell2mat(cellfun(@(metricTableData) metricTableData{responseAmplitudeIndex(1),responseAmplitudeIndex(2)}, classifiedTableCellArray,'UniformOutput',false)),0,1,'omitnan'),3);
                app.SectionBehaviorTable.Data(2,faIndex) = round(std(cell2mat(cellfun(@(metricTableData) metricTableData{finalAmplitudeIndex(1),finalAmplitudeIndex(2)}, classifiedTableCellArray,'UniformOutput',false)),0,1,'omitnan'),3);
                app.SectionBehaviorTable.Data(2,pvIndex) = round(std(cell2mat(cellfun(@(metricTableData) metricTableData{peakVelocityIndex(1),peakVelocityIndex(2)}, classifiedTableCellArray,'UniformOutput',false)),0,1,'omitnan'),3);
            end


        end

        % Generate NaN Section Behavior Table
        function generateSectionMetricTable(app)
            app.SectionBehaviorTable.Data = nan(2,3);
        end

        % Plot Y Line at 0 for given Handle
        function plotYLine(app, figureHandle)
            % Ensure Trace Does Not Exist
            if(isempty(app.findTraceIndex(figureHandle,"YLine")))
               % Plot YLine for O - Axis
               yline(figureHandle,0,'Color',[0 0 0],'LineStyle','--',"Tag","YLine");
            end
        end

        % Create Version Data
        function versionMovementData = createVersionData(app)
            versionMovementData = (app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(1), " ",app.DataChannelLabelsDropDown.Value)) - app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(2), " ",app.DataChannelLabelsDropDown.Value)))/2;
        end

        % Find Specific Children Tag
        function traceIndex = findTraceIndex(app,specificFigureHandle, specificTraceTag)
            if(~isempty(get(specificFigureHandle.Children,"Tag")))
                traceIndex = find(contains(get(specificFigureHandle.Children,"Tag"),specificTraceTag,"IgnoreCase",true));
            else
                traceIndex = [];
            end
        end

        % Delete Specific Trace from Given Figure
        function deleteTrace(app,specificFigureHandle, specificTraceTag)
            % Find Index
            traceIndex = app.findTraceIndex(specificFigureHandle,specificTraceTag);
            % Target Found then Delete
            if(~isempty(traceIndex))
                delete(specificFigureHandle.Children(traceIndex))
            end
        end

        % Clear Average Traces from Ensembles
        function clearAverageTraces(app)
            app.deleteTrace(app.MovementEnsemblePlot,"Average")
            app.deleteTrace(app.VelocityEnsemblePlot,"Average")
        end

        % Plot Given Data for Combined Plots
        function plotBinocularDataTraces(app,eyeMovementData, specificFigureHandle,tagMessage)
             % Plot Movement Data Left
            app.plotData(specificFigureHandle,eyeMovementData(:,1),false,1,strcat("Left Eye"," ",tagMessage));
                            
            % Plot Movement Data Right
            app.plotData(specificFigureHandle,eyeMovementData(:,2),false,2,strcat("Right Eye"," ",tagMessage));

            % Plot Data Binocular
            app.plotData(specificFigureHandle,eyeMovementData(:,3),false,3,app.formatTagMessage(tagMessage));
        end

        % Replot Individual (Lower) Plots
        function replotIndividuals(app)

            % Get Current Movement Data from Current Table Data
            if(app.currentEyeSelectionIndex ~= 3)
                % Delete Old Traces
                app.deleteTrace(app.IndividualMovementPlot,"MovementData");
                app.deleteTrace(app.IndividualVelocityPlot,"VelocityData");

                % Get Movement Data for Individual Plot
                fullData = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value));
                % Additional Constraint for Time Scrubber: (1:app.TimeScrubberSlider.Value*app.SamplingRateSpinner.Value)
                currentMovementData = fullData(1:app.TimeScrubberSlider.Value*app.SamplingRateSpinner.Value);

                % Plot Movement Data
                app.plotData(app.IndividualMovementPlot,currentMovementData,false,app.currentEyeSelectionIndex,app.formatTagMessage("MovementData"));
    
                % Plot Velocity Data
                app.plotData(app.IndividualVelocityPlot,app.calculateVelocity(currentMovementData),false,app.currentEyeSelectionIndex,app.formatTagMessage("VelocityData"));
            else
                % Eye Selection 1 -> Left Eye
                % Eye Selecton 2 -> Right Eye
                % Eye Selection 3 -> Binocular
                
                % Default Behavior to Plot All Data
                % Index Left
                tempFullData(:,1) = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(1), " ",app.DataChannelLabelsDropDown.Value));
                % Index Right
                tempFullData(:,2) = app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(2), " ",app.DataChannelLabelsDropDown.Value));

                % Additional Constraint for Time Scrubber: (1:app.TimeScrubberSlider.Value*app.SamplingRateSpinner.Value)
                currentMovementData = tempFullData(1:app.TimeScrubberSlider.Value*app.SamplingRateSpinner.Value,:);

                % Regenerate Data if Necessary from Classification Swap
                switch app.BehaviorTypeButtonGroup.SelectedObject.Text
                    case "Summation"
                        % Summation of Left and Right Eye
                        currentMovementData(:,3) = currentMovementData(:,1) - currentMovementData(:,2);
                        currentMovementData(:,2) = -1*currentMovementData(:,2);
                    otherwise
                        % Average of Left and Right Eye
                        currentMovementData(:,3) = mean(currentMovementData(:,1:2),2,'omitmissing');
                end

                % Left Eye and Right Eye Bad Classification
                % Combined Data NaN'd
                if(app.currentMetrics{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(1)==0 && app.currentMetrics{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(2)==0)
                    currentMovementData = currentMovementData .* NaN;
                % Left Eye Classification Bad
                elseif(app.currentMetrics{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(1) == 0)
                    currentMovementData(:,1) = currentMovementData(:,1) .* NaN;
                    currentMovementData(:,3) = currentMovementData(:,2);
                % Right Eye Classification Bad
                elseif(app.currentMetrics{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(2) == 0)
                    currentMovementData(:,2) = currentMovementData(:,2) .* NaN;
                    currentMovementData(:,3) = currentMovementData(:,1);
                end
           
                % Plot Movement Data for Binocular
                app.plotBinocularDataTraces(currentMovementData,app.IndividualMovementPlot,"MovementData");
    
                % Plot Velocity Data Binocular
                app.plotBinocularDataTraces(app.calculateVelocity(currentMovementData),app.IndividualVelocityPlot,"VelocityData");
            end

            % Plot Y Lines
            app.plotYLine(app.IndividualMovementPlot);
            app.plotYLine(app.IndividualVelocityPlot);
            
            % Update Metric Lines on Axes
            app.updateTemporalMarkers;

            % Check Version Traces
            app.PlotVersionButtonValueChanged;

            % Update Metric Selector to Default
            app.defaultMetricSelector;

            % Reset Nan'er
            app.resetNanIndex;
        end

        % Reset NaN Index Points
        function resetNanIndex(app)
            app.nanPoint1 = [];
            app.nanPoint2 = [];
        end

        % Sets RSI to Default Metric Selection 
        function defaultMetricSelector(app)
            % Update Metric Selector
            app.MetricSelectorButtonGroup.SelectedObject = app.SelectRSIButton;
            app.MetricSelectorButtonGroupSelectionChanged;
        end

        % Replots Temporal Metrics onto Individual Plots
        function updateTemporalMarkers(app)

            % Clear Old Lines
            app.clearMarkers;
        
            % Retrieve RSI / RSID
            rsiValue = app.getMetricValue(app.getMetricPlacement("RSI"));

            % Retrieve REI / REID
            reiVal = app.getMetricValue(app.getMetricPlacement("REI"));

            % Retrieve PVI / PDROCI
            pviVal = app.getMetricValue(app.getMetricPlacement("PVI"));

            % Plot if Not NaN
            if(~isnan(rsiValue))
                app.rsiXLineMovementTrace = xline(app.IndividualMovementPlot,rsiValue,"LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","RSI","Tag","rsiLine");
                app.rsiXLineVelocityTrace = xline(app.IndividualVelocityPlot,rsiValue,"LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","RSI","Tag","rsiLine");
            end
            if(~isnan(reiVal))
                app.reiXLineMovementTrace = xline(app.IndividualMovementPlot,reiVal, "LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","REI","Tag","reiLine");
                app.reiXLineVelocityTrace = xline(app.IndividualVelocityPlot,reiVal, "LabelHorizontalAlignment","right","LabelVerticalAlignment","top", "Label","REI","Tag","reiLine");
            end
            if(~isnan(pviVal))
                app.pviXLineVelocityTrace = xline(app.IndividualVelocityPlot,pviVal, "LabelHorizontalAlignment","left","LabelVerticalAlignment","middle", "Label","PVI","Tag","pviLine");
                app.pviXLineMovementTrace = xline(app.IndividualMovementPlot,pviVal, "LabelHorizontalAlignment","right","LabelVerticalAlignment","middle", "Label","PVI","Tag","pviLine");
            end

        end

        % Clear Markers from Plots
        function clearMarkers(app)
                % Delete Old
                app.deleteTrace(app.IndividualMovementPlot,"rsiLine")
                app.deleteTrace(app.IndividualVelocityPlot,"rsiLine")

                app.deleteTrace(app.IndividualMovementPlot,"reiLine")
                app.deleteTrace(app.IndividualVelocityPlot,"reiLine")

                app.deleteTrace(app.IndividualMovementPlot,"pviLine")
                app.deleteTrace(app.IndividualVelocityPlot,"pviLine")
        end

        % Extract movement data from table data
        function extractedMovementData = extractEnsembleMovementData(app)
            
            % Set default values
            extractedMovementData = [];

            % Extract Non-empty cells (cells with movement data)
            selectedMovementData = {app.currentTableData.(app.SectionNameDropDown.Value){~cellfun(@isempty,app.currentTableData.(app.SectionNameDropDown.Value))}}';
            
            % Pull out ALL Data Streams with Good/NaN Classification
            extractedMovementData = app.tableDataExtractor(selectedMovementData,strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value));
        end

        function extractedTableData = tableDataExtractor(app, cellTableArray, extractionHeader)
            % Determine Largest Sample Length for Plotting
            maxSamplesList = cellfun(@max,cellfun(@size,cellTableArray,'UniformOutput',false));
            maxSamples = max(maxSamplesList);

            % Preallocate Space 
            % Number of Rows based on First Cell Array Value
            % Number of Cols based on Size of Cell Array
            extractedTableData = nan(maxSamples,size(cellTableArray,1));
            for cellIndex = 1:size(cellTableArray,1)
                % Determine if Classification of Specified Data Channel is Good
                if((app.currentTableOfMetrics{cellIndex, app.SectionNameDropDown.Value}{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(app.currentEyeSelectionIndex)) ~= 0)
                    % If Binocular Calculate Ensembles
                    if(contains(extractionHeader,"Binocular"))
                        if(strcmp(app.BehaviorTypeButtonGroup.SelectedObject.Text,"Average"))
                            extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = mean(table2array(cellTableArray{cellIndex}(:,find(contains(cellTableArray{cellIndex}.Properties.VariableNames,app.DataChannelLabelsDropDown.Value),2))),2,'omitmissing');
                        else
                            % Invert Right Eye sign for User
                            tempTable = cellTableArray{cellIndex}(:,find(contains(cellTableArray{cellIndex}.Properties.VariableNames,app.DataChannelLabelsDropDown.Value),2));
                            tempTable(:,contains(tempTable.Properties.VariableNames,"Right")) = tempTable(:,contains(tempTable.Properties.VariableNames,"Right")) .* -1;
                            % Capture NaN Index due to Sum NaN = 0
                            removedTraceIndex = sum(isnan(table2array(tempTable)),2)==2;
                            extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = sum(table2array(tempTable),2,'omitmissing');
                            % Replace Sum NaN Zeros with NaN
                            extractedTableData(removedTraceIndex,cellIndex) = nan;
                        end
                    else
                        extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = cellTableArray{cellIndex}.(extractionHeader);
                    end
                end
            end
        end

        % Create table of NaN tables
        function metricTable = createMetricTable(app)
            % Returns # of tables in each variable of tableData
            sectionInstances = values(app.mapSectionNameSectionCount);
            
            % Creating empty table with same dimensions as tableData
            metricTable = array2table(cell(max([sectionInstances{:}]), size(sectionInstances, 2)), "VariableNames",keys(app.mapSectionNameSectionCount));

            % Iterates across sectionInstances (Columns)
            for sectionIndex = 1:size(sectionInstances, 2)

                % Iterates down number of movements within tableData
                for movementIndex = 1:sectionInstances{sectionIndex}
                    colSize = size(app.MetricTable.ColumnName,1);

                    % Assign metricTable empty NaN for processing
                    metricTable{movementIndex, sectionIndex} = {horzcat(array2table(NaN(3,colSize-1),'VariableNames',app.MetricTable.ColumnName(1:end-1),'RowNames',app.MetricTable.RowName),array2table(string(NaN(3,1)),'VariableNames',app.MetricTable.ColumnName(end),'RowNames',app.MetricTable.RowName))};
                end
            end
        end

        % Creates directory for metric table saving
        function metricTablePath = createMetricTableDirectory(~, filepath)
          % Replace Key Processing Words with "ANALYSIS_METRICS"
            metricTablePath = strrep(filepath,"FILTERED_CALIBRATED","ANALYSIS_METRICS");

            % Check If Directory Does Not Exist
            if(~exist(metricTablePath,'dir'))
                mkdir(metricTablePath);
            end
        end

        % Creates directory for metric table saving
        function figurePath = createAnalysisFigureDirectory(app, filepath, figureType)
          % Replace Key Processing Words with "ANALYSIS_METRICS"
            basePath = strrep(filepath,"FILTERED_CALIBRATED","ANALYSIS_FIGURES");
            
            % Add Specified Directory
            figurePath = fullfile(basePath,figureType,app.SectionNameDropDown.Value);

            % Check If Directory Does Not Exist
            if(~exist(figurePath,'dir'))
                mkdir(figurePath);
            end
        end

        % Create Map Container to Map Keys (Section Names) to Values
        % (Movement Counts per Section Name)
        function createSectionNameMap(app)
            % Iterate over each cell in Variable Name
            sectionLogicalCells = cellfun(@(tableVariableName) cellfun(@(sectionData) ~isempty(sectionData),app.currentTableData.(tableVariableName),'UniformOutput',false),app.currentFileSectionNames,'UniformOutput',false);
            sectionMovementCounts = cellfun(@(logicalCell) sum(cell2mat(logicalCell),1), sectionLogicalCells);
            % Map Keys - Section Names to Values - Section Movement Counts 
            app.mapSectionNameSectionCount = containers.Map(app.currentFileSectionNames,sectionMovementCounts);
        end

        % Create NaN table of tables for metric analysis
        function findMetricTable(app,filePath)
            % Retrieve FileName
            fileName = app.returnFileName;
    
            % Check if table exists
            if(isfile(fullfile(app.createMetricTableDirectory(filePath),fileName)))
                loadedMetricTableObject = load(fullfile(app.createMetricTableDirectory(filePath),fileName));
                app.currentTableOfMetrics = loadedMetricTableObject.importedData;
                app.loadMetricTableData;

                % Load in Metrics to Graph
                app.updateTemporalMarkers;

            % Else create NaN metric table
            else
                app.currentTableOfMetrics = app.createMetricTable;
            end
        end

        % Remove Excess Version Plots and Lines for Saving
        function clearPlottingOverlays(app)
            % Remove Lines
            app.clearMarkers;

            % Remove Version Plot
            app.turnOffVersionPlot;
        end

        % Save Current Individual Movement and Velocity Axes
        function saveCompleteBoolean = saveCurrentIndividuals(app)
            % Set Default Flag
            saveCompleteBoolean = false;

            % Current Classification
            currentClassification = app.currentMetrics{1}.(app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value))(app.currentEyeSelectionIndex);

            % Only Save with Good Classification
            if(~isnan(currentClassification) && currentClassification)

                % Clear Figurs of Excess Visuals
                app.clearPlottingOverlays;

                % Retrieve FileName
                fileName = app.returnFileName;

                % Save Movement Plot Data
                figureFileName = strrep(fileName,".mat","");
                currentDataChannel = app.DataChannelLabelsDropDown.Value;
                currentIndex = string(app.currentMovementIndex);

                % Creat Temporary Figure Copies
                tempMovement = figure("Visible","off");
                tempMovementAxes = axes(tempMovement);
                copyobj(app.IndividualMovementPlot.Children,tempMovementAxes);
                tempVelocity = figure("Visible","off");
                tempVelocityAxes = axes(tempVelocity);
                copyobj(app.IndividualVelocityPlot.Children,tempVelocityAxes);
                
                % Save FIG Plots Movement/Velocity
                % Generate Figure path
                fileSaveMovementPathway = app.createAnalysisFigureDirectory(app.userSelectedFilePath,"Individuals\Movement");
                fileSaveVelocityPathway = app.createAnalysisFigureDirectory(app.userSelectedFilePath,"Individuals\Velocity");

                % Save Movement Data (Fig)
                app.createPlotSavePath(tempMovement,fileSaveMovementPathway,"FIGs",strcat(figureFileName,"_MovementTrace_",currentDataChannel,"_",currentIndex),".fig");
                % Save Movement Data (EMF)
                app.createPlotSavePath(tempMovement,fileSaveMovementPathway,"EMFs",strcat(figureFileName,"_MovementTrace_",currentDataChannel,"_",currentIndex),".emf");

                % Save Velocity Data (Fig)
                app.createPlotSavePath(tempVelocity,fileSaveVelocityPathway,"FIGs",strcat(figureFileName,"_VelocityTrace_",currentDataChannel,"_",currentIndex),".fig");
                % Save Velocity Data (EMF)
                app.createPlotSavePath(tempVelocity,fileSaveVelocityPathway,"EMFs",strcat(figureFileName,"_VelocityTrace_",currentDataChannel,"_",currentIndex),".emf");
                
                % Clean Up
                delete(tempMovement)
                delete(tempVelocity)

                % Set Completion of Save
                saveCompleteBoolean = true;
            end
        end
           
        % Load Next Movement Data within Current Table Data
        function getNextMovement(app)
            % Clear Plotting Extras
            app.clearPlottingOverlays;

            % Save Current Metric Table State
            app.saveCurrentAnalysis;

            % Evaluate Button Presses
            % Expected Next Button Pressed
            if(app.nextMovementButtonPressed)
                % Check Boundaries and Roll Over to 1 if reached
                if(app.currentMovementIndex + 1 <= app.totalCurrentMovements)
                    app.currentMovementIndex = app.currentMovementIndex + 1;
                else
                    app.currentMovementIndex = 1;
                end    

            % Else Previous Button Pressed
            else
                % Check Boundaries and Roll Over to Last File if reached
                if(app.currentMovementIndex - 1 >= 1)
                    app.currentMovementIndex = app.currentMovementIndex - 1;
                else
                    app.currentMovementIndex = app.totalCurrentMovements;
                end
            end

            % Load Subsection of Table Data
            app.loadSectionOfData;

            % Update Metric Table for New Movement Index
            app.loadMetricTableData;

            % Update Time Scrubber Bounds
            app.updateTimeScrubberLimit;

            % Update Labels
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
        end

        % Find Minimum and Maxium Time Limits for Scrubbing on Graph
        function updateTimeScrubberLimit(app)
            app.TimeScrubberSlider.Limits = [1/app.SamplingRateSpinner.Value, size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value];
            app.TimeScrubberSlider.Value = size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value;
        end

        function getSectionNames(app)
            % Load Current Table Variable Names
            app.currentFileSectionNames = app.currentTableData.Properties.VariableNames';     
            % Update Dropdown Items
            app.SectionNameDropDown.Items = app.currentFileSectionNames;
            % Set Default Value to First Item
            app.currentSectionIndex = 1;
            app.SectionNameDropDown.Value = app.currentFileSectionNames{app.currentSectionIndex};
            % Map Section Names and Movement Counts
            app.createSectionNameMap;
        end

        % Table Data FILE Pathing Retrieval
        function getTablePathing(app)
            % Button was Pressed and App has data
            if(~strcmp(app.CurrentFileSelectionLabel.Text,'Current File Selection'))
                % Check Button Press (Next Default)
                if(app.nextDataFileButtonPressed)
                    % Check Boundaries and Roll Over to 1 if reached
                    if(app.currentFileIndex + 1 <= app.totalSelectedFiles)
                        app.currentFileIndex = app.currentFileIndex + 1;
                    else
                        app.currentFileIndex = 1;
                    end
                % Check Previous Button Press
                elseif(app.previousDataFileButtonPressed)
                    % Check Boundaries and Roll Over to Last File if reached
                    if(app.currentFileIndex - 1 >= 1)
                        app.currentFileIndex = app.currentFileIndex - 1;
                    else
                        app.currentFileIndex = app.totalSelectedFiles;
                    end
                % Section Name Change 
                else
                    app.currentFileIndex = 1;
                end
                
                % Update Labels
                app.currentFileIndexLabel.Text = string(app.currentFileIndex);

            % If no Button Pressed Load via Prompt
            else
                % Select File Pathway and File(s) to Process
                [app.userSelectedFilePath, app.userSelectedFile] = loadData.getTableData();

                % Set First Load Attempt Boolean to False
                app.firstLoadAttemptBool = 0;

                % Proceed If Load Successful
                if(~isempty(app.userSelectedFile))
                    % Populate Default Count Variable
                    app.currentFileIndex = 1;
                    app.currentMovementIndex = 1;
                    app.currentSectionIndex = 1;

                    % Evaluate for Single or Multi-File Selection
                    if(ischar(app.userSelectedFile))
                        app.totalSelectedFiles = 1;
                    else
                        app.totalSelectedFiles = size(app.userSelectedFile,1);
                    end

                    % Set Labels
                    app.currentFileIndexLabel.Text = string(app.currentFileIndex);
                    app.totalFileCountLabel.Text = string(app.totalSelectedFiles); 

                    % Load Table Data
                    app.loadTableData;

                    % Update Load Data Boolean
                    app.dataLoadedBoolean = true;
                end
            end
            
        end
        
        function loadTableData(app)
            % Load Current Table Path and Filename
            if(ischar(app.userSelectedFile))
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile);
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile;

            else
                app.currentTableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile{app.currentFileIndex});
                % Update Labels
                app.CurrentFileSelectionLabel.Text = app.userSelectedFile{app.currentFileIndex};
            end
            % Load Map of Section Names and Expected Counts
            app.getSectionNames;
            % Get Metric Table (New if Does Not Exist or Returns File if
            % Exists)
            app.findMetricTable(app.userSelectedFilePath);
            % Load in Metric Table File
            app.loadMetricTableData;
            % Load in Section Data of File (Default)
            app.loadSectionOfData;

            % Instantiate Time Scrubber Limits
            app.TimeScrubberSlider.Limits = [1/app.SamplingRateSpinner.Value, size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value];
            app.TimeScrubberSlider.Value = size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value;
         
        end

        % Load Metric Table Based on Current Movement Index (Row) and
        % Section Index (Column)
        function loadMetricTableData(app)
           app.currentMetrics = app.currentTableOfMetrics{app.currentMovementIndex,app.currentSectionIndex};  
           app.MetricTable.Data = app.currentMetrics{:};
        end

        function loadSectionOfData(app)
            app.currentLoadedData = app.currentTableData{app.currentMovementIndex,app.currentSectionIndex};
        end

        function fileName = returnFileName(app)
             % Evaluate Single File or Batch
            if(ischar(app.userSelectedFile))
                fileName = app.userSelectedFile;
            else
                fileName = app.userSelectedFile{app.currentFileIndex};
            end
        end

        % Update Labels for New Section
        function resetSectionMovementIndex(app)
            % Load Movement Index
            app.currentMovementIndex = 1;
            app.currentMovementCountIndex.Text = string(app.currentMovementIndex);
            app.totalCurrentMovements = app.mapSectionNameSectionCount(app.SectionNameDropDown.Value);
            app.totalMovementCount.Text = string(app.totalCurrentMovements);
        end

        % Evaluate if Load Data Boolean has been set to true (Data Loaded)
        function booleanFlag = DataLoadedValidation(app)
            booleanFlag = app.dataLoadedBoolean;
            if (~app.dataLoadedBoolean || app.firstLoadAttemptBool)
                app.LoadDataButtonPushed;
                booleanFlag = app.dataLoadedBoolean;
            end
        end

        % Updates Modifications to Current Metric Table
        function updateMetricTable(app)
            app.MetricTable.Data = app.currentMetrics{:};
        end

        % Returns Placement of Current Metric into Table
        function [metricPositionMatrix] = getMetricPlacement(app,metricName)
            metricPositionMatrix(1) = app.currentEyeSelectionIndex;
            metricPositionMatrix(2) = app.mapMetricIndex(metricName) + app.currentMetricTableOffset;
        end

        % Return Metric Value from Current Metric Table
        function metricValue = getMetricValue(app,metricPositionMatrix)
            metricValue = app.currentMetrics{1}(metricPositionMatrix(1),metricPositionMatrix(2)).(1);
        end

        % Evaluate Movement Position Response Amplitude from REI - RSI
        function updateResponseAmplitude(app)
            % Get Current Positions of Response Metrics
            rsiMetricPositionMatrix = app.getMetricPlacement("RSI");
            reiMetricPositionMatrix = app.getMetricPlacement("REI");

            % Retrieve Values
            rsiVal = app.getMetricValue(rsiMetricPositionMatrix);
            reiVal = app.getMetricValue(reiMetricPositionMatrix);

            % Check Values Exist
            rsiBool = isnan(app.currentMetrics{1}(rsiMetricPositionMatrix(1),rsiMetricPositionMatrix(2)).(1));
            reiBool = isnan(app.currentMetrics{1}(reiMetricPositionMatrix(1),reiMetricPositionMatrix(2)).(1));

            % If Both Exist Calculate RA
            if ~rsiBool && ~reiBool
                currentPositionData = app.getYData(app.IndividualMovementPlot,"Movement");
                currentRA = currentPositionData(app.time2samples(reiVal)) - currentPositionData(app.time2samples(rsiVal));
                metricPositionMatrix = app.getMetricPlacement("RA");
                app.currentMetrics{1}(metricPositionMatrix(1),metricPositionMatrix(2)) = {currentRA};
                app.updateMetricTable;
            end
        end

        % Extract YData from Line within Axes
        function lineYData = getYData(app, figureAxes,traceName)
            yLineIndex = app.findTraceIndex(figureAxes,traceName);
            lineDataObject = figureAxes.Children(yLineIndex);
            lineYData = lineDataObject.YData;
        end

        % Converts Time Space to Sample Space
        function samplePoint = time2samples(app, timePoint)
            testSamplePoint = int32(timePoint * app.SamplingRateSpinner.Value);
            if(testSamplePoint<=0)
                samplePoint = 1;
            else
                samplePoint = testSamplePoint;
            end
        end

        % Takes PVI and Calculates PVI
        function updatePeakVelocity(app,indexSelection)
            % Find Peak Velocity and Index
            [foundPVI, foundPeakVelocity] = app.findPeakVelocity(indexSelection);

            % Update Values
            peakVelocityMatrix = app.getMetricPlacement("PV");
            pviMatrix = app.getMetricPlacement("PVI");

            app.currentMetrics{1}(peakVelocityMatrix(1),peakVelocityMatrix(2)) = {foundPeakVelocity};
            app.currentMetrics{1}(pviMatrix(1),pviMatrix(2)) = {foundPVI};
            app.updateMetricTable;

            % Update Markers for Metrics
            app.updateTemporalMarkers;
        end

        % Determine Max Value within Selection
        function [determinedPVI, foundPeakVelocity] = findPeakVelocity(app, currentPVI)
            % Set Search Window
            searchWindow = app.SearchWindowSpinner.Value;

            % Load Velocity Data
            currentVelocityData = app.getYData(app.IndividualVelocityPlot,"Velocity");

            % Set Default Window Sizes
            lowerSearchWindowMod = 0;
            upperSearchWindowMod = 0;

            % Check Search Window Boundaries
            if(app.time2samples(currentPVI) - searchWindow < 1 )
                lowerBoundPVI = 1;
                upperBoundPVI = app.time2samples(currentPVI) + searchWindow;
                lowerSearchWindowMod = app.time2samples(currentPVI) - 1;
            % Out of Upper Bounds
            elseif(app.time2samples(currentPVI) + searchWindow > size(currentVelocityData,2))
                upperBoundPVI = size(currentVelocityData,2);
                lowerBoundPVI = app.time2samples(currentPVI) - searchWindow;
                upperSearchWindowMod =  size(currentVelocityData,2) - app.time2samples(currentPVI);
            % In Bounds
            else
                upperBoundPVI = app.time2samples(currentPVI) + searchWindow;
                lowerBoundPVI = app.time2samples(currentPVI) - searchWindow;
            end

            % Peak Velocity
            [foundPeakVelocity, foundPVI] = max(abs(currentVelocityData(lowerBoundPVI:upperBoundPVI)));
            % Evaluate where Velocity was Found
            % Below Current
            if(foundPVI < (searchWindow - lowerSearchWindowMod))
                adjustedPVI = app.time2samples(currentPVI) + (foundPVI - searchWindow + lowerSearchWindowMod);
            % Above Current
            elseif(foundPVI > (searchWindow + lowerSearchWindowMod))
                adjustedPVI = app.time2samples(currentPVI) + (foundPVI - searchWindow + lowerSearchWindowMod);
            % Else if Current PVI
            else
                adjustedPVI = foundPVI;
            end

            % Convert Samples to Time
            determinedPVI = double(adjustedPVI)/app.SamplingRateSpinner.Value;
        end

        % Calculate Metric Function
        function completeBoolean = calculateMetric(app, metricField, indexSelection)
            
            if(app.DataLoadedValidation())
                try
                    % Verify Input
                    if(~isempty(indexSelection))
                        % Value Correction
                        if(mod(indexSelection,1/app.SamplingRateSpinner.Value)~=0)
                            indexSelection = indexSelection - mod(indexSelection,1/app.SamplingRateSpinner.Value);
                        end

                        % Assign Value to Metric Table
                        app.assignMetricValue(metricField,indexSelection)
                       
                    else
                        uialert(app.analysisContainer,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                    end
                    completeBoolean = true;
                catch
                    uialert(app.analysisContainer,"Operation Cancelled", "Operation Cancellation",'Icon','warning')
                    completeBoolean = false;
                end

                % Check RA Status
                if(contains(metricField,"RSI") || contains(metricField,"REI"))
                    app.updateResponseAmplitude;
                end

                % Update PV with PVI
                if(contains(metricField,"PVI"))
                    app.updatePeakVelocity(indexSelection);
                end
                % Update Markers for Metrics
                app.updateTemporalMarkers;
            end
        end

        % Assign Given Metric Field Provided Value
        function assignMetricValue(app, metricFieldName, metricValue)
            % Assign Value to Metric Table
            metricPositionMatrix = app.getMetricPlacement(metricFieldName);
            app.currentMetrics{1}(metricPositionMatrix(1),metricPositionMatrix(2)) = {round(metricValue,3)};

            % Update Metric Table
            app.updateMetricTable;
        end

        % Classify Data with Classification Label
        function classifyData(app, classificationLabel, classificationValue)
            % Assign Value to Metric Table
            app.currentMetrics{1}((app.currentEyeSelectionIndex),classificationLabel) = {classificationValue};

            % Update Metric Table
            app.updateMetricTable;
            app.saveCurrentAnalysis

            % Replot Upper Plots
            app.replotEnsembles;
        end

        % Apply Analysis Metrics from currentMetricTable to Parent Table
        function saveCurrentAnalysis(app)
           app.currentTableOfMetrics{app.currentMovementIndex,app.currentSectionIndex} = app.currentMetrics;  
        end

        % Turn Version Plotting off
        function turnOffVersionPlot(app)
            % Turn off Version Plotting
            app.PlotVersionButton.Value = 0;
            app.PlotVersionButtonValueChanged;
        end

        % Increment Section Selection 
        function changeSectionSelection(app,incrementDirection)

            % Find Boundary
            % Overflows Reset Section to 1
            if(app.currentSectionIndex + incrementDirection > size(keys(app.mapSectionNameSectionCount),2))
                tempSectionIndex = 1;
            % Send to Bottom Section if below 1
            elseif(app.currentSectionIndex + incrementDirection == 0)
                tempSectionIndex = size(keys(app.mapSectionNameSectionCount),2);
            % Otherwise Normal
            else
                tempSectionIndex = app.currentSectionIndex + incrementDirection;
            end

            % Get Section Name List
            tempSectionList = keys(app.mapSectionNameSectionCount);

            % Set Current Section Name to Current Index
            app.SectionNameDropDown.Value = tempSectionList{tempSectionIndex};
            
            % Trigger Section Change          
            app.SectionNameDropDownValueChanged;

        end

        % Create Plot Saving Directory with Parent and Child
        function saveSuccessBoolean = createPlotSavePath(~,figureData, parentPath,folderName,fileName, fileType)
            try
                % Create Fullfile Pathway 
                plotSaveFilePath = fullfile(parentPath,folderName,strcat(fileName,fileType));
                if(~isfolder(fullfile(parentPath,folderName)))
                    mkdir(fullfile(parentPath,folderName))
                end
    
                % Determine Saving Method
                switch fileType
                    case ".emf"
                         exportgraphics(figureData,plotSaveFilePath);
                    case ".fig"
                        savefig(figureData,plotSaveFilePath,"compact");
                end
                saveSuccessBoolean = true;
            catch
                saveSuccessBoolean = false;
            end
        end

        % Remove Segment of Trace from Loaded Table Metrics
        function removeSuccessBoolean = removeTraceSegment(app, startingPoint, endingPoint)
            removeSuccessBoolean = true;

            % Determine Verbosity of Removal
            if(app.currentEyeSelectionIndex == 3)
                if(startingPoint < endingPoint)
                    app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(1), " ",app.DataChannelLabelsDropDown.Value))(app.time2samples(startingPoint):app.time2samples(endingPoint)) = nan;
                    app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(2), " ",app.DataChannelLabelsDropDown.Value))(app.time2samples(startingPoint):app.time2samples(endingPoint)) = nan;
                elseif(startingPoint > endingPoint)
                    app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(1), " ",app.DataChannelLabelsDropDown.Value))(app.time2samples(endingPoint):app.time2samples(startingPoint)) = nan;
                    app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(2), " ",app.DataChannelLabelsDropDown.Value))(app.time2samples(endingPoint):app.time2samples(startingPoint)) = nan;
                else
                    removeSuccessBoolean = false;
                end
            else
                if(startingPoint < endingPoint)
                    app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value))(app.time2samples(startingPoint):app.time2samples(endingPoint)) = nan;
                elseif(startingPoint > endingPoint)
                    app.currentTableData.(app.SectionNameDropDown.Value){app.currentMovementIndex}.(strcat(app.eyeSelectionMap(app.currentEyeSelectionIndex), " ",app.DataChannelLabelsDropDown.Value))(app.time2samples(endingPoint):app.time2samples(startingPoint)) = nan;
                else
                    removeSuccessBoolean = false;
                end
            end            
            
            app.replotIndividuals;
            app.replotEnsembles;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, previousFigureHandle)
            app.analysisContainer.Name = "Vergence Step Analysis";
            
            % Create Pointer Behavior
            pb.enterFcn = @(fig, currentPoint) set(fig, 'Pointer', 'crosshair');
            pb.exitFcn = @(fig, currentPoint) set(fig, 'Pointer', 'arrow');
            pb.traverseFcn = [];
            iptSetPointerBehavior([app.IndividualMovementPlot, app.IndividualVelocityPlot],pb)
            iptPointerManager(app.analysisContainer)

            % Align GUI to center of Monitor
            movegui(app.analysisContainer,'center');
            app.previousFigureHandle = previousFigureHandle;
            app.eyeSelectionMap = containers.Map([1, 2, 3], ["Left Eye", "Right Eye", "Binocular"]);
            app.mapDataChannelClassification = containers.Map(["Horizontal","Vertical","Pupil"],["HClass","VClass","PClass"]);
            app.mapMetricIndex = containers.Map(["RSI","REI","RA","PVI","PV","FA","ID"],[1,2,3,4,5,6,7]);
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Set Default Title
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            % Identify Table Pathing to Load
            app.getTablePathing;

            if(~isempty(app.userSelectedFilePath))
                % Reset Labels for Section
                app.resetSectionMovementIndex;
                % Generate Section Metric Table
                app.generateSectionMetricTable
                % Plot Initial Data
                % Replot
                app.replotIndividuals;
                app.replotEnsembles;
                % Turn Hit Test On
                app.IndividualMovementPlot.HitTest = "on";
                app.IndividualVelocityPlot.HitTest ="on";
                app.TimeScrubberSlider.Enable = "on";
            end
        end

        % Button pushed function: NextFileButton
        function NextFileButtonPushed(app, event)
            % Set Boolean for Button Condition
            app.nextDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex;
            app.loadTableData;
            % Replot
            app.replotIndividuals;
            app.replotEnsembles;
            % Reset Button Condition
            app.nextDataFileButtonPressed = false;
            % Calculate Fixed Metrics
            app.calculateFixedMetrics;
        end

        % Button pushed function: nextMovementButton
        function nextMovementButtonPushed(app, event)
            app.nextMovementButtonPressed = true;
            % Save Individual Trace before Switch
            %waitfor(app.saveCurrentIndividuals);
            % Move to Next Movement
            app.getNextMovement;
            % Replot Lower Plots
            app.replotIndividuals;
            app.nextMovementButtonPressed = false;
            % Calculate Fixed Metrics
            app.calculateFixedMetrics;
        end

        % Button pushed function: previousMovementButton
        function previousMovementButtonPushed(app, event)
            app.previousMovementButtonPressed = true;
            % Save Individual Trace before Switch
            %waitfor(app.saveCurrentIndividuals);
            % Move to Next Movement
            app.getNextMovement;
            % Replot Lower Plots
            app.replotIndividuals;
            app.previousMovementButtonPressed = false;
            % Calculate Fixed Metrics
            app.calculateFixedMetrics;
        end

        % Button pushed function: PreviousFileButton
        function PreviousFileButtonPushed(app, event)
            app.previousDataFileButtonPressed = true;
            app.getTablePathing;
            app.resetSectionMovementIndex
            app.loadTableData;
            % Replot
            app.replotIndividuals;
            app.replotEnsembles;
            % Modify Boolean
            app.previousDataFileButtonPressed = false;
            % Calculate Fixed Metrics
            app.calculateFixedMetrics;
        end

        % Value changed function: SectionNameDropDown
        function SectionNameDropDownValueChanged(app, event)
            % Save Current Analysis to Main Table
            app.saveCurrentAnalysis;

            % Update Section Name via Dropdown to Column Index
            app.currentSectionIndex = find(strcmp(app.SectionNameDropDown.Value,keys(app.mapSectionNameSectionCount)));
            
            % Set Movement Index to 1
            app.resetSectionMovementIndex;
            
            % Load New Data into Software
            app.loadSectionOfData;

            % Load Indexed Metric Table
            app.loadMetricTableData;

            % Update Time Scrubber
            app.updateTimeScrubberLimit

            % Replot
            app.replotIndividuals;
            app.replotEnsembles;
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.DataLoadedValidation())

                % Turn off Version Plotting
                app.turnOffVersionPlot;

                % Save Current Metric Analysis State
                app.saveCurrentAnalysis;
                
                % Retrieve FileName
                fileName = app.returnFileName;
    
                % Save Metric Table Data to ANALYSIS_METRIC Directory
                saveFilePathway = app.createMetricTableDirectory(app.userSelectedFilePath);

                % Save Metric Data
                saveFunction.saveData(app.currentTableOfMetrics,fullfile(saveFilePathway,fileName),"-mat");

                % Popup Save Notification
                uialert(app.analysisContainer ,"Save Successful","Save","Modal",true,"Icon","success")
            end            
        end

        % Selection changed function: EyeSelectorButtonGroup
        function EyeSelectorButtonGroupSelectionChanged(app, event)
           switch app.EyeSelectorButtonGroup.SelectedObject.Text
                case "Left Eye"
                    app.currentEyeSelectionIndex = 1;
               case "Right Eye"
                    app.currentEyeSelectionIndex = 2;
               case "Binocular"
                    app.currentEyeSelectionIndex = 3;
           end
           % Replot 
           app.replotIndividuals;
           app.replotEnsembles;
        end

        % Value changed function: DataChannelLabelsDropDown
        function DataChannelLabelsDropDownValueChanged(app, event)
            % Evaluate if Pupil/Horizontal Selected
            if(contains(app.DataChannelLabelsDropDown.Value,"Pupil",'IgnoreCase',true))
                app.currentMetricTableOffset = 6;
            else
                app.currentMetricTableOffset = 0;
            end
            % Trigger Replot
            app.replotIndividuals;
            app.replotEnsembles;
        end

        % Button pushed function: GoodButton
        function GoodButtonPushed(app, event)
            % Determine Classification
            classificationType = app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value);

            app.classifyData(classificationType,1);
        end

        % Button pushed function: BadButton
        function BadButtonPushed(app, event)
            % Determine Classification
            classificationType = app.mapDataChannelClassification(app.DataChannelLabelsDropDown.Value);

            app.classifyData(classificationType,0);
        end

        % Value changed function: ClassificationCommentsDropDown
        function ClassificationCommentsDropDownValueChanged(app, event)
            app.classifyData("Comments",app.ClassificationCommentsDropDown.Value);
        end

        % Key press function: analysisContainer
        function analysisContainerKeyPress(app, event)
            switch event.Key
                case "l"
                    app.LoadDataButtonPushed;
                case "q"
                    app.MetricSelectorButtonGroup.SelectedObject = app.SelectRSIButton;
                    app.MetricSelectorButtonGroupSelectionChanged;
                case "w"
                    app.MetricSelectorButtonGroup.SelectedObject = app.SelectPeakVelocityButton;
                    app.MetricSelectorButtonGroupSelectionChanged;
                case "e"
                    app.MetricSelectorButtonGroup.SelectedObject = app.SelectREIButton;
                    app.MetricSelectorButtonGroupSelectionChanged;
                case "r"
                    if(contains(app.DataChannelLabelsDropDown.Value,"Pupil",'IgnoreCase',true))
                        app.MetricSelectorButtonGroup.SelectedObject = app.IDButton;
                        app.MetricSelectorButtonGroupSelectionChanged;
                    end
                case "t"
                    if(app.FAButton.Value)
                        app.FAButton.Value = 0;
                    else
                        app.FAButton.Value = 1;
                    end
                case "y"
                    app.ScaleEnsembleVelocitytoAnalysisCheckBox.Value = ~app.ScaleEnsembleVelocitytoAnalysisCheckBox.Value;
                    app.ScaleEnsembleVelocitytoAnalysisCheckBoxValueChanged;
                case "g"
                    app.GoodButtonPushed
                case "b"
                    app.BadButtonPushed;
                case "s"
                    app.SaveButtonPushed;
                case "a"
                    app.previousMovementButtonPushed;
                case "d"
                    app.nextMovementButtonPushed;
                case "z"
                    if(double(app.totalFileCountLabel.Text)~=1)
                        app.PreviousFileButtonPushed;
                    end
                case "c"
                    if(double(app.totalFileCountLabel.Text)~=1)
                        app.NextFileButtonPushed;
                    end
                case "f"
                    app.saveCurrentIndividuals;
                case "v"
                    app.SaveEnsemblesButtonPushed;
                case "h"
                    app.DataChannelLabelsDropDown.Value = "Horizontal";
                    app.DataChannelLabelsDropDownValueChanged;
                case "j"
                    app.DataChannelLabelsDropDown.Value = "Vertical";
                    app.DataChannelLabelsDropDownValueChanged;
                case "k"
                    app.DataChannelLabelsDropDown.Value = "Pupil";
                    app.DataChannelLabelsDropDownValueChanged;
                case "escape"
                    app.analysisContainerCloseRequest;
                case "1"
                    app.EyeSelectorButtonGroup.SelectedObject = app.LeftEyeButton;
                    app.EyeSelectorButtonGroupSelectionChanged;
                case "2"
                    app.EyeSelectorButtonGroup.SelectedObject = app.RightEyeButton;
                    app.EyeSelectorButtonGroupSelectionChanged;
                case "3"
                    app.EyeSelectorButtonGroup.SelectedObject = app.BinocularButton;
                    app.EyeSelectorButtonGroupSelectionChanged;
                case "downarrow"
                    app.changeSectionSelection(1);
                case "uparrow"
                    app.changeSectionSelection(-1);
                case "rightarrow"
                    app.BehaviorTypeButtonGroup.SelectedObject = app.AverageButton;
                    app.BehaviorTypeButtonGroupSelectionChanged;
                case "leftarrow"
                    app.BehaviorTypeButtonGroup.SelectedObject = app.SummationButton;
                    app.BehaviorTypeButtonGroupSelectionChanged;
                case "n"
                    if(app.NaNifyButton.Value)
                        app.NaNifyButton.Value = 0;
                    else
                        app.NaNifyButton.Value = 1;
                    end
            end


        end

        % Close request function: analysisContainer
        function analysisContainerCloseRequest(app, event)
            userSelection = uiconfirm(app.analysisContainer,"Close Analysis Module?","Confirm Exit","Icon","question");
            switch userSelection
                case "OK"
                    try
                        set(app.previousFigureHandle,"WindowState",'normal');
                        delete(app)
                    catch
                        delete(app)
                    end
            end            
        end

        % Button pushed function: SaveEnsemblesButton
        function SaveEnsemblesButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.DataLoadedValidation())
                % Retrieve FileName
                currentFileName = app.returnFileName;
                figureFileName = strrep(currentFileName,".mat","");
                figureFileName = regexprep(figureFileName,'_\d{4,}',"");
                currentDataChannel = strcat(app.DataChannelLabelsDropDown.Value,"_",strrep(app.EyeSelectorButtonGroup.SelectedObject.Text," ","_"));
                % Generate Directory Pathing
                figureSavePathway = app.createAnalysisFigureDirectory(app.userSelectedFilePath,"Ensembles");

                % Create All Temporary Figures
                tempVelocityAverageTrace = figure("Visible","off","Name","Velocity Average",'Color','none');
                tempScaledVelocityTrace = figure("Visible","off","Name","Scaled Velocity",'Color','none');                
                tempEnsembleMovementAverageTrace = figure("Visible","off","Name","Ensemble Average",'Color','none');
                tempEnsembleMovement = figure("Visible","off","Name","Ensemble Movement",'Color','none');
                tempEnsembleVelocity = figure("Visible","off","Name", "Ensemble Velocity",'Color','none');

                % Create Axes for Temp Figures
                tempAverageVelocityTraceAxes = axes(tempVelocityAverageTrace);
                tempScaledVelocityTraceAxes = axes(tempScaledVelocityTrace);
                tempEnsembleMovementAverageAxes = axes(tempEnsembleMovementAverageTrace);
                tempEnsembleMovementAxes = axes(tempEnsembleMovement);
                tempEnsembleVelocityAxes = axes(tempEnsembleVelocity);                

                % Specific Figure-Wise Data Routines

                % Extract Scaled Velocity Average
                app.ScaleEnsembleVelocitytoAnalysisCheckBox.Value = 1;
                app.ScaleEnsembleVelocitytoAnalysisCheckBoxValueChanged;

                % Copy Velocity Plot Child Data to Temp Figure
                copyobj(app.VelocityEnsemblePlot.Children,tempScaledVelocityTraceAxes);
                % Remove Individual Velocity Traces from Temp Figure
                app.deleteTrace(tempScaledVelocityTraceAxes,"VelocityEnsemble");

                % Copy Movement Plot Child Data to Temp Figure
                copyobj(app.MovementEnsemblePlot.Children,tempEnsembleMovementAverageAxes);
                app.deleteTrace(tempEnsembleMovementAverageAxes,"MovementEnsemble");

                % Copy Movement Plot Child Data to Temp Figure
                copyobj(app.VelocityEnsemblePlot.Children,tempAverageVelocityTraceAxes);
                app.deleteTrace(tempAverageVelocityTraceAxes,"VelocityEnsemble");

                % Clear Average/Version Traces for Movement-Wise Ensembles
                app.turnOffVersionPlot;
                app.clearAverageTraces;
                app.ScaleEnsembleVelocitytoAnalysisCheckBox.Value = 0;
                app.ScaleEnsembleVelocitytoAnalysisCheckBoxValueChanged;

                % Copy Movement Plot Children to Temp Figure
                copyobj(app.MovementEnsemblePlot.Children,tempEnsembleMovementAxes);

                % Create Temporary Figure Copies (Velocity)
                copyobj(app.VelocityEnsemblePlot.Children,tempEnsembleVelocityAxes);

                % Save Routines for All Temp Figures Generated

                % Save Scaled Velocity Data (Figure)
                waitfor(app.createPlotSavePath(tempScaledVelocityTrace,figureSavePathway,"Scaled\FIGs",strcat(figureFileName,"_ScaledVelocity_EnsembleTrace_",currentDataChannel),".fig"));
                % Save Scaled Velocity Data (EMF)
                waitfor(app.createPlotSavePath(tempScaledVelocityTrace,figureSavePathway,"Scaled\EMFs",strcat(figureFileName,"_ScaledVelocity_EnsembleTrace_",currentDataChannel),".emf"));

                % Save Average Positional Data (Figure)
                waitfor(app.createPlotSavePath(tempEnsembleMovementAverageTrace,figureSavePathway,"Averaged\FIGs",strcat(figureFileName,"_AverageMovement_EnsembleTrace_",currentDataChannel),".fig"));
                % Save Average Positional Data (EMF)
                waitfor(app.createPlotSavePath(tempEnsembleMovementAverageTrace,figureSavePathway,"Averaged\EMFs",strcat(figureFileName,"_AverageMovement_EnsembleTrace_",currentDataChannel),".emf"));    

                % Save Average Velocity Data (Figure)
                waitfor(app.createPlotSavePath(tempVelocityAverageTrace,figureSavePathway,"Averaged\FIGs",strcat(figureFileName,"_AverageVelocity_EnsembleTrace_",currentDataChannel),".fig"));
                % Save Average Positional Data (EMF)
                waitfor(app.createPlotSavePath(tempVelocityAverageTrace,figureSavePathway,"Averaged\EMFs",strcat(figureFileName,"_AverageVelocity_EnsembleTrace_",currentDataChannel),".emf")); 

                % Save Ensemble Positional Data (Figure)
                waitfor(app.createPlotSavePath(tempEnsembleMovement,figureSavePathway,"Raw\Movement\FIGs",strcat(figureFileName,"_Movement_EnsembleTrace_",currentDataChannel),".fig"));
                % Save Ensemble Positional Data (EMF)
                waitfor(app.createPlotSavePath(tempEnsembleMovement,figureSavePathway,"Raw\Movement\EMFs",strcat(figureFileName,"_Movement_EnsembleTrace_",currentDataChannel),".emf"));

                % Save Ensemble Velocity Data (Figure)
                waitfor(app.createPlotSavePath(tempEnsembleVelocity,figureSavePathway,"Raw\Velocity\FIGs",strcat(figureFileName,"_Velocity_EnsembleTrace_",currentDataChannel),".fig"));
                % Save Ensemble Velocity Data (EMF)
                waitfor(app.createPlotSavePath(tempEnsembleVelocity,figureSavePathway,"Raw\Velocity\EMFs",strcat(figureFileName,"_Velocity_EnsembleTrace_",currentDataChannel),".emf"));

                % Clean Up
                delete(tempEnsembleMovement)
                delete(tempEnsembleVelocity)
                delete(tempScaledVelocityTrace)
                delete(tempEnsembleMovementAverageTrace)
                delete(tempVelocityAverageTrace)
                
                % Replot Averages with Ensembles
                app.replotEnsembles;

                % Popup Save Notification
                uialert(app.analysisContainer ,"Ensemble Saved","Save","Modal",true,"Icon","success")
            end   
        end

        % Value changed function: PlotVersionButton
        function PlotVersionButtonValueChanged(app, event)
            app.plotVersion(app.PlotVersionButton.Value)
        end

        % Selection changed function: MetricSelectorButtonGroup
        function MetricSelectorButtonGroupSelectionChanged(app, event)
            if(app.DataLoadedValidation())                  
                switch app.MetricSelectorButtonGroup.SelectedObject.Text
                    case "Select RSI"
                        app.currentMetricSelection = "RSI"; 
                    case "Select Peak Velocity"
                        app.currentMetricSelection = "PVI";
                    case "Select REI"
                        app.currentMetricSelection = "REI";
                    case "ID"
                        if(contains(app.DataChannelLabelsDropDown.Value,"Pupil",'IgnoreCase',true))
                            tempData = app.getYData(app.IndividualMovementPlot,"Movement");
                            app.assignMetricValue("ID",mean(tempData(1:(app.SamplingRateSpinner.Value*.5))));
                        end
                end       
            end
        end

        % Button down function: IndividualVelocityPlot
        function IndividualVelocityPlotButtonDown(app, event)
            buttonClickXYZ = get(app.IndividualVelocityPlot,'CurrentPoint');
            buttonClickX = buttonClickXYZ(1,1);
            metricValue = app.calculateMetric(app.currentMetricSelection,buttonClickX);
            waitfor(metricValue);
            app.generateSectionStatistics;
            app.defaultMetricSelector;
        end

        % Button down function: IndividualMovementPlot
        function IndividualMovementPlotButtonDown(app, event)
            if(app.NaNifyButton.Value)
                if(isempty(app.nanPoint1))
                    buttonClickXYZ = get(app.IndividualMovementPlot,'CurrentPoint');
                    if(buttonClickXYZ(1,1) < 0)
                        app.nanPoint1 = 0;
                    elseif(buttonClickXYZ(1,1) >= (size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value))
                        app.nanPoint1 = ((size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value)-(1/app.SamplingRateSpinner.Value));
                    else
                        app.nanPoint1 = buttonClickXYZ(1,1);
                    end
                else
                    buttonClickXYZ = get(app.IndividualMovementPlot,'CurrentPoint');
                    if(buttonClickXYZ(1,1) < 0)
                        app.nanPoint2 = 0;
                    elseif(buttonClickXYZ(1,1) >= (size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value))
                        app.nanPoint2 = ((size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value)-(1/app.SamplingRateSpinner.Value));
                    else
                        app.nanPoint2 = buttonClickXYZ(1,1);
                    end
                end

                if(~isempty(app.nanPoint2) && ~isempty(app.nanPoint1))
                    waitfor(app.removeTraceSegment(app.nanPoint1, app.nanPoint2));
                    app.generateSectionStatistics;
                    app.defaultMetricSelector;
                    app.NaNifyButton.Value = 0;
                    app.nanPoint1 = [];
                    app.nanPoint2 = [];
                end
            % New Requirement 10-28-2024
            % Have Index Modifiable FA rather then last half second of data
            elseif(app.FAButton.Value)
                if(isempty(app.FAPoint1))
                    buttonClickXYZ = get(app.IndividualMovementPlot,'CurrentPoint');
                    if(buttonClickXYZ(1,1) < 0)
                        app.FAPoint1 = 0;
                    elseif(buttonClickXYZ(1,1) >= (size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value))
                        app.FAPoint1 = ((size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value)-(1/app.SamplingRateSpinner.Value));
                    else
                        app.FAPoint1 = buttonClickXYZ(1,1);
                    end
                else
                    % Added Try and Catch following 02/10/2025 for Error in
                    % Final Amplitude Index 
                    try
                        buttonClickXYZ = get(app.IndividualMovementPlot,'CurrentPoint');
                        if(buttonClickXYZ(1,1) < 0)
                            app.FAPoint2 = 0;
                        elseif(buttonClickXYZ(1,1) >= (size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value))
                            app.FAPoint2 = ((size(app.currentLoadedData{1},1)/app.SamplingRateSpinner.Value)-(1/app.SamplingRateSpinner.Value));
                        else
                            app.FAPoint2 = buttonClickXYZ(1,1);
                        end
                    catch
                        app.FAPoint1 = [];
                        app.FAPoint2 = [];
                        app.FAButton.Value = 0;
                    end
                end
    
                if(~isempty(app.FAPoint2) && ~isempty(app.FAPoint1))
                    tempData = app.getYData(app.IndividualMovementPlot,"Movement");
                    app.assignMetricValue("FA",mean(tempData(app.time2samples(app.FAPoint1):app.time2samples(app.FAPoint2)),'omitnan'));
                    app.generateSectionStatistics;
                    app.defaultMetricSelector;
                    app.FAButton.Value = 0;
                    app.FAPoint1 = [];
                    app.FAPoint2 = [];
                end
            end
        end

        % Button pushed function: SaveIndividualsButton
        function SaveIndividualsButtonPushed(app, event)
            waitfor(app.saveCurrentIndividuals);
        end

        % Value changed function: ScaleEnsembleVelocitytoAnalysisCheckBox
        function ScaleEnsembleVelocitytoAnalysisCheckBoxValueChanged(app, event)
            app.replotEnsembles;            
        end

        % Selection changed function: BehaviorTypeButtonGroup
        function BehaviorTypeButtonGroupSelectionChanged(app, event)
            app.replotIndividuals;
            app.replotEnsembles;
        end

        % Value changed function: TimeScrubberSlider
        function TimeScrubberSliderValueChanged(app, event)
            app.replotIndividuals;
        end

        % Value changed function: SamplingRateSpinner
        function SamplingRateSpinnerValueChanged(app, event)
            app.updateTimeScrubberLimit;
            app.replotIndividuals;
            app.replotEnsembles;            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.analysisContainer = uifigure('Visible', 'off');
            app.analysisContainer.Name = 'MATLAB App';
            app.analysisContainer.CloseRequestFcn = createCallbackFcn(app, @analysisContainerCloseRequest, true);
            app.analysisContainer.KeyPressFcn = createCallbackFcn(app, @analysisContainerKeyPress, true);
            app.MovementEnsemblePlot = uiaxes(app.analysisContainer);
            title(app.MovementEnsemblePlot, 'Movement Ensemble Plot')
            xlabel(app.MovementEnsemblePlot, 'Time (Seconds)')
            ylabel(app.MovementEnsemblePlot, 'Final Position')
            zlabel(app.MovementEnsemblePlot, 'Z')
            app.MovementEnsemblePlot.YAxisLocation = 'right';
            app.IndividualMovementPlot = uiaxes(app.analysisContainer);
            title(app.IndividualMovementPlot, 'Individual Movement Plot')
            xlabel(app.IndividualMovementPlot, 'Time (Seconds)')
            ylabel(app.IndividualMovementPlot, 'Final Positon')
            zlabel(app.IndividualMovementPlot, 'Z')
            app.IndividualMovementPlot.YAxisLocation = 'right';
            app.IndividualMovementPlot.ButtonDownFcn = createCallbackFcn(app, @IndividualMovementPlotButtonDown, true);
            app.VelocityEnsemblePlot = uiaxes(app.analysisContainer);
            title(app.VelocityEnsemblePlot, 'Velocity Ensemble Plot')
            xlabel(app.VelocityEnsemblePlot, 'Time (Seconds)')
            ylabel(app.VelocityEnsemblePlot, 'Velocity')
            zlabel(app.VelocityEnsemblePlot, 'Z')
            app.IndividualVelocityPlot = uiaxes(app.analysisContainer);
            title(app.IndividualVelocityPlot, 'Individual Velocity Plot')
            xlabel(app.IndividualVelocityPlot, 'Time (Seconds)')
            ylabel(app.IndividualVelocityPlot, 'Velocity')
            zlabel(app.IndividualVelocityPlot, 'Z')
            app.IndividualVelocityPlot.ButtonDownFcn = createCallbackFcn(app, @IndividualVelocityPlotButtonDown, true);
            app.CurrentFileSelectionLabel = uilabel(app.analysisContainer);
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            app.ofLabel = uilabel(app.analysisContainer);
            app.ofLabel.Text = 'of';
            app.currentFileIndexLabel = uilabel(app.analysisContainer);
            app.currentFileIndexLabel.Text = '###';
            app.totalFileCountLabel = uilabel(app.analysisContainer);
            app.totalFileCountLabel.Text = '###';
            app.LoadDataButton = uibutton(app.analysisContainer, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.previousMovementButton = uibutton(app.analysisContainer, 'push');
            app.previousMovementButton.ButtonPushedFcn = createCallbackFcn(app, @previousMovementButtonPushed, true);
            app.previousMovementButton.Text = 'PREVIOUS';
            app.nextMovementButton = uibutton(app.analysisContainer, 'push');
            app.nextMovementButton.ButtonPushedFcn = createCallbackFcn(app, @nextMovementButtonPushed, true);
            app.nextMovementButton.Text = 'NEXT';
            app.MetricTable = uitable(app.analysisContainer);
            app.MetricTable.ColumnName = {'RSI'; 'REI'; 'RA'; 'PVI'; 'PV'; 'FA'; 'RSID'; 'REID'; 'RAD'; 'PDROCI'; 'PDROC'; 'FD'; 'ID'; 'HClass'; 'VClass'; 'PClass'; 'Comments'};
            app.MetricTable.RowName = {'Left Eye'; 'Right Eye'; 'Binocular'};
            app.MetricTable.SelectionType = 'row';
            app.EyeSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.EyeSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @EyeSelectorButtonGroupSelectionChanged, true);
            app.EyeSelectorButtonGroup.Title = 'Eye Selector';
            app.LeftEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.LeftEyeButton.Text = 'Left Eye';
            app.BinocularButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.BinocularButton.Text = 'Binocular';
            app.BinocularButton.Value = true;
            app.RightEyeButton = uitogglebutton(app.EyeSelectorButtonGroup);
            app.RightEyeButton.Text = 'Right Eye';
            app.ClassificationSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.ClassificationSelectorButtonGroup.Title = 'Classification Selector';
            app.GoodButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.GoodButton.ButtonPushedFcn = createCallbackFcn(app, @GoodButtonPushed, true);
            app.GoodButton.Text = 'Good';
            app.BadButton = uibutton(app.ClassificationSelectorButtonGroup, 'push');
            app.BadButton.ButtonPushedFcn = createCallbackFcn(app, @BadButtonPushed, true);
            app.BadButton.Text = 'Bad';
            app.ClassificationCommentsDropDownLabel = uilabel(app.ClassificationSelectorButtonGroup);
            app.ClassificationCommentsDropDownLabel.Text = 'Classification Comments';
            app.ClassificationCommentsDropDown = uidropdown(app.ClassificationSelectorButtonGroup);
            app.ClassificationCommentsDropDown.Items = {'None', 'Asymmetrical', 'Loss of Fusion', 'Overshoot', 'Undershoot', 'Sequential Saccade', 'Saccade In Transient'};
            app.ClassificationCommentsDropDown.Editable = 'on';
            app.ClassificationCommentsDropDown.ValueChangedFcn = createCallbackFcn(app, @ClassificationCommentsDropDownValueChanged, true);
            app.ClassificationCommentsDropDown.Value = 'None';
            app.DataSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.DataSelectorButtonGroup.Title = 'Data Selector';
            app.ClassificationLabelsDropDown_2Label = uilabel(app.DataSelectorButtonGroup);
            app.ClassificationLabelsDropDown_2Label.Text = 'Data Channel Labels';
            app.DataChannelLabelsDropDown = uidropdown(app.DataSelectorButtonGroup);
            app.DataChannelLabelsDropDown.Items = {'Horizontal', 'Vertical', 'Pupil'};
            app.DataChannelLabelsDropDown.ValueChangedFcn = createCallbackFcn(app, @DataChannelLabelsDropDownValueChanged, true);
            app.DataChannelLabelsDropDown.Value = 'Horizontal';
            app.PlotVersionButton = uibutton(app.DataSelectorButtonGroup, 'state');
            app.PlotVersionButton.ValueChangedFcn = createCallbackFcn(app, @PlotVersionButtonValueChanged, true);
            app.PlotVersionButton.Text = 'Plot Version';
            app.NextFileButton = uibutton(app.analysisContainer, 'push');
            app.NextFileButton.ButtonPushedFcn = createCallbackFcn(app, @NextFileButtonPushed, true);
            app.NextFileButton.Text = 'Next File';
            app.PreviousFileButton = uibutton(app.analysisContainer, 'push');
            app.PreviousFileButton.ButtonPushedFcn = createCallbackFcn(app, @PreviousFileButtonPushed, true);
            app.PreviousFileButton.Text = 'Previous File';
            app.SaveButton = uibutton(app.analysisContainer, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SectionNameDropDownLabel = uilabel(app.analysisContainer);
            app.SectionNameDropDownLabel.Text = 'Section  Name';
            app.SectionNameDropDown = uidropdown(app.analysisContainer);
            app.SectionNameDropDown.ValueChangedFcn = createCallbackFcn(app, @SectionNameDropDownValueChanged, true);
            app.movementCounterOfLabel = uilabel(app.analysisContainer);
            app.movementCounterOfLabel.Text = 'of';
            app.currentMovementCountIndex = uilabel(app.analysisContainer);
            app.currentMovementCountIndex.Text = '###';
            app.totalMovementCount = uilabel(app.analysisContainer);
            app.totalMovementCount.Text = '###';
            app.MovementLabel = uilabel(app.analysisContainer);
            app.MovementLabel.Text = 'Movement';
            app.SamplingRateSpinnerLabel = uilabel(app.analysisContainer);
            app.SamplingRateSpinnerLabel.Text = 'Sampling Rate';
            app.SamplingRateSpinner = uispinner(app.analysisContainer);
            app.SamplingRateSpinner.Limits = [0 10000];
            app.SamplingRateSpinner.RoundFractionalValues = 'on';
            app.SamplingRateSpinner.ValueChangedFcn = createCallbackFcn(app, @SamplingRateSpinnerValueChanged, true);
            app.SamplingRateSpinner.Value = 500;
            app.SaveEnsemblesButton = uibutton(app.analysisContainer, 'push');
            app.SaveEnsemblesButton.ButtonPushedFcn = createCallbackFcn(app, @SaveEnsemblesButtonPushed, true);
            app.SaveEnsemblesButton.Text = 'Save Ensembles';
            app.SearchWindowSpinnerLabel = uilabel(app.analysisContainer);
            app.SearchWindowSpinnerLabel.Text = 'Search Window';
            app.SearchWindowSpinner = uispinner(app.analysisContainer);
            app.SearchWindowSpinner.Limits = [1 1000];
            app.SearchWindowSpinner.RoundFractionalValues = 'on';
            app.SearchWindowSpinner.Value = 10;
            app.MetricSelectorButtonGroup = uibuttongroup(app.analysisContainer);
            app.MetricSelectorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @MetricSelectorButtonGroupSelectionChanged, true);
            app.MetricSelectorButtonGroup.Title = 'Metric Selector';
            app.SelectRSIButton = uitogglebutton(app.MetricSelectorButtonGroup);
            app.SelectRSIButton.Text = 'Select RSI';
            app.SelectRSIButton.Value = true;
            app.SelectPeakVelocityButton = uitogglebutton(app.MetricSelectorButtonGroup);
            app.SelectPeakVelocityButton.Text = 'Select Peak Velocity';
            app.SelectREIButton = uitogglebutton(app.MetricSelectorButtonGroup);
            app.SelectREIButton.Text = 'Select REI';
            app.IDButton = uitogglebutton(app.MetricSelectorButtonGroup);
            app.IDButton.Text = 'ID';
            app.FAButton = uibutton(app.MetricSelectorButtonGroup, 'state');
            app.FAButton.Text = 'FA';
            app.SaveIndividualsButton = uibutton(app.analysisContainer, 'push');
            app.SaveIndividualsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveIndividualsButtonPushed, true);
            app.SaveIndividualsButton.Text = 'Save Individuals';
            app.Label = uilabel(app.analysisContainer);
            app.Label.Text = '';
            app.AnalyzedSectionMetricReferencePanel = uipanel(app.analysisContainer);
            app.AnalyzedSectionMetricReferencePanel.Title = 'Analyzed Section Metric Reference';
            app.SectionBehaviorTable = uitable(app.AnalyzedSectionMetricReferencePanel);
            app.SectionBehaviorTable.ColumnName = {'RA'; 'FA'; 'PV'};
            app.SectionBehaviorTable.RowName = {};
            app.SectionBehaviorTable.SelectionType = 'row';
            app.SectionBehaviorTable.Multiselect = 'off';
            app.ScaleEnsembleVelocitytoAnalysisCheckBox = uicheckbox(app.analysisContainer);
            app.ScaleEnsembleVelocitytoAnalysisCheckBox.ValueChangedFcn = createCallbackFcn(app, @ScaleEnsembleVelocitytoAnalysisCheckBoxValueChanged, true);
            app.ScaleEnsembleVelocitytoAnalysisCheckBox.Text = 'Scale Ensemble Velocity to Analysis';
            app.BehaviorTypeButtonGroup = uibuttongroup(app.analysisContainer);
            app.BehaviorTypeButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @BehaviorTypeButtonGroupSelectionChanged, true);
            app.BehaviorTypeButtonGroup.Title = 'Behavior Type';
            app.SummationButton = uitogglebutton(app.BehaviorTypeButtonGroup);
            app.SummationButton.Text = 'Summation';
            app.SummationButton.Value = true;
            app.AverageButton = uitogglebutton(app.BehaviorTypeButtonGroup);
            app.AverageButton.Text = 'Average';
            app.TimeScrubberSlider = uislider(app.analysisContainer);
            app.TimeScrubberSlider.Limits = [1 10];
            app.TimeScrubberSlider.ValueChangedFcn = createCallbackFcn(app, @TimeScrubberSliderValueChanged, true);
            app.TimeScrubberSlider.Enable = 'off';
            app.TimeScrubberSlider.Value = 1;
            app.NaNifyButton = uibutton(app.analysisContainer, 'state');
            app.NaNifyButton.Text = 'NaNify';
            % Show the figure after all components are created
            app.analysisContainer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = stepAnalysisModule(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.analysisContainer)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.analysisContainer)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.analysisContainer)
        end
    end
end