classdef mainAnalysisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainAnalysisUIFigure      matlab.ui.Figure
        EXPORTANALYSISButton      matlab.ui.control.Button
        SamplingRateSpinner       matlab.ui.control.Spinner
        SamplingRateSpinnerLabel  matlab.ui.control.Label
        FFTAnalysisButton         matlab.ui.control.Button
        ANALYSISMODULELabel       matlab.ui.control.Label
        SACCADEANALYSISButton     matlab.ui.control.Button
        RAMPANALYSISButton        matlab.ui.control.Button
        STEPANALYSISButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Store Figure Handle to Call On-Close
        previousFigureHandle 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, previousFigureHandle)
            movegui(app.mainAnalysisUIFigure,'center');
            app.previousFigureHandle = previousFigureHandle;
        end

        % Button pushed function: STEPANALYSISButton
        function STEPANALYSISButtonPushed(app, event)
            set(app.mainAnalysisUIFigure,"WindowState",'minimized');
            stepAnalysisModule(app.mainAnalysisUIFigure);
        end

        % Button pushed function: RAMPANALYSISButton
        function RAMPANALYSISButtonPushed(app, event)
            set(app.mainAnalysisUIFigure,"WindowState",'minimized');
            rampAnalysisModule;
        end

        % Button pushed function: SACCADEANALYSISButton
        function SACCADEANALYSISButtonPushed(app, event)
            set(app.mainAnalysisUIFigure,"WindowState",'minimized');
            saccadeAnalysisModule;
        end

        % Button pushed function: FFTAnalysisButton
        function FFTAnalysisButtonPushed(app, event)
            set(app.mainAnalysisUIFigure,"WindowState",'minimized');
            fftAnalysisModule;
        end

        % Button pushed function: EXPORTANALYSISButton
        function EXPORTANALYSISButtonPushed(app, event)
            exportMetrics.exportMetricData;
        end

        % Close request function: mainAnalysisUIFigure
        function mainAnalysisUIFigureCloseRequest(app, event)
            userSelection = uiconfirm(app.mainAnalysisUIFigure,"Close Analysis Module?","Confirm Exit","Icon","question");
            switch userSelection
                case "OK"
                    try
                        set(app.previousFigureHandle,"WindowState",'normal');
                        delete(app)
                    catch
                        delete(app)
                    end
            end            
        end

        % Key press function: mainAnalysisUIFigure
        function mainAnalysisUIFigureKeyPress(app, event)
            switch event.Key
                case "escape"
                    app.mainAnalysisUIFigureCloseRequest;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainAnalysisUIFigure = uifigure('Visible', 'off');
            app.mainAnalysisUIFigure.Name = 'MATLAB App';
            app.mainAnalysisUIFigure.CloseRequestFcn = createCallbackFcn(app, @mainAnalysisUIFigureCloseRequest, true);
            app.mainAnalysisUIFigure.KeyPressFcn = createCallbackFcn(app, @mainAnalysisUIFigureKeyPress, true);
            app.STEPANALYSISButton = uibutton(app.mainAnalysisUIFigure, 'push');
            app.STEPANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @STEPANALYSISButtonPushed, true);
            app.STEPANALYSISButton.Text = 'STEP ANALYSIS';
            app.RAMPANALYSISButton = uibutton(app.mainAnalysisUIFigure, 'push');
            app.RAMPANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @RAMPANALYSISButtonPushed, true);
            app.RAMPANALYSISButton.Text = 'RAMP ANALYSIS';
            app.SACCADEANALYSISButton = uibutton(app.mainAnalysisUIFigure, 'push');
            app.SACCADEANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @SACCADEANALYSISButtonPushed, true);
            app.SACCADEANALYSISButton.Text = 'SACCADE ANALYSIS';
            app.ANALYSISMODULELabel = uilabel(app.mainAnalysisUIFigure);
            app.ANALYSISMODULELabel.Text = 'ANALYSIS MODULE';
            app.FFTAnalysisButton = uibutton(app.mainAnalysisUIFigure, 'push');
            app.FFTAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @FFTAnalysisButtonPushed, true);
            app.FFTAnalysisButton.Text = 'FFT Analysis';
            app.SamplingRateSpinnerLabel = uilabel(app.mainAnalysisUIFigure);
            app.SamplingRateSpinnerLabel.Text = 'Sampling Rate';
            app.SamplingRateSpinner = uispinner(app.mainAnalysisUIFigure);
            app.SamplingRateSpinner.Limits = [0 10000];
            app.SamplingRateSpinner.RoundFractionalValues = 'on';
            app.SamplingRateSpinner.Value = 500;
            app.EXPORTANALYSISButton = uibutton(app.mainAnalysisUIFigure, 'push');
            app.EXPORTANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTANALYSISButtonPushed, true);
            app.EXPORTANALYSISButton.Text = 'EXPORT ANALYSIS';
            % Show the figure after all components are created
            app.mainAnalysisUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainAnalysisModule(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mainAnalysisUIFigure)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.mainAnalysisUIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainAnalysisUIFigure)
        end
    end
end