classdef calibrationModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        calibrationContainer            matlab.ui.Figure
        PlotBehaviorButtonGroup         matlab.ui.container.ButtonGroup
        AverageButton                   matlab.ui.control.ToggleButton
        SummationButton                 matlab.ui.control.ToggleButton
        DataSavedLamp                   matlab.ui.control.Lamp
        NEXTButton                      matlab.ui.control.Button
        PREVIOUSButton                  matlab.ui.control.Button
        SAVEDATAButton                  matlab.ui.control.Button
        totalFileCountLabel             matlab.ui.control.Label
        currentFileIndexLabel           matlab.ui.control.Label
        ofLabel                         matlab.ui.control.Label
        FileLabel                       matlab.ui.control.Label
        GainEvaluationPanel             matlab.ui.container.Panel
        TrimmeanSpinner                 matlab.ui.control.Spinner
        TrimmeanSpinnerLabel            matlab.ui.control.Label
        RightGainEvaluation             matlab.ui.control.NumericEditField
        RightEditFieldLabel             matlab.ui.control.Label
        LeftGainEvaluation              matlab.ui.control.NumericEditField
        LeftEditFieldLabel              matlab.ui.control.Label
        MovementSelectionDropDown       matlab.ui.control.DropDown
        MovementSelectionDropDownLabel  matlab.ui.control.Label
        BinocularGainEvaluation         matlab.ui.control.NumericEditField
        BinocularLabel_2                matlab.ui.control.Label
        EndIndexSpinner                 matlab.ui.control.Spinner
        EndIndexSpinnerLabel            matlab.ui.control.Label
        StartIndexSpinner               matlab.ui.control.Spinner
        StartIndexSpinnerLabel          matlab.ui.control.Label
        RegressionInformationPanel      matlab.ui.container.Panel
        PupilConversionFactor_RightEye  matlab.ui.control.NumericEditField
        RightPupilGainLabel             matlab.ui.control.Label
        PupilConversionFactor_LeftEye   matlab.ui.control.NumericEditField
        LeftPupilGainLabel              matlab.ui.control.Label
        LeftEyeGainValue                matlab.ui.control.Spinner
        RightEyeGainValue               matlab.ui.control.Spinner
        RightEyeGainLabel               matlab.ui.control.Label
        LeftEyeGainLabel                matlab.ui.control.Label
        RightRSquaredValue              matlab.ui.control.Label
        RightRSquaredLabel              matlab.ui.control.Label
        LeftRSquaredValue               matlab.ui.control.Label
        LeftRSquaredLabel               matlab.ui.control.Label
        BinocularRSquaredValue          matlab.ui.control.Label
        BinocularRSquaredLabel          matlab.ui.control.Label
        MovementSelectionPanel          matlab.ui.container.Panel
        MovementDisplayDropDown6        matlab.ui.control.DropDown
        MovementDisplayDropDown_6Label  matlab.ui.control.Label
        MovementDisplayDropDown5        matlab.ui.control.DropDown
        MovementDisplayDropDown_5Label  matlab.ui.control.Label
        MovementDisplayDropDown4        matlab.ui.control.DropDown
        MovementDisplayDropDown_4Label  matlab.ui.control.Label
        MovementDisplayDropDown3        matlab.ui.control.DropDown
        MovementDisplayDropDown_3Label  matlab.ui.control.Label
        MovementDisplayDropDown2        matlab.ui.control.DropDown
        MovementDisplayDropDown_2Label  matlab.ui.control.Label
        MovementDisplayDropDown1        matlab.ui.control.DropDown
        MovementDisplayDropDownLabel    matlab.ui.control.Label
        MovementDropDown6               matlab.ui.control.DropDown
        Movement6DropDownLabel          matlab.ui.control.Label
        MovementDropDown5               matlab.ui.control.DropDown
        Movement5DropDownLabel          matlab.ui.control.Label
        MovementDropDown4               matlab.ui.control.DropDown
        Movement4DropDownLabel          matlab.ui.control.Label
        MovementDropDown3               matlab.ui.control.DropDown
        Movement3DropDownLabel          matlab.ui.control.Label
        MovementDropDown2               matlab.ui.control.DropDown
        Movement2DropDownLabel          matlab.ui.control.Label
        MovementDropDown1               matlab.ui.control.DropDown
        Movement1DropDownLabel          matlab.ui.control.Label
        CurrentFileSelectionLabel       matlab.ui.control.Label
        LoadDataButton                  matlab.ui.control.Button
        CALIBRATIONButtonGroup          matlab.ui.container.ButtonGroup
        CalibrationOverviewPanel        matlab.ui.container.Panel
        PupilLabel                      matlab.ui.control.Label
        VerticalLabel                   matlab.ui.control.Label
        HorizontalLabel                 matlab.ui.control.Label
        LeftEyeLabel                    matlab.ui.control.Label
        RightEyeLabel                   matlab.ui.control.Label
        LeftEyePupilLamp                matlab.ui.control.Lamp
        RightEyePupilLamp               matlab.ui.control.Lamp
        LeftEyeHorizontalLamp           matlab.ui.control.Lamp
        RightEyeHorizontalLamp          matlab.ui.control.Lamp
        LeftEyeVerticalLamp             matlab.ui.control.Lamp
        RightEyeVerticalLamp            matlab.ui.control.Lamp
        SelectEyetoCalibratePanel       matlab.ui.container.Panel
        LeftEyeCalibrationButton        matlab.ui.control.Button
        RightEyeCalibrationButton       matlab.ui.control.Button
        CalibrationSelectionPanel       matlab.ui.container.Panel
        Calibration6PositionSpinner     matlab.ui.control.Spinner
        Calibration6PositionSpinnerLabel  matlab.ui.control.Label
        Calibration5PositionSpinner     matlab.ui.control.Spinner
        Calibration5PositionSpinnerLabel  matlab.ui.control.Label
        Calibration4PositionSpinner     matlab.ui.control.Spinner
        Calibration4PositionSpinnerLabel  matlab.ui.control.Label
        Calibration3PositionSpinner     matlab.ui.control.Spinner
        Calibration3PositionSpinnerLabel  matlab.ui.control.Label
        Calibration2PositionSpinner     matlab.ui.control.Spinner
        Calibration2PositionSpinnerLabel  matlab.ui.control.Label
        Calibration1PositionSpinner     matlab.ui.control.Spinner
        Calibration1PositionSpinnerLabel  matlab.ui.control.Label
        CalibrationTypeDropDown         matlab.ui.control.DropDown
        CalibrationTypeDropDownLabel    matlab.ui.control.Label
        CalibrationDropDown1            matlab.ui.control.DropDown
        CalibrationDropDown2            matlab.ui.control.DropDown
        CalibrationDropDown3            matlab.ui.control.DropDown
        CalibrationDropDown4            matlab.ui.control.DropDown
        CalibrationDropDown5            matlab.ui.control.DropDown
        CalibrationDropDown6            matlab.ui.control.DropDown
        Calibration6DropDownLabel       matlab.ui.control.Label
        Calibration5DropDownLabel       matlab.ui.control.Label
        Calibration4DropDownLabel       matlab.ui.control.Label
        Calibration3DropDownLabel       matlab.ui.control.Label
        Calibration2DropDownLabel       matlab.ui.control.Label
        Calibration1DropDownLabel       matlab.ui.control.Label
        EyeMovementFigure               matlab.ui.control.UIAxes
        CalibrationFitting              matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        calibrationBinocularPlots = cell(6,1); % Empty Cell for Calibration Binocular Plotting
        calibrationGainTable; % Table of Gains (Default 1)
        calibrationLeftPlots = cell(6,1); % Empty Cell for Calibration Left Plotting
        calibrationRightPlots = cell(6,1); % Empty Cell for Calibration Right Plotting
        calibrationRegressionPlotBinocular = {}% Plot Binocular Variable for Linear Regression
        calibrationRegressionPlotRight = {}% Plot Binocular Variable for Linear Regression
        calibrationRegressionPlotLeft = {}% Plot Binocular Variable for Linear Regression
        calibratedTableDataSet % Contains Calibrated Data
        dataLoadedBoolean = false; % Boolean Flag for Load Command
        currentFileIndex = 1; % Position in Stack of Selected Files
        elementFieldNames; % Array of Fieldnames
        gainButtonCalled = false; % Boolean for Gain Button Modification
        gainStartIndex % Plot Variable for Gain Evaluation
        gainEndIndex % Plot Variable for Gain Evaluation
        maxSamples % Maximum Samples in Movement Data Set Plotted
        movementBinocularPlots = cell(6,1); % Empty Cell for Binocular Plotting
        movementLeftPlots = cell(6,1); % Empty Cell for Left Plotting
        movementLoadDataButtonPressed = false; % Data Movement Button (Default State is False)
        movementRightPlots = cell(6,1); % Empty Cell for Right Plotting
        nextDataButtonPressed = false; % Boolean to Evaluate Button Press
        previousDataButtonPressed = false; % Boolean to Evaluate Button Press
        previousFigureHandle; % Previous Figure
        selectedMovementData % Movement Data for Movement Plotting
        tableData % Loaded Subject Table Data
        totalSelectedFiles = 1; % Number of Selected Files
        userSelectedFilePath % File Pathway from User Selection
        userSelectedFile % File Name from User Selection
    end
    
    methods (Access = private)
        
        % Find Indices for "Cal" Movement within Data Table
        % Return Index and Name of Movement
        function [calibrationColIndex, calibrationNameList] = identCals(app)
            calibrationColIndex = contains(app.tableData.Properties.VariableNames,'cal','IgnoreCase',true);
            calibrationNameList = app.tableData.Properties.VariableNames(calibrationColIndex);
        end
        
        % Add Default Dropdown Value and Identified Calibration Movements
        function updateDropdowns(app,dropdownNames,dropdownType)
            dropdownElements = app.elementFieldNames(contains(app.elementFieldNames, strcat(dropdownType,'Dropdown')+digitsPattern,'IgnoreCase',true));
            for elementIndex = 1:size(dropdownElements,1)
                app.(dropdownElements{elementIndex}).Items = horzcat("None",dropdownNames);
            end
        end
        
        % Plot Calibration Averages
        function calibrationPlotter(app, dropdownNumber)
            % Check if Data is Loaded
            if(app.validateDataLoad)
                % Validate "None" is not selected
                if(~strcmp(app.("CalibrationDropDown"+dropdownNumber).Value,'None'))
                    % Collected Data from Table Data via Provided Dropdown
                    % Index and extract Data Movement

                    % Calibration Data is ALWAYS averaged Binocularly
                    selectedCalibrationDataBinocular = mean([app.tableData.(app.("CalibrationDropDown"+dropdownNumber).Value){1}.(strcat("Left Eye ",app.CalibrationTypeDropDown.Value)),app.tableData.(app.("CalibrationDropDown"+dropdownNumber).Value){1}.(strcat("Right Eye ",app.CalibrationTypeDropDown.Value))],2);
                    selectedCalibrationDataLeft = app.tableData.(app.("CalibrationDropDown"+dropdownNumber).Value){1}.(strcat("Left Eye ",app.CalibrationTypeDropDown.Value));
                    selectedCalibrationDataRight = app.tableData.(app.("CalibrationDropDown"+dropdownNumber).Value){1}.(strcat("Right Eye ",app.CalibrationTypeDropDown.Value));

                    % Validate Calibration Position is not -999 (Default)
                    if(app.("Calibration"+dropdownNumber+"PositionSpinner").Value ~= -999)
                        % Delete Old Plot if Exists
                        if(~isempty(app.calibrationBinocularPlots{dropdownNumber}))
                            delete(app.calibrationBinocularPlots{dropdownNumber})
                            delete(app.calibrationLeftPlots{dropdownNumber})
                            delete(app.calibrationRightPlots{dropdownNumber})
    
                            app.calibrationBinocularPlots{dropdownNumber} = [];
                            app.calibrationLeftPlots{dropdownNumber} = [];
                            app.calibrationRightPlots{dropdownNumber}  = [];
                        end
                        % Plot Calibration Mean Binocular
                        app.calibrationBinocularPlots{dropdownNumber} = plot(app.CalibrationFitting,app.("Calibration"+dropdownNumber+"PositionSpinner").Value,trimmean(selectedCalibrationDataBinocular,50),"*b");
                        hold(app.CalibrationFitting,"on")
                        % Plot Calibration Mean Left
                        app.calibrationLeftPlots{dropdownNumber} = plot(app.CalibrationFitting,app.("Calibration"+dropdownNumber+"PositionSpinner").Value,trimmean(selectedCalibrationDataLeft,50),"*g");
                        hold(app.CalibrationFitting,"on")
                        % Plot Calibration Mean Right
                        app.calibrationRightPlots{dropdownNumber} = plot(app.CalibrationFitting,app.("Calibration"+dropdownNumber+"PositionSpinner").Value,trimmean(selectedCalibrationDataRight,50),"*r");
                        hold(app.CalibrationFitting,"on")
                    end
                else
                    % Calibration Dropdown Switched to "None"
                    if(app.("Calibration"+dropdownNumber+"PositionSpinner").Value ~= -999)
                        % Delete Old Plot if Exists
                        if(~isempty(app.calibrationBinocularPlots{dropdownNumber}))
                            delete(app.calibrationBinocularPlots{dropdownNumber})
                            delete(app.calibrationLeftPlots{dropdownNumber})
                            delete(app.calibrationRightPlots{dropdownNumber})
    
                            app.calibrationBinocularPlots{dropdownNumber} = [];
                            app.calibrationLeftPlots{dropdownNumber} = [];
                            app.calibrationRightPlots{dropdownNumber}  = [];
                        end
                    end
                end
    
                % Calculate Regression
                app.regressionCalculator;
    
                % Replot Movement Plots
                app.activateMovementDropdown;
            end
        end
        
        function movementNames = identMovements(app, calibrationMovementIndex)
            % Return All Movements that are NOT "Cal"-ibrations
            movementNames = app.tableData.Properties.VariableNames(~calibrationMovementIndex);
        end
        
        
        function regressionCalculator(app)
            % Validate Calibration Plots Exist >2 and 
            % ANY are valid plots for replotting
            if(size([app.calibrationBinocularPlots{:}],2) >= 2 && any(isvalid([app.calibrationBinocularPlots{:}])))
                % Extract Plot Data
                % Binocular
                calibrationLineObjectsBinocular = [app.calibrationBinocularPlots{:}]';
                % Left
                calibrationLineObjectsLeft = [app.calibrationLeftPlots{:}]';
                % Right
                calibrationLineObjectsRight = [app.calibrationRightPlots{:}]';

                % Iterate over Plots to Extract X and Y Data
                validLineDataIndex = find(isvalid(calibrationLineObjectsBinocular(:))); 
                for objectIndex = 1:length(validLineDataIndex)
                    % Binocular
                    calibrationXDataBinocular(objectIndex) = calibrationLineObjectsBinocular(validLineDataIndex(objectIndex)).XData;
                    calibrationYDataBinocular(objectIndex) = calibrationLineObjectsBinocular(validLineDataIndex(objectIndex)).YData;

                    % Left
                    calibrationXDataLeft(objectIndex) = calibrationLineObjectsLeft(validLineDataIndex(objectIndex)).XData;
                    calibrationYDataLeft(objectIndex) = calibrationLineObjectsLeft(validLineDataIndex(objectIndex)).YData;

                    % Right
                    calibrationXDataRight(objectIndex) = calibrationLineObjectsRight(validLineDataIndex(objectIndex)).XData;
                    calibrationYDataRight(objectIndex) = calibrationLineObjectsRight(validLineDataIndex(objectIndex)).YData;
                end

                % Delete Old Regression Plots
                if(~isempty(app.calibrationRegressionPlotBinocular))
                    % Binocular
                    delete(app.calibrationRegressionPlotBinocular{1})

                    % Left
                    delete(app.calibrationRegressionPlotLeft{1})

                    % Right
                    delete(app.calibrationRegressionPlotRight{1})
                end

                % Regression Calculations
                % Binocular
                [linearFittingCoefficientsBinocular, ~] = polyfit(calibrationXDataBinocular(:),calibrationYDataBinocular(:),1);
                % Left
                [linearFittingCoefficientsLeft, ~] = polyfit(calibrationXDataLeft(:),calibrationYDataLeft(:),1);
                % Right
                [linearFittingCoefficientsRight, ~] = polyfit(calibrationXDataRight(:),calibrationYDataRight(:),1);

                % Reconstruct Fitting
                % Binocular
                linearReconstructionBinocular = linearFittingCoefficientsBinocular(1) * calibrationXDataBinocular(:) + linearFittingCoefficientsBinocular(2);
                % Left
                linearReconstructionLeft = linearFittingCoefficientsLeft(1) * calibrationXDataLeft(:) + linearFittingCoefficientsLeft(2);
                % Right
                linearReconstructionRight = linearFittingCoefficientsRight(1) * calibrationXDataRight(:) + linearFittingCoefficientsRight(2);

                % Find Difference of Fitting
                % Binocular
                linearFittingResidualsBinocular = calibrationYDataBinocular(:) - linearReconstructionBinocular(:);
                % Left
                linearFittingResidualsLeft = calibrationYDataLeft(:) - linearReconstructionLeft(:);
                % Right
                linearFittingResidualsRight = calibrationYDataRight(:) - linearReconstructionRight(:);

                % Sum of Squares
                % Binocular
                sumResidualsBinocular = sum(linearFittingResidualsBinocular.^2);
                % Left
                sumResidualsLeft = sum(linearFittingResidualsLeft.^2);
                % Right
                sumResidualsRight = sum(linearFittingResidualsRight.^2);

                % Total Sum of Squares
                % Binocular
                sumTotalsBinocular = (length(calibrationYDataBinocular)-1) * var(calibrationYDataBinocular(:));
                % Left
                sumTotalsLeft = (length(calibrationYDataLeft)-1) * var(calibrationYDataLeft(:));
                % Right
                sumTotalsRight = (length(calibrationYDataRight)-1) * var(calibrationYDataRight(:));

                % Calculate r-Squared
                % Binocular
                rSquaredBinocular = 1 - sumResidualsBinocular/sumTotalsBinocular;
                % Left
                rSquaredLeft = 1 - sumResidualsLeft/sumTotalsLeft;
                % Right
                rSquaredRight = 1 - sumResidualsRight/sumTotalsRight;

                % Update R-Squared Value
                % Binocular
                app.BinocularRSquaredValue.Text = num2str(rSquaredBinocular);
                % Left
                app.LeftRSquaredValue.Text = num2str(rSquaredLeft);
                app.LeftEyeGainValue.Value = 1/abs(linearFittingCoefficientsLeft(1));
                % Right
                app.RightRSquaredValue.Text = num2str(rSquaredRight);
                app.RightEyeGainValue.Value = 1/abs(linearFittingCoefficientsRight(1));

                % Plot Linear Regression
                % Binocular
                app.calibrationRegressionPlotBinocular{1} = plot(app.CalibrationFitting,calibrationXDataBinocular,linearReconstructionBinocular,"Color",'blue');
                % Left
                app.calibrationRegressionPlotLeft{1} = plot(app.CalibrationFitting,calibrationXDataLeft,linearReconstructionLeft,"Color",'green');
                % Right
                app.calibrationRegressionPlotRight{1} = plot(app.CalibrationFitting,calibrationXDataRight,linearReconstructionRight,"Color",'red');

            end
        end

        function extractedTableData = tableDataExtractor(app, cellTableArray, extractionHeader)
            % Determine Largest Sample Length for Plotting
            maxSamplesList = cellfun(@max,cellfun(@size,cellTableArray,'UniformOutput',false));
            app.maxSamples = max(maxSamplesList);

            % Preallocate Space 
            % Number of Rows based on First Cell Array Value
            % Number of Cols based on Size of Cell Array
            extractedTableData = nan(app.maxSamples,size(cellTableArray,1));
            for cellIndex = 1:size(cellTableArray,1)
                extractedTableData(1:maxSamplesList(cellIndex),cellIndex) = cellTableArray{cellIndex}.(extractionHeader);
            end
        end
        
        function movementPlotter(app, dropdownNumber)
            % Check if Data is Loaded
            if(app.validateDataLoad)
                % Validate "None" is not selected
                if(~strcmp(app.("MovementDropDown"+dropdownNumber).Value,'None'))
                    % Extract Data
                    [binocularMovementData,leftMovementData,rightMovementData] = returnDropdownData(app,dropdownNumber,[]);
                   
                    % Delete Old Plot if Exists
                    if(~isempty(app.movementBinocularPlots{dropdownNumber}))
                        delete(app.movementBinocularPlots{dropdownNumber})
                        delete(app.movementLeftPlots{dropdownNumber})
                        delete(app.movementRightPlots{dropdownNumber})
    
                        % Clear Variable
                        app.movementBinocularPlots{dropdownNumber} = [];
                        app.movementLeftPlots{dropdownNumber} = [];
                        app.movementRightPlots{dropdownNumber} = [];
    
                    end

                    % Change sign Convention for User
                    % Left Eye is Positive Inward and Right Eye is Inverted
                    if(contains(app.PlotBehaviorButtonGroup.SelectedObject.Text,"Summation"))
                        rightMovementData = -1*rightMovementData;
                    end
    
                    % Default Set to Ensemble
                    % Binocular Default is Summation
                    plotBinocularData = binocularMovementData;
                    plotLeftData = leftMovementData;
                    plotRightData = rightMovementData;
                    alphaValue = .1;
                    lineWidthValue = .5;
    
                    % Determine Plotting Output if Average
                    if(contains(app.("MovementDisplayDropDown"+num2str(dropdownNumber)).Value,"Average"))
                        plotBinocularData = mean(binocularMovementData,2,'omitnan');
                        plotLeftData = mean(leftMovementData,2,'omitnan');
                        plotRightData = mean(rightMovementData,2,'omitnan');
                        alphaValue = 1;
                        lineWidthValue =2 ;
                    end
        
                    % Determine Calibration Factors for Plotting
                    if(contains(app.CalibrationTypeDropDown.Value,'Pupil'))
                        binocularGain = (app.PupilConversionFactor_LeftEye.Value + app.PupilConversionFactor_RightEye.Value)/2;
                        leftEyeGain = app.PupilConversionFactor_LeftEye.Value;
                        rightEyeGain = app.PupilConversionFactor_RightEye.Value;
                    else
                        binocularGain = (app.LeftEyeGainValue.Value+app.RightEyeGainValue.Value)/2;
                        leftEyeGain = app.LeftEyeGainValue.Value;
                        rightEyeGain = app.RightEyeGainValue.Value;
                    end
                    % Ensure Tick Axes are Auto
                    xticks(app.EyeMovementFigure,'auto');
                    yticks(app.EyeMovementFigure,'auto');
    
                    % Plot Binocular
                    app.movementBinocularPlots{dropdownNumber} = plot(app.EyeMovementFigure,plotBinocularData .* binocularGain,'Color', [0, 0, 1, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
                    % Plot Left
                    app.movementLeftPlots{dropdownNumber} = plot(app.EyeMovementFigure,plotLeftData .* leftEyeGain,'Color', [0, 1, 0, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
                    % Plot Right
                    app.movementRightPlots{dropdownNumber} = plot(app.EyeMovementFigure,plotRightData .* rightEyeGain,'Color', [1, 0, 0, alphaValue],'LineWidth',lineWidthValue);
                    hold(app.EyeMovementFigure,"on")
    
                    % New Movement New Gain
                    if(~app.gainButtonCalled)
                        app.movementLoadDataButtonPressed = true;
                    end
    
                    app.updateGainSpinners;
                    app.gainEvaluation;
    
                    % Reset Boolean
                    app.movementLoadDataButtonPressed = false;
    
                else
                    % Dropdown Switched to "None"
                    if(~isempty(app.movementBinocularPlots{dropdownNumber}))
                        delete(app.movementBinocularPlots{dropdownNumber})
                        delete(app.movementLeftPlots{dropdownNumber})
                        delete(app.movementRightPlots{dropdownNumber})
                    end
                end
            end
        end
        
        function activateMovementDropdown(app)
            if(app.validateDataLoad)
                movementDropdownNames = app.elementFieldNames(find(contains(app.elementFieldNames,"MovementDropDown"+digitsPattern)));
                app.movementDropdownValueChange(movementDropdownNames)
            end
        end
        
        function movementDropdownValueChange(app, movementDropdownName)
            for movementNames = 1:size(movementDropdownName,1)
                app.(strcat(movementDropdownName{movementNames},'ValueChanged'));
            end
        end
        
        function gainEvaluation(app)
            % Ensure that Indices for Start and End are Valid
            if(app.EndIndexSpinner.Value >= app.StartIndexSpinner.Value)
                
                % Extract Data
                [binocularMovementData,leftMovementData,rightMovementData] = returnDropdownData(app,[],app.MovementSelectionDropDown.Value);

                % Adjust Values and Plots
                app.updateGainSpinners;

                % "None" Selection Returns Nothing
                if(~isempty(binocularMovementData))
                    try
                        % Calculate and Update Fields
                        app.BinocularGainEvaluation.Value = ((app.LeftEyeGainValue.Value+app.RightEyeGainValue.Value)/2)*trimmean(mean(binocularMovementData(app.StartIndexSpinner.Value:app.EndIndexSpinner.Value,:),2,'omitnan'),app.TrimmeanSpinner.Value,1);
                        app.LeftGainEvaluation.Value = app.LeftEyeGainValue.Value*trimmean(mean(leftMovementData((app.StartIndexSpinner.Value):(app.EndIndexSpinner.Value),:),2,'omitnan'),app.TrimmeanSpinner.Value,1);
                        app.RightGainEvaluation.Value = app.RightEyeGainValue.Value*trimmean(mean(rightMovementData((app.StartIndexSpinner.Value):(app.EndIndexSpinner.Value),:),2,'omitnan'),app.TrimmeanSpinner.Value,1);
                    catch
                        warndlg("Trimmean Set Too High!", "Trimmean Warning","modal");
                        app.TrimmeanSpinner.Value = 1;
                        app.BinocularGainEvaluation.Value = ((app.LeftEyeGainValue.Value+app.RightEyeGainValue.Value)/2)*trimmean(mean(binocularMovementData(app.StartIndexSpinner.Value:app.EndIndexSpinner.Value,:),2,'omitnan'),app.TrimmeanSpinner.Value,1);
                        app.LeftGainEvaluation.Value = app.LeftEyeGainValue.Value*trimmean(mean(leftMovementData((app.StartIndexSpinner.Value):(app.EndIndexSpinner.Value),:),2,'omitnan'),app.TrimmeanSpinner.Value,1);
                        app.RightGainEvaluation.Value = app.RightEyeGainValue.Value*trimmean(mean(rightMovementData((app.StartIndexSpinner.Value):(app.EndIndexSpinner.Value),:),2,'omitnan'),app.TrimmeanSpinner.Value,1);

                    end
                else
                    % If "None" or Empty return default Movement 1
                    app.MovementSelectionDropDown.Value = app.MovementSelectionDropDown.Items{1};
                end
            end
        end
        
        function [binocularMovementData,leftMovementData,rightMovementData] = returnDropdownData(app,dropdownNumber,directDropdownCall)

            % Two Conditions to Check
            % Number has priority
            if(~isempty(dropdownNumber))
                dropdownCall = ("MovementDropDown"+dropdownNumber);
            else
                dropdownCall = strrep(directDropdownCall," ","DropDown");
            end

            % Create Defaults
            leftMovementData = [];
            rightMovementData = [];
            binocularMovementData = [];

             % Validate "None" is not selected and Valid Movement Selection
            if(~strcmp(app.(dropdownCall).Value,'None'))
                % Collected Data from Table Data via Provided Dropdown
                % Index Data and Extract Cell Array
                app.selectedMovementData = {app.tableData.(app.(dropdownCall).Value){~cellfun(@isempty,app.tableData.(app.(dropdownCall).Value))}}';

                % Selectively Extract Data
                leftMovementData = app.tableDataExtractor(app.selectedMovementData,strcat("Left Eye ",app.CalibrationTypeDropDown.Value));
                rightMovementData = app.tableDataExtractor(app.selectedMovementData,strcat("Right Eye ",app.CalibrationTypeDropDown.Value));
                binocularMovementData = leftMovementData - rightMovementData;

                if(contains(app.PlotBehaviorButtonGroup.SelectedObject.Text, "Average"))
                    binocularMovementData = mean([leftMovementData,rightMovementData],2);
                end
            end

        end
        
        % Clear All Plots
        function clearFigureAxes(app)
            cla(app.EyeMovementFigure);
            cla(app.CalibrationFitting);
        end
        
        % Update Label for Current File Index
        function updateFileIndexLabels(app)
            app.currentFileIndexLabel.Text = num2str(app.currentFileIndex);
        end
        
        % Set All DropDown Values to Default "None"
        function resetDropdownPlotValue(app)
            dropdownNames = app.elementFieldNames(find(contains(app.elementFieldNames,"CalibrationDropDown"+digitsPattern) + contains(app.elementFieldNames,"MovementDropDown"+digitsPattern)));
            app.refreshDropdownPlots(dropdownNames);
        end
        
        % Identify Dropdowns and Change to "None" 
        function refreshDropdownPlots(app, dropDown)
            for cellIndex = 1:size(dropDown,1)
                % Check Dropdown for "None" or Change to "None"
                if(~strcmp(app.(dropDown{cellIndex}).Value, "None"))
                    app.(strcat(dropDown{cellIndex},'ValueChanged'));
                end
            end
        end
        
        % Returns Table Directory and Filename(s)
        function getTablePathing(app)
            % Button was Pressed and App has data
            if(app.movementLoadDataButtonPressed && ~strcmp(app.CurrentFileSelectionLabel.Text,'Current File Selection'))
                % Check Button Press (Next Default)
                if(app.nextDataButtonPressed)
                    % Check Boundaries and Roll Over to 1 if reached
                    if(app.currentFileIndex + 1 <= app.totalSelectedFiles)
                        app.currentFileIndex = app.currentFileIndex + 1;
                    else
                        app.currentFileIndex = 1;
                    end
                else
                    % Check Boundaries and Roll Over to Last File if reached
                    if(app.currentFileIndex - 1 >= 1)
                        app.currentFileIndex = app.currentFileIndex - 1;
                    else
                        app.currentFileIndex = app.totalSelectedFiles;
                    end

                end
            % If no Button Pressed Load via Prompt
            else
                % Select File Pathway and File(s) to Process
                [app.userSelectedFilePath, app.userSelectedFile] = loadData.getTableData();
            end
        end
        
        % Calibrates Table Data per Entry
        function calibrateTableData(app,movementType)
            
            % Determine which Calibration Gain to Utilize
            if(contains(app.CalibrationTypeDropDown.Value,'Pupil'))
                if(contains(movementType,'Right'))
                    calibrationGain = app.PupilConversionFactor_RightEye.Value;
                else
                    calibrationGain = app.PupilConversionFactor_LeftEye.Value;
                end
            elseif(contains(movementType,'Right'))
                calibrationGain = app.RightEyeGainValue.Value;
            else
                calibrationGain = app.LeftEyeGainValue.Value;
            end

            %Iterate Across Columns of Table
            for tableColIndex = 1:size(app.calibratedTableDataSet,2)
                % Iterate Down Rows of Table
                for tableRowIndex = 1:size(app.calibratedTableDataSet,1)
                    % Skip if "Cal"-ibration Movement is Selected
                    if(~contains(app.calibratedTableDataSet(tableRowIndex,tableColIndex).Properties.VariableNames{1},"Cal",'IgnoreCase',true))
                        calibrationTableSubset = app.calibratedTableDataSet(tableRowIndex,tableColIndex).(app.calibratedTableDataSet(tableRowIndex,tableColIndex).Properties.VariableNames{1}){:};

                        % Skip Empty Data
                        % Short Circuit if Whole Entry is Empty
                        % Compare if Entry is NOT empty but Col Is
                        if(~isempty(calibrationTableSubset) && ~isempty(calibrationTableSubset.(append(movementType,app.CalibrationTypeDropDown.Value))))
                            calibrationTableSubset.(append(movementType,app.CalibrationTypeDropDown.Value)) = calibrationTableSubset.(append(movementType,app.CalibrationTypeDropDown.Value)) .* calibrationGain;

                            % Replace Table Entry
                            app.calibratedTableDataSet.(app.calibratedTableDataSet(tableRowIndex,tableColIndex).Properties.VariableNames{1})(tableRowIndex) = {calibrationTableSubset};
                        end
                    end
                end
            end

            % Turn on the lights its getting dark in here
            app.calibrationLightStatus(append(movementType,app.CalibrationTypeDropDown.Value));    
            % Save Calibration Gain
            app.calibrationGainTable.(strtrim(movementType))(app.CalibrationTypeDropDown.Value) = calibrationGain;
        end
        
        % Evaluates Boolean Status for Calibration Types
        function calibrationLightStatus(app, calibrationLight)
            lampFieldName = app.elementFieldNames(contains(app.elementFieldNames,append(strrep(calibrationLight,' ',''),'Lamp')));
            app.(lampFieldName{1}).Enable = 'On';
        end
        
        function updateGainSpinners(app)
            if(app.movementLoadDataButtonPressed)
                endIndex = app.maxSamples;
                startIndex = endIndex - 25;
            else
                endIndex = app.EndIndexSpinner.Value;
                startIndex = app.StartIndexSpinner.Value;
            end

            % Update End Range for Gain Evaluation
            app.EndIndexSpinner.Value = endIndex;
            % Offset Start Range for Gain Evaluation
            app.StartIndexSpinner.Value = startIndex;

            % Replot Xlines
            app.plotGainBounds;
        end
        
        function plotGainBounds(app)
            % Plot Evaluation Range
            if(~isempty(app.gainStartIndex))
                % Clear Old Plots
                delete(app.gainStartIndex);
                delete(app.gainEndIndex);
            end
            app.gainStartIndex = xline(app.EyeMovementFigure,app.StartIndexSpinner.Value,'k','GSI','LabelVerticalAlignment','middle','LabelHorizontalAlignment','left');
            hold(app.EyeMovementFigure,"on")
            app.gainEndIndex = xline(app.EyeMovementFigure,app.EndIndexSpinner.Value,'k','GEI','LabelVerticalAlignment','middle','LabelHorizontalAlignment','left');
            hold(app.EyeMovementFigure,"on")            
        end
        
        function createdFilePathway = createCalibratedDataDirectory(~, currentFilePathing)
            % Replace Key Processing Words with "CALIBRATED"
            createdFilePathway = strrep(currentFilePathing,"FILTERED_UNCALIBRATED","FILTERED_CALIBRATED");
        end
        
        function createdFilePathway = createCalibrationMetricsDirectory(~, currentFilePathing)
            % Replace Key Processing Words with "CALIBRATED"
            createdFilePathway = strrep(currentFilePathing,"FILTERED_UNCALIBRATED","CALIBRATION_METRICS");
        end
        
        function metricsTable = createMetricTable(app)
            metricsTable = cell2table({app.LeftEyeGainValue.Value,app.RightEyeGainValue.Value,(app.LeftEyeGainValue.Value+app.RightEyeGainValue.Value)/2},'VariableNames',["Left Eye Gain Value","Right Eye Gain Value","Binocular Gain Value"]);
        end
        
        function setCalibrationLamps(app,enableOnOff)
            switch enableOnOff
                case 'On'
                    lampNames = app.elementFieldNames(contains(app.elementFieldNames,"Lamp"));
                    for lampNameIndex = 1:size(lampNames,1)
                        app.(lampNames{lampNameIndex}).Enable = enableOnOff;
                    end
                case 'Off'
                    lampNames = app.elementFieldNames(contains(app.elementFieldNames,"Lamp"));
                    for lampNameIndex = 1:size(lampNames,1)
                        app.(lampNames{lampNameIndex}).Enable = enableOnOff;
                    end
            end
            
        end
        
        % Loads Gain Table Values into Gain Fields
        function loadCalibrationTableGains(app)
            % Populate Default (or Saved) Values from Gain Table
            app.LeftEyeGainValue.Value = app.calibrationGainTable.('Left Eye')(app.CalibrationTypeDropDown.Value);
            app.RightEyeGainValue.Value = app.calibrationGainTable.('Right Eye')(app.CalibrationTypeDropDown.Value);
        end

        % Validate Data is Loaded
        function boolFlag = validateDataLoad(app)
            % Check if Data is Loaded
            if(~app.dataLoadedBoolean)
                % Force Data Load
                app.LoadDataButtonPushed;                
            end

            if(~app.dataLoadedBoolean)
                boolFlag = false;
            else
                boolFlag = true;
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, pupilConversionFactorLeft, pupilConversionFactorRight, previousFigureHandle)
            % Name Figure
            app.calibrationContainer.Name = "Calibration Module";
            % Center GUI
            movegui(app.calibrationContainer,'center')
            % Populate Fieldname List
            app.elementFieldNames = fieldnames(app);
            % Update Pupil Calibration Factor with Input Argument
            app.PupilConversionFactor_LeftEye.Value = pupilConversionFactorLeft;
            app.PupilConversionFactor_RightEye.Value = pupilConversionFactorRight;
            % Hold Figure Handle to Reopen On Close
            app.previousFigureHandle = previousFigureHandle;
        end

        % Button pushed function: LoadDataButton
        function LoadDataButtonPushed(app, event)
            % Identify Table Pathing to Load
            app.getTablePathing;

            % Set Defaults Until Proven
            app.calibrationGainTable = array2table(ones(3,2),'VariableNames',["Left Eye","Right Eye"],'RowNames',["Horizontal", "Vertical", "Pupil"]);

            % Red Light
            app.DataSavedLamp.Enable = 'off';

            % Validate Selection
            if(~isempty(app.userSelectedFilePath))
                % Default Load First File in Stack
                % Only Single File Selected
                if(ischar(app.userSelectedFile))

                    % If File Exists for Saved Data Load Saved Data
                    if(isfile(fullfile(app.createCalibrationMetricsDirectory(app.userSelectedFilePath),app.userSelectedFile)))
                        % Load Saved Metrics File to Gain Table
                        app.calibrationGainTable = loadData.loadTableData(app.createCalibrationMetricsDirectory(app.userSelectedFilePath),app.userSelectedFile);

                        % Turn on Calibration Lamos
                        app.setCalibrationLamps('On')
                        % Give it the Green Light
                        app.DataSavedLamp.Enable = 'on';

                    % Else Load Uncalibrated Table Data
                    else
                        % Turn off Calibration Lamps
                        app.setCalibrationLamps('Off')
                        
                    end

                    % Populate Labels
                    app.LeftEyeGainValue.Value = app.calibrationGainTable.('Left Eye')(app.CalibrationTypeDropDown.Value);
                    app.RightEyeGainValue.Value = app.calibrationGainTable.('Right Eye')(app.CalibrationTypeDropDown.Value);

                    % Load Raw Table
                    app.tableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile);
                    
                    % Set Number of Files to 1
                    app.currentFileIndex = 1;
                    app.totalSelectedFiles = 1;

                    % Update label with current data selection
                    app.CurrentFileSelectionLabel.Text = app.userSelectedFile;

                    % Update File Index Labels
                    app.totalFileCountLabel.Text = num2str(app.totalSelectedFiles);

                    % Update Load Boolean Flag
                    app.dataLoadedBoolean = true;

                % Multiple Files Selected
                else
                    % If New Data Loaded from Selection
                    if (~app.movementLoadDataButtonPressed)
                        % Reset Current Index for New Loaded Data
                        app.currentFileIndex = 1;
                        % Set Total Number of Files
                        app.totalSelectedFiles = size(app.userSelectedFile,1);
                        % Update File Index Labels
                        app.totalFileCountLabel.Text = num2str(app.totalSelectedFiles);
                    end

                    % Evaluate if Saved Data for Selection Exists
                    if(isfile(fullfile(app.createCalibrationMetricsDirectory(app.userSelectedFilePath),app.userSelectedFile{app.currentFileIndex})))
                        % Load Saved Metrics File to Gain Table
                        app.calibrationGainTable = loadData.loadTableData(app.createCalibrationMetricsDirectory(app.userSelectedFilePath),app.userSelectedFile{app.currentFileIndex});

                        % Turn off Calibration Lamos
                        app.setCalibrationLamps('On');
                        % Give it the Green Light
                        app.DataSavedLamp.Enable = 'on';

                    % Else Load Uncalibrated Table Data
                    else
                        % Turn off Calibration Lamos
                        app.setCalibrationLamps('Off')
                        % Give it the Red Light
                        app.DataSavedLamp.Enable = 'Off';
                    end

                    % Load Raw Table
                    app.tableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile{app.currentFileIndex});

                    % Update Load Boolean Flag
                    app.dataLoadedBoolean = true;

                    % Populate Labels
                    app.LeftEyeGainValue.Value = app.calibrationGainTable.('Left Eye')(app.CalibrationTypeDropDown.Value);
                    app.RightEyeGainValue.Value = app.calibrationGainTable.('Right Eye')(app.CalibrationTypeDropDown.Value);
    
                    % Update label with current data selection
                    app.CurrentFileSelectionLabel.Text = app.userSelectedFile{app.currentFileIndex};
                end

                % Set Calibrated Data to Extracted Data
                app.calibratedTableDataSet = app.tableData;
            
                % Load Calibration Data into Dropdowns
                [calibrationIndex, calibrationNames] = app.identCals;
                app.updateDropdowns(calibrationNames, 'Calibration');
    
                % Load Movement Data
                movementNames = app.identMovements(calibrationIndex);
                app.updateDropdowns(movementNames, 'Movement');

                % Update File Index Labels
                app.updateFileIndexLabels;

                % Reset Plots
                app.resetDropdownPlotValue;

            end
        end

        % Callback function: Calibration1PositionSpinner, 
        % ...and 3 other components
        function CalibrationDropDown1ValueChanged(app, event)
            app.calibrationPlotter(1)
        end

        % Callback function: Calibration2PositionSpinner, 
        % ...and 2 other components
        function CalibrationDropDown2ValueChanged(app, event)
            app.calibrationPlotter(2)
        end

        % Callback function: Calibration3PositionSpinner, 
        % ...and 2 other components
        function CalibrationDropDown3ValueChanged(app, event)
            app.calibrationPlotter(3)            
        end

        % Callback function: Calibration4PositionSpinner, 
        % ...and 2 other components
        function CalibrationDropDown4ValueChanged(app, event)
            app.calibrationPlotter(4)
        end

        % Callback function: Calibration5PositionSpinner, 
        % ...and 2 other components
        function CalibrationDropDown5ValueChanged(app, event)
            app.calibrationPlotter(5)
        end

        % Callback function: Calibration6PositionSpinner, 
        % ...and 2 other components
        function CalibrationDropDown6ValueChanged(app, event)
            app.calibrationPlotter(6)
        end

        % Value changed function: MovementDisplayDropDown1, 
        % ...and 1 other component
        function MovementDropDown1ValueChanged(app, event)
            app.movementPlotter(1);          
        end

        % Value changed function: MovementDisplayDropDown2, 
        % ...and 1 other component
        function MovementDropDown2ValueChanged(app, event)
            app.movementPlotter(2);
        end

        % Value changed function: MovementDisplayDropDown3, 
        % ...and 1 other component
        function MovementDropDown3ValueChanged(app, event)
            app.movementPlotter(3);
        end

        % Value changed function: MovementDisplayDropDown4, 
        % ...and 1 other component
        function MovementDropDown4ValueChanged(app, event)
            app.movementPlotter(4);
        end

        % Value changed function: MovementDisplayDropDown5, 
        % ...and 1 other component
        function MovementDropDown5ValueChanged(app, event)
            app.movementPlotter(5);
        end

        % Value changed function: MovementDisplayDropDown6, 
        % ...and 1 other component
        function MovementDropDown6ValueChanged(app, event)
            app.movementPlotter(6);
        end

        % Value changed function: LeftEyeGainValue, RightEyeGainValue
        function LeftEyeGainValueValueChanged(app, event)
            app.gainButtonCalled = true;
            app.activateMovementDropdown;
            app.gainButtonCalled = false;
        end

        % Value changed function: MovementSelectionDropDown
        function MovementSelectionDropDownValueChanged(app, event)
            if(app.validateDataLoad)
                app.movementLoadDataButtonPressed = true;
                app.updateGainSpinners;
                app.gainEvaluation;
                app.movementLoadDataButtonPressed = false;
            end
        end

        % Value changed function: CalibrationTypeDropDown
        function CalibrationTypeDropDownValueChanged(app, event)
            if(app.validateDataLoad)
                app.resetDropdownPlotValue;
                app.loadCalibrationTableGains;
            end
        end

        % Button pushed function: NEXTButton
        function NEXTButtonPushed(app, event)
            app.nextDataButtonPressed = true;
            app.movementLoadDataButtonPressed = true;
            app.LoadDataButtonPushed;
            app.nextDataButtonPressed = false;
            app.movementLoadDataButtonPressed = false;
        end

        % Button pushed function: PREVIOUSButton
        function PREVIOUSButtonPushed(app, event)
            app.previousDataButtonPressed = true;
            app.movementLoadDataButtonPressed = true;
            app.LoadDataButtonPushed;
            app.previousDataButtonPressed = false;
            app.movementLoadDataButtonPressed = false;
        end

        % Button pushed function: RightEyeCalibrationButton
        function RightEyeCalibrationButtonPushed(app, event)
            if(app.validateDataLoad)
                app.calibrateTableData('Right Eye ');
            end
        end

        % Button pushed function: LeftEyeCalibrationButton
        function LeftEyeCalibrationButtonPushed(app, event)
            if(app.validateDataLoad)
                app.calibrateTableData('Left Eye ');
            end
        end

        % Button pushed function: SAVEDATAButton
        function SAVEDATAButtonPushed(app, event)
            % Check if Data is Loaded
            if(app.validateDataLoad)
                % Evaluate Single File Calibration or Batch
                if(ischar(app.userSelectedFile))
                    fileName = app.userSelectedFile;
                else
                    fileName = app.userSelectedFile{app.currentFileIndex};
                end
    
                % Save Calibrated Data to Calibration Directory
                saveFilePathway = app.createCalibratedDataDirectory(app.userSelectedFilePath);
                % Check If Directory Does Not Exist
                if(~exist(saveFilePathway,'dir'))
                    mkdir(saveFilePathway);
                end
                saveFunction.saveData(app.calibratedTableDataSet,fullfile(saveFilePathway,fileName),"-mat");
    
                % Save Calibration Metrics to Calibration Metrics Directory
                saveMetricsPathway = app.createCalibrationMetricsDirectory(app.userSelectedFilePath);
                % Check If Directory Does Not Exist
                if(~exist(saveMetricsPathway,'dir'))
                    mkdir(saveMetricsPathway);
                end
                
                saveFunction.saveData(app.calibrationGainTable,fullfile(saveMetricsPathway,fileName),"-mat");
    
                % Give it the Green Light
                app.DataSavedLamp.Enable = 'on';

                % Popup Save Notification
                uialert(app.calibrationContainer,"Save Successful","Save","Modal",true,"Icon","success")
            end
        end

        % Value changed function: EndIndexSpinner, StartIndexSpinner
        function StartIndexSpinnerValueChanged(app, event)
            if(app.validateDataLoad)
                app.updateGainSpinners;
                app.gainEvaluation;
            end
        end

        % Key press function: calibrationContainer
        function calibrationContainerKeyPress(app, event)
            switch event.Key
                case "a"
                    app.PREVIOUSButtonPushed;
                case "d"
                    app.NEXTButtonPushed;
                case "s"
                    app.SAVEDATAButtonPushed;
                case "r"
                    app.RightEyeCalibrationButtonPushed;
                case "l"
                    app.LeftEyeCalibrationButtonPushed;
                case "rightarrow"
                    app.PlotBehaviorButtonGroup.SelectedObject = app.AverageButton;
                    app.PlotBehaviorButtonGroupSelectionChanged
                case "leftarrow"
                    app.PlotBehaviorButtonGroup.SelectedObject = app.SummationButton;
                    app.PlotBehaviorButtonGroupSelectionChanged
                case "h"
                    app.CalibrationTypeDropDown.Value = "Horizontal";
                    app.CalibrationTypeDropDownValueChanged;
                case "v"
                    app.CalibrationTypeDropDown.Value = "Vertical";
                    app.CalibrationTypeDropDownValueChanged;
                case "p"
                    app.CalibrationTypeDropDown.Value = "Pupil";
                    app.CalibrationTypeDropDownValueChanged;
                case "escape"
                    app.calibrationContainerCloseRequest;
            end          
        end

        % Close request function: calibrationContainer
        function calibrationContainerCloseRequest(app, event)
            userSelection = uiconfirm(app.calibrationContainer,strcat("Close ",app.calibrationContainer.Name,"?"),"Confirm Exit","Icon","question");
            switch userSelection
                case "OK"
                    try
                        set(app.previousFigureHandle,"WindowState",'normal');
                        delete(app)
                    catch
                        delete(app)
                    end
            end            
        end

        % Value changed function: TrimmeanSpinner
        function TrimmeanSpinnerValueChanged(app, event)
            app.gainEvaluation;
        end

        % Selection changed function: PlotBehaviorButtonGroup
        function PlotBehaviorButtonGroupSelectionChanged(app, event)
            if(app.validateDataLoad)
                movementDropdownNames = app.elementFieldNames(find(contains(app.elementFieldNames,"MovementDropDown"+digitsPattern)));
                app.movementDropdownValueChange(movementDropdownNames)
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.calibrationContainer = uifigure('Visible', 'off');
            app.calibrationContainer.Name = 'REVAMP CLASSIFIER';
            app.calibrationContainer.CloseRequestFcn = createCallbackFcn(app, @calibrationContainerCloseRequest, true);
            app.calibrationContainer.KeyPressFcn = createCallbackFcn(app, @calibrationContainerKeyPress, true);
            app.CalibrationFitting = uiaxes(app.calibrationContainer);
            title(app.CalibrationFitting, 'Calibrations Regressions')
            xlabel(app.CalibrationFitting, 'Position')
            ylabel(app.CalibrationFitting, 'Recorded Measurement')
            zlabel(app.CalibrationFitting, 'Z')
            app.EyeMovementFigure = uiaxes(app.calibrationContainer);
            title(app.EyeMovementFigure, 'Movement Plots')
            xlabel(app.EyeMovementFigure, 'Samples')
            ylabel(app.EyeMovementFigure, 'Final Positoon')
            zlabel(app.EyeMovementFigure, 'Z')
            app.EyeMovementFigure.YAxisLocation = 'right';
            app.CalibrationSelectionPanel = uipanel(app.calibrationContainer);
            app.CalibrationSelectionPanel.Title = 'Calibration Selection';
            app.Calibration1DropDownLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration1DropDownLabel.Text = 'Calibration 1';
            app.Calibration2DropDownLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration2DropDownLabel.Text = 'Calibration 2';
            app.Calibration3DropDownLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration3DropDownLabel.Text = 'Calibration 3';
            app.Calibration4DropDownLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration4DropDownLabel.Text = 'Calibration 4';
            app.Calibration5DropDownLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration5DropDownLabel.Text = 'Calibration 5';
            app.Calibration6DropDownLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration6DropDownLabel.Text = 'Calibration 6';
            app.CalibrationDropDown6 = uidropdown(app.CalibrationSelectionPanel);
            app.CalibrationDropDown6.Items = {'Select Movement'};
            app.CalibrationDropDown6.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown6ValueChanged, true);
            app.CalibrationDropDown6.Tag = 'CalibrationDropdown';
            app.CalibrationDropDown6.Value = 'Select Movement';
            app.CalibrationDropDown5 = uidropdown(app.CalibrationSelectionPanel);
            app.CalibrationDropDown5.Items = {'Select Movement'};
            app.CalibrationDropDown5.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown5ValueChanged, true);
            app.CalibrationDropDown5.Tag = 'CalibrationDropdown';
            app.CalibrationDropDown5.Value = 'Select Movement';
            app.CalibrationDropDown4 = uidropdown(app.CalibrationSelectionPanel);
            app.CalibrationDropDown4.Items = {'Select Movement'};
            app.CalibrationDropDown4.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown4ValueChanged, true);
            app.CalibrationDropDown4.Tag = 'CalibrationDropdown';
            app.CalibrationDropDown4.Value = 'Select Movement';
            app.CalibrationDropDown3 = uidropdown(app.CalibrationSelectionPanel);
            app.CalibrationDropDown3.Items = {'Select Movement'};
            app.CalibrationDropDown3.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown3ValueChanged, true);
            app.CalibrationDropDown3.Tag = 'CalibrationDropdown';
            app.CalibrationDropDown3.Value = 'Select Movement';
            app.CalibrationDropDown2 = uidropdown(app.CalibrationSelectionPanel);
            app.CalibrationDropDown2.Items = {'Select Movement'};
            app.CalibrationDropDown2.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown2ValueChanged, true);
            app.CalibrationDropDown2.Tag = 'CalibrationDropdown';
            app.CalibrationDropDown2.Value = 'Select Movement';
            app.CalibrationDropDown1 = uidropdown(app.CalibrationSelectionPanel);
            app.CalibrationDropDown1.Items = {'Select Movement'};
            app.CalibrationDropDown1.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown1ValueChanged, true);
            app.CalibrationDropDown1.Tag = 'CalibrationDropdown';
            app.CalibrationDropDown1.Value = 'Select Movement';
            app.CalibrationTypeDropDownLabel = uilabel(app.CalibrationSelectionPanel);
            app.CalibrationTypeDropDownLabel.Text = 'Calibration Type';
            app.CalibrationTypeDropDown = uidropdown(app.CalibrationSelectionPanel);
            app.CalibrationTypeDropDown.Items = {'Horizontal', 'Vertical', 'Pupil'};
            app.CalibrationTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @CalibrationTypeDropDownValueChanged, true);
            app.CalibrationTypeDropDown.Placeholder = 'Horizontal';
            app.CalibrationTypeDropDown.ClickedFcn = createCallbackFcn(app, @CalibrationDropDown1ValueChanged, true);
            app.CalibrationTypeDropDown.Value = 'Horizontal';
            app.Calibration1PositionSpinnerLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration1PositionSpinnerLabel.Text = 'Calibration 1 Position';
            app.Calibration1PositionSpinner = uispinner(app.CalibrationSelectionPanel);
            app.Calibration1PositionSpinner.Step = 0.1;
            app.Calibration1PositionSpinner.ValueChangingFcn = createCallbackFcn(app, @CalibrationDropDown1ValueChanged, true);
            app.Calibration1PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown1ValueChanged, true);
            app.Calibration2PositionSpinnerLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration2PositionSpinnerLabel.Text = 'Calibration 2 Position';
            app.Calibration2PositionSpinner = uispinner(app.CalibrationSelectionPanel);
            app.Calibration2PositionSpinner.Step = 0.1;
            app.Calibration2PositionSpinner.ValueChangingFcn = createCallbackFcn(app, @CalibrationDropDown2ValueChanged, true);
            app.Calibration2PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown2ValueChanged, true);
            app.Calibration2PositionSpinner.Value = 1;
            app.Calibration3PositionSpinnerLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration3PositionSpinnerLabel.Text = 'Calibration 3 Position';
            app.Calibration3PositionSpinner = uispinner(app.CalibrationSelectionPanel);
            app.Calibration3PositionSpinner.Step = 0.1;
            app.Calibration3PositionSpinner.ValueChangingFcn = createCallbackFcn(app, @CalibrationDropDown3ValueChanged, true);
            app.Calibration3PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown3ValueChanged, true);
            app.Calibration3PositionSpinner.Value = 3;
            app.Calibration4PositionSpinnerLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration4PositionSpinnerLabel.Text = 'Calibration 4 Position';
            app.Calibration4PositionSpinner = uispinner(app.CalibrationSelectionPanel);
            app.Calibration4PositionSpinner.Step = 0.1;
            app.Calibration4PositionSpinner.ValueChangingFcn = createCallbackFcn(app, @CalibrationDropDown4ValueChanged, true);
            app.Calibration4PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown4ValueChanged, true);
            app.Calibration4PositionSpinner.Value = 5;
            app.Calibration5PositionSpinnerLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration5PositionSpinnerLabel.Text = 'Calibration 5 Position';
            app.Calibration5PositionSpinner = uispinner(app.CalibrationSelectionPanel);
            app.Calibration5PositionSpinner.Step = 0.1;
            app.Calibration5PositionSpinner.ValueChangingFcn = createCallbackFcn(app, @CalibrationDropDown5ValueChanged, true);
            app.Calibration5PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown5ValueChanged, true);
            app.Calibration5PositionSpinner.Value = 7;
            app.Calibration6PositionSpinnerLabel = uilabel(app.CalibrationSelectionPanel);
            app.Calibration6PositionSpinnerLabel.Text = 'Calibration 6 Position';
            app.Calibration6PositionSpinner = uispinner(app.CalibrationSelectionPanel);
            app.Calibration6PositionSpinner.Step = 0.1;
            app.Calibration6PositionSpinner.ValueChangingFcn = createCallbackFcn(app, @CalibrationDropDown6ValueChanged, true);
            app.Calibration6PositionSpinner.ValueChangedFcn = createCallbackFcn(app, @CalibrationDropDown6ValueChanged, true);
            app.Calibration6PositionSpinner.Value = -999;
            app.CALIBRATIONButtonGroup = uibuttongroup(app.calibrationContainer);
            app.CALIBRATIONButtonGroup.Title = 'CALIBRATION';
            app.SelectEyetoCalibratePanel = uipanel(app.CALIBRATIONButtonGroup);
            app.SelectEyetoCalibratePanel.Title = 'Select Eye to Calibrate';
            app.RightEyeCalibrationButton = uibutton(app.SelectEyetoCalibratePanel, 'push');
            app.RightEyeCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @RightEyeCalibrationButtonPushed, true);
            app.RightEyeCalibrationButton.Text = 'Right Eye ';
            app.LeftEyeCalibrationButton = uibutton(app.SelectEyetoCalibratePanel, 'push');
            app.LeftEyeCalibrationButton.ButtonPushedFcn = createCallbackFcn(app, @LeftEyeCalibrationButtonPushed, true);
            app.LeftEyeCalibrationButton.Text = 'Left Eye ';
            app.CalibrationOverviewPanel = uipanel(app.CALIBRATIONButtonGroup);
            app.CalibrationOverviewPanel.Title = 'Calibration Overview';
            app.RightEyeVerticalLamp = uilamp(app.CalibrationOverviewPanel);
            app.RightEyeVerticalLamp.Enable = 'off';
            app.LeftEyeVerticalLamp = uilamp(app.CalibrationOverviewPanel);
            app.LeftEyeVerticalLamp.Enable = 'off';
            app.RightEyeHorizontalLamp = uilamp(app.CalibrationOverviewPanel);
            app.RightEyeHorizontalLamp.Enable = 'off';
            app.LeftEyeHorizontalLamp = uilamp(app.CalibrationOverviewPanel);
            app.LeftEyeHorizontalLamp.Enable = 'off';
            app.RightEyePupilLamp = uilamp(app.CalibrationOverviewPanel);
            app.RightEyePupilLamp.Enable = 'off';
            app.LeftEyePupilLamp = uilamp(app.CalibrationOverviewPanel);
            app.LeftEyePupilLamp.Enable = 'off';
            app.RightEyeLabel = uilabel(app.CalibrationOverviewPanel);
            app.RightEyeLabel.Text = 'Right Eye';
            app.LeftEyeLabel = uilabel(app.CalibrationOverviewPanel);
            app.LeftEyeLabel.Text = 'Left Eye';
            app.HorizontalLabel = uilabel(app.CalibrationOverviewPanel);
            app.HorizontalLabel.Text = 'Horizontal';
            app.VerticalLabel = uilabel(app.CalibrationOverviewPanel);
            app.VerticalLabel.Text = 'Vertical';
            app.PupilLabel = uilabel(app.CalibrationOverviewPanel);
            app.PupilLabel.Text = 'Pupil';
            app.LoadDataButton = uibutton(app.calibrationContainer, 'push');
            app.LoadDataButton.ButtonPushedFcn = createCallbackFcn(app, @LoadDataButtonPushed, true);
            app.LoadDataButton.Text = 'Load Data';
            app.CurrentFileSelectionLabel = uilabel(app.calibrationContainer);
            app.CurrentFileSelectionLabel.Text = 'Current File Selection';
            app.MovementSelectionPanel = uipanel(app.calibrationContainer);
            app.MovementSelectionPanel.Title = 'Movement Selection';
            app.Movement1DropDownLabel = uilabel(app.MovementSelectionPanel);
            app.Movement1DropDownLabel.Text = 'Movement 1';
            app.MovementDropDown1 = uidropdown(app.MovementSelectionPanel);
            app.MovementDropDown1.Items = {'Select Movement'};
            app.MovementDropDown1.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown1ValueChanged, true);
            app.MovementDropDown1.Value = 'Select Movement';
            app.Movement2DropDownLabel = uilabel(app.MovementSelectionPanel);
            app.Movement2DropDownLabel.Text = 'Movement 2';
            app.MovementDropDown2 = uidropdown(app.MovementSelectionPanel);
            app.MovementDropDown2.Items = {'Select Movement'};
            app.MovementDropDown2.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown2ValueChanged, true);
            app.MovementDropDown2.Value = 'Select Movement';
            app.Movement3DropDownLabel = uilabel(app.MovementSelectionPanel);
            app.Movement3DropDownLabel.Text = 'Movement 3';
            app.MovementDropDown3 = uidropdown(app.MovementSelectionPanel);
            app.MovementDropDown3.Items = {'Select Movement'};
            app.MovementDropDown3.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown3ValueChanged, true);
            app.MovementDropDown3.Value = 'Select Movement';
            app.Movement4DropDownLabel = uilabel(app.MovementSelectionPanel);
            app.Movement4DropDownLabel.Text = 'Movement 4';
            app.MovementDropDown4 = uidropdown(app.MovementSelectionPanel);
            app.MovementDropDown4.Items = {'Select Movement'};
            app.MovementDropDown4.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown4ValueChanged, true);
            app.MovementDropDown4.Value = 'Select Movement';
            app.Movement5DropDownLabel = uilabel(app.MovementSelectionPanel);
            app.Movement5DropDownLabel.Text = 'Movement 5';
            app.MovementDropDown5 = uidropdown(app.MovementSelectionPanel);
            app.MovementDropDown5.Items = {'Select Movement'};
            app.MovementDropDown5.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown5ValueChanged, true);
            app.MovementDropDown5.Value = 'Select Movement';
            app.Movement6DropDownLabel = uilabel(app.MovementSelectionPanel);
            app.Movement6DropDownLabel.Text = 'Movement 6';
            app.MovementDropDown6 = uidropdown(app.MovementSelectionPanel);
            app.MovementDropDown6.Items = {'Select Movement'};
            app.MovementDropDown6.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown6ValueChanged, true);
            app.MovementDropDown6.Value = 'Select Movement';
            app.MovementDisplayDropDownLabel = uilabel(app.MovementSelectionPanel);
            app.MovementDisplayDropDownLabel.Text = 'Movement Display';
            app.MovementDisplayDropDown1 = uidropdown(app.MovementSelectionPanel);
            app.MovementDisplayDropDown1.Items = {'Horizontal Average', 'Horizontal Ensemble', 'Vertical Average', 'Vertical Ensemble', 'Pupil Average', 'Pupil Ensemble'};
            app.MovementDisplayDropDown1.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown1ValueChanged, true);
            app.MovementDisplayDropDown1.Value = 'Horizontal Average';
            app.MovementDisplayDropDown_2Label = uilabel(app.MovementSelectionPanel);
            app.MovementDisplayDropDown_2Label.Text = 'Movement Display';
            app.MovementDisplayDropDown2 = uidropdown(app.MovementSelectionPanel);
            app.MovementDisplayDropDown2.Items = {'Horizontal Average', 'Horizontal Ensemble', 'Vertical Average', 'Vertical Ensemble', 'Pupil Average', 'Pupil Ensemble'};
            app.MovementDisplayDropDown2.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown2ValueChanged, true);
            app.MovementDisplayDropDown2.Value = 'Horizontal Ensemble';
            app.MovementDisplayDropDown_3Label = uilabel(app.MovementSelectionPanel);
            app.MovementDisplayDropDown_3Label.Text = 'Movement Display';
            app.MovementDisplayDropDown3 = uidropdown(app.MovementSelectionPanel);
            app.MovementDisplayDropDown3.Items = {'Horizontal Average', 'Horizontal Ensemble', 'Vertical Average', 'Vertical Ensemble', 'Pupil Average', 'Pupil Ensemble'};
            app.MovementDisplayDropDown3.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown3ValueChanged, true);
            app.MovementDisplayDropDown3.Value = 'Horizontal Average';
            app.MovementDisplayDropDown_4Label = uilabel(app.MovementSelectionPanel);
            app.MovementDisplayDropDown_4Label.Text = 'Movement Display';
            app.MovementDisplayDropDown4 = uidropdown(app.MovementSelectionPanel);
            app.MovementDisplayDropDown4.Items = {'Horizontal Average', 'Horizontal Ensemble', 'Vertical Average', 'Vertical Ensemble', 'Pupil Average', 'Pupil Ensemble'};
            app.MovementDisplayDropDown4.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown4ValueChanged, true);
            app.MovementDisplayDropDown4.Value = 'Horizontal Ensemble';
            app.MovementDisplayDropDown_5Label = uilabel(app.MovementSelectionPanel);
            app.MovementDisplayDropDown_5Label.Text = 'Movement Display';
            app.MovementDisplayDropDown5 = uidropdown(app.MovementSelectionPanel);
            app.MovementDisplayDropDown5.Items = {'Horizontal Average', 'Horizontal Ensemble', 'Vertical Average', 'Vertical Ensemble', 'Pupil Average', 'Pupil Ensemble'};
            app.MovementDisplayDropDown5.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown5ValueChanged, true);
            app.MovementDisplayDropDown5.Value = 'Horizontal Average';
            app.MovementDisplayDropDown_6Label = uilabel(app.MovementSelectionPanel);
            app.MovementDisplayDropDown_6Label.Text = 'Movement Display';
            app.MovementDisplayDropDown6 = uidropdown(app.MovementSelectionPanel);
            app.MovementDisplayDropDown6.Items = {'Horizontal Average', 'Horizontal Ensemble', 'Vertical Average', 'Vertical Ensemble', 'Pupil Average', 'Pupil Ensemble'};
            app.MovementDisplayDropDown6.ValueChangedFcn = createCallbackFcn(app, @MovementDropDown6ValueChanged, true);
            app.MovementDisplayDropDown6.Value = 'Horizontal Ensemble';
            app.RegressionInformationPanel = uipanel(app.calibrationContainer);
            app.RegressionInformationPanel.Title = 'Regression Information';
            app.BinocularRSquaredLabel = uilabel(app.RegressionInformationPanel);
            app.BinocularRSquaredLabel.Text = 'Combined R-Squared';
            app.BinocularRSquaredValue = uilabel(app.RegressionInformationPanel);
            app.BinocularRSquaredValue.Text = '#.###';
            app.LeftRSquaredLabel = uilabel(app.RegressionInformationPanel);
            app.LeftRSquaredLabel.Text = 'Left R-Squared';
            app.LeftRSquaredValue = uilabel(app.RegressionInformationPanel);
            app.LeftRSquaredValue.Text = '#.###';
            app.RightRSquaredLabel = uilabel(app.RegressionInformationPanel);
            app.RightRSquaredLabel.Text = 'Right R-Squared';
            app.RightRSquaredValue = uilabel(app.RegressionInformationPanel);
            app.RightRSquaredValue.Text = '#.###';
            app.LeftEyeGainLabel = uilabel(app.RegressionInformationPanel);
            app.LeftEyeGainLabel.Text = 'Left Eye Gain';
            app.RightEyeGainLabel = uilabel(app.RegressionInformationPanel);
            app.RightEyeGainLabel.Text = 'Right Eye Gain';
            app.RightEyeGainValue = uispinner(app.RegressionInformationPanel);
            app.RightEyeGainValue.Step = 0.1;
            app.RightEyeGainValue.LowerLimitInclusive = 'off';
            app.RightEyeGainValue.ValueDisplayFormat = '%.3f';
            app.RightEyeGainValue.ValueChangedFcn = createCallbackFcn(app, @LeftEyeGainValueValueChanged, true);
            app.RightEyeGainValue.Value = 1;
            app.LeftEyeGainValue = uispinner(app.RegressionInformationPanel);
            app.LeftEyeGainValue.Step = 0.1;
            app.LeftEyeGainValue.LowerLimitInclusive = 'off';
            app.LeftEyeGainValue.UpperLimitInclusive = 'off';
            app.LeftEyeGainValue.ValueDisplayFormat = '%.3f';
            app.LeftEyeGainValue.ValueChangedFcn = createCallbackFcn(app, @LeftEyeGainValueValueChanged, true);
            app.LeftEyeGainValue.Value = 1;
            app.LeftPupilGainLabel = uilabel(app.RegressionInformationPanel);
            app.LeftPupilGainLabel.Text = 'Left Pupil Gain';
            app.PupilConversionFactor_LeftEye = uieditfield(app.RegressionInformationPanel, 'numeric');
            app.PupilConversionFactor_LeftEye.ValueDisplayFormat = '%.3f';
            app.PupilConversionFactor_LeftEye.Editable = 'off';
            app.RightPupilGainLabel = uilabel(app.RegressionInformationPanel);
            app.RightPupilGainLabel.Text = 'Right Pupil Gain';
            app.PupilConversionFactor_RightEye = uieditfield(app.RegressionInformationPanel, 'numeric');
            app.PupilConversionFactor_RightEye.ValueDisplayFormat = '%.3f';
            app.PupilConversionFactor_RightEye.Editable = 'off';
            app.GainEvaluationPanel = uipanel(app.calibrationContainer);
            app.GainEvaluationPanel.Title = 'Gain Evaluation';
            app.StartIndexSpinnerLabel = uilabel(app.GainEvaluationPanel);
            app.StartIndexSpinnerLabel.Text = 'Start Index';
            app.StartIndexSpinner = uispinner(app.GainEvaluationPanel);
            app.StartIndexSpinner.Step = 250;
            app.StartIndexSpinner.Limits = [1 Inf];
            app.StartIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @StartIndexSpinnerValueChanged, true);
            app.StartIndexSpinner.Value = 1;
            app.EndIndexSpinnerLabel = uilabel(app.GainEvaluationPanel);
            app.EndIndexSpinnerLabel.Text = 'End Index';
            app.EndIndexSpinner = uispinner(app.GainEvaluationPanel);
            app.EndIndexSpinner.Step = 250;
            app.EndIndexSpinner.Limits = [1 Inf];
            app.EndIndexSpinner.ValueChangedFcn = createCallbackFcn(app, @StartIndexSpinnerValueChanged, true);
            app.EndIndexSpinner.Value = 1;
            app.BinocularLabel_2 = uilabel(app.GainEvaluationPanel);
            app.BinocularLabel_2.Text = 'Binocular';
            app.BinocularGainEvaluation = uieditfield(app.GainEvaluationPanel, 'numeric');
            app.BinocularGainEvaluation.Editable = 'off';
            app.MovementSelectionDropDownLabel = uilabel(app.GainEvaluationPanel);
            app.MovementSelectionDropDownLabel.Text = 'Movement Selection';
            app.MovementSelectionDropDown = uidropdown(app.GainEvaluationPanel);
            app.MovementSelectionDropDown.Items = {'Movement 1', 'Movement 2', 'Movement 3', 'Movement 4', 'Movement 5', 'Movement 6'};
            app.MovementSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @MovementSelectionDropDownValueChanged, true);
            app.MovementSelectionDropDown.Value = 'Movement 1';
            app.LeftEditFieldLabel = uilabel(app.GainEvaluationPanel);
            app.LeftEditFieldLabel.Text = 'Left';
            app.LeftGainEvaluation = uieditfield(app.GainEvaluationPanel, 'numeric');
            app.LeftGainEvaluation.Editable = 'off';
            app.RightEditFieldLabel = uilabel(app.GainEvaluationPanel);
            app.RightEditFieldLabel.Text = 'Right';
            app.RightGainEvaluation = uieditfield(app.GainEvaluationPanel, 'numeric');
            app.RightGainEvaluation.Editable = 'off';
            app.TrimmeanSpinnerLabel = uilabel(app.GainEvaluationPanel);
            app.TrimmeanSpinnerLabel.Text = 'Trimmean';
            app.TrimmeanSpinner = uispinner(app.GainEvaluationPanel);
            app.TrimmeanSpinner.Limits = [1 99];
            app.TrimmeanSpinner.RoundFractionalValues = 'on';
            app.TrimmeanSpinner.ValueChangedFcn = createCallbackFcn(app, @TrimmeanSpinnerValueChanged, true);
            app.TrimmeanSpinner.Value = 99;
            app.FileLabel = uilabel(app.calibrationContainer);
            app.FileLabel.Text = 'File';
            app.ofLabel = uilabel(app.calibrationContainer);
            app.ofLabel.Text = 'of';
            app.currentFileIndexLabel = uilabel(app.calibrationContainer);
            app.currentFileIndexLabel.Text = '###';
            app.totalFileCountLabel = uilabel(app.calibrationContainer);
            app.totalFileCountLabel.Text = '###';
            app.SAVEDATAButton = uibutton(app.calibrationContainer, 'push');
            app.SAVEDATAButton.ButtonPushedFcn = createCallbackFcn(app, @SAVEDATAButtonPushed, true);
            app.SAVEDATAButton.Text = 'SAVE DATA';
            app.PREVIOUSButton = uibutton(app.calibrationContainer, 'push');
            app.PREVIOUSButton.ButtonPushedFcn = createCallbackFcn(app, @PREVIOUSButtonPushed, true);
            app.PREVIOUSButton.Text = 'PREVIOUS';
            app.NEXTButton = uibutton(app.calibrationContainer, 'push');
            app.NEXTButton.ButtonPushedFcn = createCallbackFcn(app, @NEXTButtonPushed, true);
            app.NEXTButton.Text = 'NEXT';
            app.DataSavedLamp = uilamp(app.calibrationContainer);
            app.DataSavedLamp.Enable = 'off';
            app.PlotBehaviorButtonGroup = uibuttongroup(app.calibrationContainer);
            app.PlotBehaviorButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @PlotBehaviorButtonGroupSelectionChanged, true);
            app.PlotBehaviorButtonGroup.Title = 'Plot Behavior';
            app.SummationButton = uitogglebutton(app.PlotBehaviorButtonGroup);
            app.SummationButton.Text = 'Summation';
            app.SummationButton.Value = true;
            app.AverageButton = uitogglebutton(app.PlotBehaviorButtonGroup);
            app.AverageButton.Text = 'Average';
            % Show the figure after all components are created
            app.calibrationContainer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = calibrationModule(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.calibrationContainer)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.calibrationContainer)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.calibrationContainer)
        end
    end
end