classdef accommodationFileConverter < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        fileConverter                 matlab.ui.Figure
        ODPupilFoundDropDown          matlab.ui.control.DropDown
        ODPupilFoundDropDownLabel     matlab.ui.control.Label
        OSPupilFoundDropDown          matlab.ui.control.DropDown
        OSPupilFoundDropDownLabel     matlab.ui.control.Label
        RecordingTimeEditField        matlab.ui.control.EditField
        RecordingTimeEditFieldLabel   matlab.ui.control.Label
        RecordingDataEditField        matlab.ui.control.EditField
        RecordingDataEditFieldLabel   matlab.ui.control.Label
        ExperimentEditField           matlab.ui.control.EditField
        ExperimentEditField_2Label    matlab.ui.control.Label
        SessionEditField              matlab.ui.control.EditField
        SessionEditFieldLabel         matlab.ui.control.Label
        StudyNameEditField            matlab.ui.control.EditField
        StudyNameEditFieldLabel       matlab.ui.control.Label
        OperatorEditField             matlab.ui.control.EditField
        OperatorEditFieldLabel        matlab.ui.control.Label
        SubjectIDEditField            matlab.ui.control.EditField
        SubjectIDEditFieldLabel       matlab.ui.control.Label
        FileNameBuilderLabel          matlab.ui.control.Label
        TriggerLabel                  matlab.ui.control.Label
        TriggerLampLabel              matlab.ui.control.Label
        TriggerDataLamp               matlab.ui.control.Lamp
        TriggerColumnDropDown         matlab.ui.control.DropDown
        TriggerColumnDropDownLabel    matlab.ui.control.Label
        SequenceLabel                 matlab.ui.control.Label
        DataLabel                     matlab.ui.control.Label
        ConvertFileButton             matlab.ui.control.Button
        ImportSequenceLamp            matlab.ui.control.Lamp
        ImportDataLamp                matlab.ui.control.Lamp
        PlusOptixDatacsvLabel         matlab.ui.control.Label
        SequenceProtocolxlsxLabel     matlab.ui.control.Label
        ImportSequenceButton          matlab.ui.control.Button
        ODVerticalDropDown            matlab.ui.control.DropDown
        ODVerticalDropDownLabel       matlab.ui.control.Label
        ODPupilDropDown               matlab.ui.control.DropDown
        ODPupilDropDownLabel          matlab.ui.control.Label
        ODAccommodationDropDown       matlab.ui.control.DropDown
        ODAccommodationDropDownLabel  matlab.ui.control.Label
        OSVerticalDropDown            matlab.ui.control.DropDown
        OSVerticalDropDownLabel       matlab.ui.control.Label
        OSPupilDropDown               matlab.ui.control.DropDown
        OSPupilDropDownLabel          matlab.ui.control.Label
        OSAccommodationDropDown       matlab.ui.control.DropDown
        OSAccommodationDropDownLabel  matlab.ui.control.Label
        RightEyeLabel                 matlab.ui.control.Label
        LeftEyeLabel                  matlab.ui.control.Label
        ImportDataButton              matlab.ui.control.Button
        PlusOptixDataTable            matlab.ui.control.Table
    end

    
    properties (Access = private)
        previousFigureHandle;
        movementHeaderConversionSequence % Sequence Table 
        OS_ACC_BOOL = false;
        OD_ACC_BOOL = false;
        triggerData_BOOL = false;
        OS_ACC = []; 
        OS_PUP = [];
        OS_PUP_BOOL = [];
        OS_VERT = [];
        OD_ACC = [];
        OD_PUP = [];
        OD_PUP_BOOL = [];
        OD_VERT = [];
        triggerData = [];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, previousFigureHandle)
            app.previousFigureHandle = previousFigureHandle;
            app.fileConverter.Name = "PlusOptix to VisualEyes";
            movegui(app.fileConverter,'center')
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
           try
                % Set up the Import Options and import the data
                opts = delimitedTextImportOptions("NumVariables", 41);
                
                % Specify range and delimiter
                opts.DataLines = [2, Inf];
                opts.Delimiter = ";";
                
                % Specify column names and types
                opts.VariableNames = ["Timems", "Segment", "Distancem", "Brightness", "PupilFoundL", "PupilPosLX", "PupilPosLY", "PupilSizeMMLXmm", "PupilSizeMMLYmm", "PupilDiameterMMLmm", "PupilBrightnessLDU", "PurkinjeSizeLXpx", "PurkinjeSizeLYpx", "DecentrationLXpx", "DecentrationLYpx", "GazeLX", "GazeLY", "RefractionLSphereDpt", "GetAverageRefractionLContents", "GetAverageRefractionLMeanDpt", "GetAverageRefractionLStdDeviationDpt", "PupilFoundR", "PupilPosRX", "PupilPosRY", "PupilSizeMMRXmm", "PupilSizeMMRYmm", "PupilDiameterMMRmm", "PupilBrightnessRDU", "PurkinjeSizeRXpx", "PurkinjeSizeRYpx", "DecentrationRXpx", "DecentrationRYpx", "GazeRX", "GazeRY", "RefractionRSphereDpt", "GetAverageRefractionRContents", "GetAverageRefractionRMeanDpt", "GetAverageRefractionRStdDeviationDpt", "InterpupDistmm", "InterpupAngle", "Trigger"];
                opts.VariableTypes = ["double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double", "double"];
                
                % Specify file level properties
                opts.ExtraColumnsRule = "ignore";
                opts.EmptyLineRule = "read";
                
                % Specify variable properties
                opts = setvaropts(opts, "Segment", "TrimNonNumeric", true);
                opts = setvaropts(opts, "Segment", "ThousandsSeparator", ",");
                
                % Import the data
                [fileName, filePath] = uigetfile("MultiSelect","off","*.csv");
                app.PlusOptixDataTable.Data = readtable(fullfile(filePath,fileName),opts);
                app.PlusOptixDataTable.ColumnName = opts.VariableNames;
                
                % Load Data Drop Down Options
                app.OSAccommodationDropDown.Items = opts.VariableNames;
                app.OSPupilDropDown.Items = opts.VariableNames;
                app.OSPupilFoundDropDown.Items = opts.VariableNames;
                app.OSVerticalDropDown.Items = ["None",opts.VariableNames];
                
                app.ODAccommodationDropDown.Items = opts.VariableNames;
                app.ODPupilDropDown.Items = opts.VariableNames;
                app.ODPupilFoundDropDown.Items = opts.VariableNames;
                app.ODVerticalDropDown.Items= ["None",opts.VariableNames];
                
                app.TriggerColumnDropDown.Items = opts.VariableNames;
                app.PlusOptixDatacsvLabel.Text = fileName;
                app.ImportDataLamp.Enable = "on";
                

           catch
               errordlg("Import Data Table Failed", "Failed Import","modal");
           end

        end

        % Button pushed function: ImportSequenceButton
        function ImportSequenceButtonPushed(app, event)
            % Load Parsing Table
            try
                [fileName, filePath] = uigetfile("MultiSelect","off","*protocol*.xlsx");
                protocolTable = readtable(fullfile(filePath, fileName));
                app.movementHeaderConversionSequence = protocolTable;
                app.SequenceProtocolxlsxLabel.Text = fileName;
                app.ImportSequenceLamp.Enable = "on";
            catch
                errordlg("Import Sequence Table Failed", "Failed Sequence Import","modal");
            end
        end

        % Close request function: fileConverter
        function fileConverterCloseRequest(app, event)
            userSelection = uiconfirm(app.fileConverter,strcat("Close ",app.fileConverter.Name,"?"),"Confirm Exit","Icon","question");
            switch userSelection
                case "OK"
                    try
                        set(app.previousFigureHandle,"WindowState",'normal');
                        delete(app)
                    catch
                        delete(app)
                    end
            end       
        end

        % Value changed function: OSAccommodationDropDown
        function OSAccommodationDropDownValueChanged(app, event)
            app.OS_ACC = app.PlusOptixDataTable.Data.(app.OSAccommodationDropDown.Value);
            app.OS_ACC_BOOL = true;
        end

        % Value changed function: OSPupilDropDown
        function OSPupilDropDownValueChanged(app, event)
            app.OS_PUP = app.PlusOptixDataTable.Data.(app.OSPupilDropDown.Value);
        end

        % Value changed function: OSVerticalDropDown
        function OSVerticalDropDownValueChanged(app, event)
            if(~strcmp(app.OSVerticalDropDown.Value,"None"))
                app.OS_VERT = app.PlusOptixDataTable.Data.(app.OSVerticalDropDown.Value);
            end
        end

        % Value changed function: ODAccommodationDropDown
        function ODAccommodationDropDownValueChanged(app, event)
            app.OD_ACC = app.PlusOptixDataTable.Data.(app.ODAccommodationDropDown.Value);
            app.OD_ACC_BOOL = true;
        end

        % Value changed function: ODPupilDropDown
        function ODPupilDropDownValueChanged(app, event)
            app.OD_PUP = app.PlusOptixDataTable.Data.(app.ODPupilDropDown.Value);
        end

        % Value changed function: ODVerticalDropDown
        function ODVerticalDropDownValueChanged(app, event)
            if(~strcmp(app.ODVerticalDropDown.Value,"None"))
                app.OD_VERT = app.PlusOptixDataTable.Data.(app.ODVerticalDropDown.Value);
            end
        end

        % Button pushed function: ConvertFileButton
        function ConvertFileButtonPushed(app, event)
            % Check Import Lamp for Data, Sequence Lamp for Protocol
            % Check ACC for LE and RE for data
            if(strcmp(app.ImportDataLamp.Enable,"on") && strcmp(app.ImportSequenceLamp.Enable,"on") && app.OS_ACC_BOOL && app.OD_ACC_BOOL && app.triggerData_BOOL)
                try
                    % Create File Name Based on ReVAMP Standards
                    fileName = strcat(strcat(app.SubjectIDEditField.Value,app.SessionEditField.Value),"_",app.OperatorEditField.Value,"_",app.StudyNameEditField.Value,"_", app.ExperimentEditField.Value,"_",app.RecordingDataEditField.Value,"_",app.RecordingTimeEditField.Value);

                    plusoptixToVisualEyes.fileConverter(app.OD_ACC,app.OS_ACC,app.OS_VERT,app.OS_PUP,app.OS_PUP_BOOL,app.OD_VERT,app.OD_PUP,app.OS_PUP_BOOL,app.triggerData,app.movementHeaderConversionSequence,fileName);

                    % Indicate Success
                    msgbox("Conversion Successful!","modal");
                catch
                    errordlg("Data or Sequence Information Error!", "Error In Parsing!","modal")
                end
            else
                errordlg("Data or Sequence Information Missing!", "Missing Data!","modal")
            end
        end

        % Value changed function: TriggerColumnDropDown
        function TriggerColumnDropDownValueChanged(app, event)
            app.triggerData = app.PlusOptixDataTable.Data.(app.TriggerColumnDropDown.Value);
            app.triggerData_BOOL = true;
            app.TriggerDataLamp.Enable = "on";
        end

        % Value changed function: OSPupilFoundDropDown
        function OSPupilFoundDropDownValueChanged(app, event)
            app.OS_PUP_BOOL = app.PlusOptixDataTable.Data.(app.OSPupilFoundDropDown.Value);            
        end

        % Value changed function: ODPupilFoundDropDown
        function ODPupilFoundDropDownValueChanged(app, event)
            app.OD_PUP_BOOL = app.PlusOptixDataTable.Data.(app.ODPupilFoundDropDown.Value);    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.fileConverter = uifigure('Visible', 'off');
            app.fileConverter.Name = 'MATLAB App';
            app.fileConverter.CloseRequestFcn = createCallbackFcn(app, @fileConverterCloseRequest, true);
            app.PlusOptixDataTable = uitable(app.fileConverter);
            app.PlusOptixDataTable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.PlusOptixDataTable.RowName = {};
            app.ImportDataButton = uibutton(app.fileConverter, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.LeftEyeLabel = uilabel(app.fileConverter);
            app.LeftEyeLabel.Text = 'Left Eye';
            app.RightEyeLabel = uilabel(app.fileConverter);
            app.RightEyeLabel.Text = 'Right Eye';
            app.OSAccommodationDropDownLabel = uilabel(app.fileConverter);
            app.OSAccommodationDropDownLabel.Text = 'OS Accommodation';
            app.OSAccommodationDropDown = uidropdown(app.fileConverter);
            app.OSAccommodationDropDown.ValueChangedFcn = createCallbackFcn(app, @OSAccommodationDropDownValueChanged, true);
            app.OSPupilDropDownLabel = uilabel(app.fileConverter);
            app.OSPupilDropDownLabel.Text = 'OS Pupil';
            app.OSPupilDropDown = uidropdown(app.fileConverter);
            app.OSPupilDropDown.ValueChangedFcn = createCallbackFcn(app, @OSPupilDropDownValueChanged, true);
            app.OSVerticalDropDownLabel = uilabel(app.fileConverter);
            app.OSVerticalDropDownLabel.Text = 'OS Vertical';
            app.OSVerticalDropDown = uidropdown(app.fileConverter);
            app.OSVerticalDropDown.ValueChangedFcn = createCallbackFcn(app, @OSVerticalDropDownValueChanged, true);
            app.ODAccommodationDropDownLabel = uilabel(app.fileConverter);
            app.ODAccommodationDropDownLabel.Text = 'OD Accommodation';
            app.ODAccommodationDropDown = uidropdown(app.fileConverter);
            app.ODAccommodationDropDown.ValueChangedFcn = createCallbackFcn(app, @ODAccommodationDropDownValueChanged, true);
            app.ODPupilDropDownLabel = uilabel(app.fileConverter);
            app.ODPupilDropDownLabel.Text = 'OD Pupil';
            app.ODPupilDropDown = uidropdown(app.fileConverter);
            app.ODPupilDropDown.ValueChangedFcn = createCallbackFcn(app, @ODPupilDropDownValueChanged, true);
            app.ODVerticalDropDownLabel = uilabel(app.fileConverter);
            app.ODVerticalDropDownLabel.Text = 'OD Vertical';
            app.ODVerticalDropDown = uidropdown(app.fileConverter);
            app.ODVerticalDropDown.ValueChangedFcn = createCallbackFcn(app, @ODVerticalDropDownValueChanged, true);
            app.ImportSequenceButton = uibutton(app.fileConverter, 'push');
            app.ImportSequenceButton.ButtonPushedFcn = createCallbackFcn(app, @ImportSequenceButtonPushed, true);
            app.ImportSequenceButton.Text = 'Import Sequence';
            app.SequenceProtocolxlsxLabel = uilabel(app.fileConverter);
            app.SequenceProtocolxlsxLabel.Text = 'SequenceProtocol.xlsx';
            app.PlusOptixDatacsvLabel = uilabel(app.fileConverter);
            app.PlusOptixDatacsvLabel.Text = 'PlusOptixData.csv';
            app.ImportDataLamp = uilamp(app.fileConverter);
            app.ImportDataLamp.Enable = 'off';
            app.ImportSequenceLamp = uilamp(app.fileConverter);
            app.ImportSequenceLamp.Enable = 'off';
            app.ConvertFileButton = uibutton(app.fileConverter, 'push');
            app.ConvertFileButton.ButtonPushedFcn = createCallbackFcn(app, @ConvertFileButtonPushed, true);
            app.ConvertFileButton.Text = 'Convert File';
            app.DataLabel = uilabel(app.fileConverter);
            app.DataLabel.Text = 'Data';
            app.SequenceLabel = uilabel(app.fileConverter);
            app.SequenceLabel.Text = 'Sequence';
            app.TriggerColumnDropDownLabel = uilabel(app.fileConverter);
            app.TriggerColumnDropDownLabel.Text = 'Trigger Column';
            app.TriggerColumnDropDown = uidropdown(app.fileConverter);
            app.TriggerColumnDropDown.ValueChangedFcn = createCallbackFcn(app, @TriggerColumnDropDownValueChanged, true);
            app.TriggerDataLamp = uilamp(app.fileConverter);
            app.TriggerDataLamp.Enable = 'off';
            app.TriggerLampLabel = uilabel(app.fileConverter);
            app.TriggerLampLabel.Text = 'Trigger';
            app.TriggerLabel = uilabel(app.fileConverter);
            app.TriggerLabel.Text = 'Trigger';
            app.FileNameBuilderLabel = uilabel(app.fileConverter);
            app.FileNameBuilderLabel.Text = 'File Name Builder';
            app.SubjectIDEditFieldLabel = uilabel(app.fileConverter);
            app.SubjectIDEditFieldLabel.Text = 'Subject ID';
            app.SubjectIDEditField = uieditfield(app.fileConverter, 'text');
            app.SubjectIDEditField.Placeholder = 'SUB999';
            app.SubjectIDEditField.Value = 'SUB999';
            app.OperatorEditFieldLabel = uilabel(app.fileConverter);
            app.OperatorEditFieldLabel.Text = 'Operator';
            app.OperatorEditField = uieditfield(app.fileConverter, 'text');
            app.OperatorEditField.Placeholder = 'SNF';
            app.OperatorEditField.Value = 'SNF';
            app.StudyNameEditFieldLabel = uilabel(app.fileConverter);
            app.StudyNameEditFieldLabel.Text = 'Study Name';
            app.StudyNameEditField = uieditfield(app.fileConverter, 'text');
            app.StudyNameEditField.Placeholder = 'STUDY';
            app.StudyNameEditField.Value = 'STUDY';
            app.SessionEditFieldLabel = uilabel(app.fileConverter);
            app.SessionEditFieldLabel.Text = 'Session';
            app.SessionEditField = uieditfield(app.fileConverter, 'text');
            app.SessionEditField.Placeholder = 'BASELINE';
            app.SessionEditField.Value = 'BASELINE';
            app.ExperimentEditField_2Label = uilabel(app.fileConverter);
            app.ExperimentEditField_2Label.Text = 'Experiment';
            app.ExperimentEditField = uieditfield(app.fileConverter, 'text');
            app.ExperimentEditField.Placeholder = 'EXP';
            app.ExperimentEditField.Value = 'EXP';
            app.RecordingDataEditFieldLabel = uilabel(app.fileConverter);
            app.RecordingDataEditFieldLabel.Text = 'Recording Data';
            app.RecordingDataEditField = uieditfield(app.fileConverter, 'text');
            app.RecordingDataEditField.Placeholder = '12312025';
            app.RecordingDataEditField.Value = '12312025';
            app.RecordingTimeEditFieldLabel = uilabel(app.fileConverter);
            app.RecordingTimeEditFieldLabel.Text = 'Recording Time';
            app.RecordingTimeEditField = uieditfield(app.fileConverter, 'text');
            app.RecordingTimeEditField.Placeholder = '1159';
            app.RecordingTimeEditField.Value = '1159';
            app.OSPupilFoundDropDownLabel = uilabel(app.fileConverter);
            app.OSPupilFoundDropDownLabel.Text = 'OS Pupil Found';
            app.OSPupilFoundDropDown = uidropdown(app.fileConverter);
            app.OSPupilFoundDropDown.ValueChangedFcn = createCallbackFcn(app, @OSPupilFoundDropDownValueChanged, true);
            app.ODPupilFoundDropDownLabel = uilabel(app.fileConverter);
            app.ODPupilFoundDropDownLabel.Text = 'OD Pupil Found';
            app.ODPupilFoundDropDown = uidropdown(app.fileConverter);
            app.ODPupilFoundDropDown.ValueChangedFcn = createCallbackFcn(app, @ODPupilFoundDropDownValueChanged, true);
            % Show the figure after all components are created
            app.fileConverter.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = accommodationFileConverter(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.fileConverter)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.fileConverter)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.fileConverter)
        end
    end
end