classdef revampModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        revampContainer                 matlab.ui.Figure
        ANALYSISButton                  matlab.ui.control.Button
        FilterandCalibrateDataPanel     matlab.ui.container.Panel
        CalibrationSettingsPanel        matlab.ui.container.Panel
        RightLabel                      matlab.ui.control.Label
        LeftLabel                       matlab.ui.control.Label
        PupilConversionFactor_RightEye  matlab.ui.control.NumericEditField
        RecordedPixelRightEyeLabel      matlab.ui.control.Label
        PupilCalibration5Spinner_RightEye  matlab.ui.control.Spinner
        PupilCalibration4Spinner_RightEye  matlab.ui.control.Spinner
        PupilCalibration3Spinner_RightEye  matlab.ui.control.Spinner
        PupilCalibration2Spinner_RightEye  matlab.ui.control.Spinner
        PupilCalibration1Spinner_RightEye  matlab.ui.control.Spinner
        PendingInputLamp                matlab.ui.control.Lamp
        PendingInputLampLabel           matlab.ui.control.Label
        ReadyToCalibrateLamp            matlab.ui.control.Lamp
        ReadyToCalibrateLampLabel       matlab.ui.control.Label
        PupilConversionFactor_LeftEye   matlab.ui.control.NumericEditField
        ConversionFactorsLabel          matlab.ui.control.Label
        CalibrateDataButton             matlab.ui.control.Button
        PupilMeasurement5Spinner        matlab.ui.control.Spinner
        PupilMeasurement4Spinner        matlab.ui.control.Spinner
        PupilMeasurement3Spinner        matlab.ui.control.Spinner
        PupilMeasurement2Spinner        matlab.ui.control.Spinner
        PupilMeasurement1Spinner        matlab.ui.control.Spinner
        MeasuredDiametermmLabel         matlab.ui.control.Label
        RecordedPixelLeftEyeLabel       matlab.ui.control.Label
        PupilCalibration5Spinner_LeftEye  matlab.ui.control.Spinner
        PupilCalibration5SpinnerLabel   matlab.ui.control.Label
        PupilCalibration4Spinner_LeftEye  matlab.ui.control.Spinner
        PupilCalibration4SpinnerLabel   matlab.ui.control.Label
        PupilCalibration3Spinner_LeftEye  matlab.ui.control.Spinner
        PupilCalibration3SpinnerLabel   matlab.ui.control.Label
        PupilCalibration2Spinner_LeftEye  matlab.ui.control.Spinner
        PupilCalibration2SpinnerLabel   matlab.ui.control.Label
        PupilCalibration1Spinner_LeftEye  matlab.ui.control.Spinner
        PupilCalibration1SpinnerLabel   matlab.ui.control.Label
        FilterSettingsPanel             matlab.ui.container.Panel
        PlusOptixDataButton             matlab.ui.control.StateButton
        BlinkInTransientSampleThresholdPanel  matlab.ui.container.Panel
        BlinkFillMethodDropDown         matlab.ui.control.DropDown
        BlinkFillMethodDropDownLabel    matlab.ui.control.Label
        TransientSampleThresholdSpinner  matlab.ui.control.Spinner
        PrimaryCutOffFrequencyHzLabel_4  matlab.ui.control.Label
        FilterOrderSpinner              matlab.ui.control.Spinner
        FilterOrderSpinnerLabel         matlab.ui.control.Label
        FilterTypeDropDown              matlab.ui.control.DropDown
        FilterTypeDropDownLabel         matlab.ui.control.Label
        PupilFilterCutOffFrequencyPanel  matlab.ui.container.Panel
        PupilPrimaryCutOffFrequencyHzSpinner  matlab.ui.control.Spinner
        PrimaryCutOffFrequencyHzLabel_3  matlab.ui.control.Label
        SaccadeFilterCutOffFrequencyPanel  matlab.ui.container.Panel
        SaccadeSecondaryCutOffFrequencyHzSpinner  matlab.ui.control.Spinner
        SecondaryCutOffFrequencyHzLabel_2  matlab.ui.control.Label
        SaccadePrimaryCutOffFrequencyHzSpinner  matlab.ui.control.Spinner
        PrimaryCutOffFrequencyHzLabel_2  matlab.ui.control.Label
        VergenceFilterCutOffFrequencyPanel  matlab.ui.container.Panel
        VergenceSecondaryCutOffFrequencyHzSpinner  matlab.ui.control.Spinner
        SecondaryCutOffFrequencyHzLabel  matlab.ui.control.Label
        VergencePrimaryCutOffFrequencyHzSpinner  matlab.ui.control.Spinner
        PrimaryCutOffFrequencyHzLabel   matlab.ui.control.Label
        FilteringLamp                   matlab.ui.control.Lamp
        FilteringLampLabel              matlab.ui.control.Label
        FilteringCompleteLamp           matlab.ui.control.Lamp
        FilteringCompleteLampLabel      matlab.ui.control.Label
        DataAvailableLamp               matlab.ui.control.Lamp
        ImportedDataAvailableLabel      matlab.ui.control.Label
        SamplingRateHzSpinner           matlab.ui.control.Spinner
        SamplingRateHzSpinnerLabel      matlab.ui.control.Label
        SelectFilterDataButton          matlab.ui.control.Button
        FilterDataButton                matlab.ui.control.Button
        ImportDataPanel                 matlab.ui.container.Panel
        PlusOptixButton                 matlab.ui.control.StateButton
        ImportAccommodationDataButton   matlab.ui.control.Button
        ImportFailedLamp                matlab.ui.control.Lamp
        ImportFailedLampLabel           matlab.ui.control.Label
        ImportCompleteLamp              matlab.ui.control.Lamp
        ImportCompleteLampLabel         matlab.ui.control.Label
        ImportingDataLamp               matlab.ui.control.Lamp
        ImportingDataLampLabel          matlab.ui.control.Label
        ImportDataButton                matlab.ui.control.Button
    end

    
    properties (Access = private)       
        filterDataSelected % Boolean for Filter Data Selection Validation
        pupilCalibrationBoolean = false; % Default Boolean for Loaded Data
        userSelectedFile % User Selected Filename(s) for Filtering
        userSelectedFilePath % User Selected Filepath for Filtering
        accommodationFilterBoolean % Boolean for Accommodation Filter Bypass
        PlusOptixImportBoolean % Boolean for Accommodation Import Offset Bypass
    end
    
    methods (Access = private)
        % Returns Table Directory and Filename(s)
        function getTablePathing(app)
            % Select File Pathway and File(s) to Process
            [app.userSelectedFilePath, app.userSelectedFile] = loadData.getTableData();

            % Evaluate Selection
            if(~isempty(app.userSelectedFilePath))
                app.filterDataSelected = true;
            else
                app.filterDataSelected = false;
            end
        end
        
        function createdFilePathway = createDataDirectory(~, currentFilePathing, oldString, newString)
            % Replace Key Processing Words with "CALIBRATED"
            createdFilePathway = strrep(currentFilePathing,oldString,newString);
        end
        
        % Return File Name for Given Index
        function fileName = getFilename(app,fileIndex)
            fileName = app.userSelectedFile{fileIndex,1};
        end
        
        function makeDirectory(~,directoryPath)
            % Check if Filtered Data Directory Exists
            % If it does not - make directory
            if(~exist(directoryPath,'dir'))
                % If Does Not Exist Make Directory
                mkdir(directoryPath);
            end
        end
        
        function conversionFactor = calculatePupilConversionFactor(app,pupilLeftRight)
            % Create Flexible Suffix to determine which conversion factor
            % to calculate
            if(pupilLeftRight == "Left")
                spinnerSuffix = "_LeftEye";
            else
                spinnerSuffix = "_RightEye";
            end
            % Create Regression from 
            % Recorded Pupil Calibration (X)
            pupilCals = [app.(strcat("PupilCalibration1Spinner",spinnerSuffix)).Value, app.(strcat("PupilCalibration2Spinner",spinnerSuffix)).Value, app.(strcat("PupilCalibration3Spinner",spinnerSuffix)).Value, app.(strcat("PupilCalibration4Spinner",spinnerSuffix)).Value, app.(strcat("PupilCalibration5Spinner",spinnerSuffix)).Value];
            % Recorded Calibration Measurements (Y)
            pupilMeasurements = [app.PupilMeasurement1Spinner.Value, app.PupilMeasurement2Spinner.Value, app.PupilMeasurement3Spinner.Value, app.PupilMeasurement4Spinner.Value, app.PupilMeasurement5Spinner.Value];
            % Regression
            linearRegressionCoefficients = polyfit(pupilMeasurements,pupilCals,1);
            % Conversion Factor is Slope
            conversionFactor = linearRegressionCoefficients(1);
            % Update Label
            app.(strcat("PupilConversionFactor",spinnerSuffix)).Value = conversionFactor;
            % Update Pupil Boolean
            app.pupilCalibrationBoolean = true;
            % Update Graphics if both Conversion Factors are not zero
            if(app.PupilConversionFactor_LeftEye.Value~=0 && app.PupilConversionFactor_RightEye.Value~=0)
                app.PendingInputLamp.Enable = "off";
                app.ReadyToCalibrateLamp.Enable = "on";
                % Open Access to Calibration
                app.CalibrateDataButton.Enable = "on";
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.revampContainer.Name = "ReVAMP";
            movegui(app.revampContainer,'center')
            app.calculatePupilConversionFactor("Left");
            app.calculatePupilConversionFactor("Right");
        end

        % Button pushed function: ImportDataButton
        function ImportDataButtonPushed(app, event)
            % Reset Lamps
            app.ImportFailedLamp.Enable = 'off';
            app.ImportCompleteLamp.Enable = 'off';
            app.DataAvailableLamp.Enable = "off";
            app.ImportingDataLamp.Enable = 'on';
            % Attempt Preprocessing
            [~, preprocessCompleteBoolean, dataFilepaths] = revampPreprocessor.preprocessData(app.PlusOptixImportBoolean);

            % Evaluate Preprocessing Boolean Report
            if preprocessCompleteBoolean
                app.ImportingDataLamp.Enable = 'off';
                app.ImportCompleteLamp.Enable = "on";
                app.DataAvailableLamp.Enable = "on";
                app.userSelectedFile = dataFilepaths;
                app.userSelectedFilePath = [];
                app.filterDataSelected = true;
            else
                app.ImportingDataLamp.Enable = 'off';
                app.ImportFailedLamp.Enable = "on";
            end

        end

        % Value changing function: 
        % VergenceSecondaryCutOffFrequencyHzSpinner
        function VergenceSecondaryCutOffFrequencyHzSpinnerValueChanging(app, event)
            % Secondary Cut-off only required for 'bandpass' filter type
            if(~strcmp(app.FilterTypeDropDown.Value,'bandpass'))
                app.VergenceSecondaryCutOffFrequencyHzSpinner.Value = 0;
            end
        end

        % Value changed function: VergenceSecondaryCutOffFrequencyHzSpinner
        function VergenceSecondaryCutOffFrequencyHzSpinnerValueChanged(app, event)
            % Secondary Cut-off only required for 'bandpass' filter type
            if(~strcmp(app.FilterTypeDropDown.Value,'bandpass'))
                app.VergenceSecondaryCutOffFrequencyHzSpinner.Value = 0;
            end
        end

        % Button pushed function: CalibrateDataButton
        function CalibrateDataButtonPushed(app, event)
            set(app.revampContainer,"WindowState",'minimized');
            calibrationModule(app.PupilConversionFactor_LeftEye.Value,app.PupilConversionFactor_RightEye.Value, app.revampContainer);
        end

        % Button pushed function: SelectFilterDataButton
        function SelectFilterDataButtonPushed(app, event)
            % Prompt User for File Selection(s)
            app.getTablePathing;

            % Validate Selection
            if(app.filterDataSelected)
                app.DataAvailableLamp.Enable = "on";
                app.FilteringLamp.Enable = "off";
                app.FilteringCompleteLamp.Enable = "off";
            end
        end

        % Button pushed function: FilterDataButton
        function FilterDataButtonPushed(app, event)
            % Reset on Completion Lamp
            app.DataAvailableLamp.Enable = "off";
            app.FilteringCompleteLamp.Enable = "off";
            app.FilteringLamp.Enable = "on";
            % Hold On A Milisecond
            pause(.001);
            
            % Validate Data Selection for Filtering
            if(app.filterDataSelected)

                % Create Filtered Data Directory Pathing
                if(isempty(app.userSelectedFilePath) && ischar(app.userSelectedFile))
                    tempDirectoryPathing = strsplit(app.userSelectedFile,'\');
                    app.userSelectedFile = tempDirectoryPathing{end};
                    app.userSelectedFilePath = fullfile(tempDirectoryPathing{1:end-1});

                    % Generate Filtered Data Directory
                    filteredFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","FILTERED_UNCALIBRATED");
                    app.makeDirectory(filteredFilePathing);

                    % Generate Blink in Transient Data Directory
                    blinkInTransientFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_IN_TRANSIENT");
                    app.makeDirectory(blinkInTransientFilePathing);

                    % Generate Blink Count Data Directory
                    blinkCountFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_COUNT");
                    app.makeDirectory(blinkCountFilePathing);

                    % Generate Blink Count Data Directory
                    blinkIndexFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_INDEX");
                    app.makeDirectory(blinkIndexFilePathing);

                elseif(isempty(app.userSelectedFilePath))
                    % Discover Root Path
                    tempRootDirectoryPathing = strsplit(app.userSelectedFile{1},'\');
                    tempRootDirectoryPathing = fullfile(tempRootDirectoryPathing{1:end-2});
                    app.userSelectedFilePath = tempRootDirectoryPathing;
                    filteredFilePathing = app.createDataDirectory(tempRootDirectoryPathing,"UNFILTERED_UNCALIBRATED","FILTERED_UNCALIBRATED");
                    blinkInTransientFilePathing = app.createDataDirectory(tempRootDirectoryPathing,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_IN_TRANSIENT");
                    blinkCountFilePathing = app.createDataDirectory(tempRootDirectoryPathing,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_COUNT");
                    blinkIndexFilePathing = app.createDataDirectory(tempRootDirectoryPathing,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_INDEX");

                    % Break Pathing into Principal Components
                    tempDirectoryPathing = cellfun(@(fullPathCell) strsplit(fullPathCell,'\'),app.userSelectedFile,'UniformOutput',false);

                    % Extract Subject and Experiment Assign Selected Files
                    app.userSelectedFile = cellfun(@(extractedFullPathway) fullfile(extractedFullPathway{size(extractedFullPathway,2)-1:size(extractedFullPathway,2)}),tempDirectoryPathing,'UniformOutput',false);
                    
                    % Create Directories for Saving
                    tempExperimentNames = cellfun(@(extractedFullPathway) extractedFullPathway{size(extractedFullPathway,2)-1},tempDirectoryPathing,'UniformOutput',false);

                    % Spooky - Makes Unique Folder Directories from
                    % Discovered Experiment Names with Root Directory for
                    % Filtered Data
                    cellfun(@(experimentNames) app.makeDirectory(app.createDataDirectory(fullfile(tempRootDirectoryPathing,experimentNames),"UNFILTERED_UNCALIBRATED","FILTERED_UNCALIBRATED")),unique(tempExperimentNames),'UniformOutput',false);
                    % Discovered Experiment Names with Root Directory for
                    % Blinks in Transient
                    cellfun(@(experimentNames) app.makeDirectory(app.createDataDirectory(fullfile(tempRootDirectoryPathing,experimentNames),"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_IN_TRANSIENT")),unique(tempExperimentNames),'UniformOutput',false);
                    % Discovered Experiment Names with Root Directory for
                    % Blinks in Transient
                    cellfun(@(experimentNames) app.makeDirectory(app.createDataDirectory(fullfile(tempRootDirectoryPathing,experimentNames),"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_COUNT")),unique(tempExperimentNames),'UniformOutput',false);
                    % Blinks in Transient
                    cellfun(@(experimentNames) app.makeDirectory(app.createDataDirectory(fullfile(tempRootDirectoryPathing,experimentNames),"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_INDEX")),unique(tempExperimentNames),'UniformOutput',false);
                else
                    % Generate Filtered Data Directory
                    filteredFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","FILTERED_UNCALIBRATED");
                    app.makeDirectory(filteredFilePathing);

                    % Generate Blinks in Transient Data Directory
                    blinkInTransientFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_IN_TRANSIENT");
                    app.makeDirectory(blinkInTransientFilePathing);

                    % Generate Blinks in Data Directory
                    blinkCountFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_COUNT");
                    app.makeDirectory(blinkCountFilePathing);

                    % Generate Blinks in Data Directory
                    blinkIndexFilePathing = app.createDataDirectory(app.userSelectedFilePath,"UNFILTERED_UNCALIBRATED","BLINK_DATA\BLINK_INDEX");
                    app.makeDirectory(blinkIndexFilePathing);
                end
             
                % Assess if Single File Selected
                if(ischar(app.userSelectedFile))
                    % Load Table
                    tableData = loadData.loadTableData(app.userSelectedFilePath,app.userSelectedFile);
                    % Filter Table
                    [filteredTableData, blinkInTransientMatrix, blinkInDataMatrix, blinkIndexPairs] = filterTable.filterTableData(tableData,app.FilterTypeDropDown.Value,app.FilterOrderSpinner.Value, ...
                        app.VergencePrimaryCutOffFrequencyHzSpinner.Value,app.VergenceSecondaryCutOffFrequencyHzSpinner.Value, ...
                        app.SaccadePrimaryCutOffFrequencyHzSpinner.Value,app.SaccadeSecondaryCutOffFrequencyHzSpinner.Value,...
                        app.SamplingRateHzSpinner.Value,app.TransientSampleThresholdSpinner.Value,app.PupilPrimaryCutOffFrequencyHzSpinner.Value,app.BlinkFillMethodDropDown.Value, app.accommodationFilterBoolean);
                    % Save Filtered Table Data
                    saveFunction.saveData(filteredTableData,fullfile(filteredFilePathing,app.userSelectedFile),"-mat");
                    % Save Blink In Transient Boolean Data
                    saveFunction.saveData(blinkInTransientMatrix,fullfile(blinkInTransientFilePathing,app.userSelectedFile),"-mat");
                    % Save Blink In Data
                    saveFunction.saveData(blinkInDataMatrix,fullfile(blinkCountFilePathing,app.userSelectedFile),"-mat");
                    % Save Blink Index Data
                    saveFunction.saveData(blinkIndexPairs,fullfile(blinkIndexFilePathing,app.userSelectedFile),"-mat");

                    % Turn on Completion Lamp
                    app.FilteringLamp.Enable = "off";
                    app.FilteringCompleteLamp.Enable = "on";

                % Else (Parallel-Processing) Multiple Files
                else
                    % Create Local Copies of Filenames and Pathing
                    localFileNameList = app.userSelectedFile;
                    localFilePath = app.userSelectedFilePath;
                    filterType = app.FilterTypeDropDown.Value;
                    filterOrder = app.FilterOrderSpinner.Value;
                    filterVergencePrimaryCutOff = app.VergencePrimaryCutOffFrequencyHzSpinner.Value;
                    filterVergenceSecondaryCutOff = app.VergenceSecondaryCutOffFrequencyHzSpinner.Value;
                    filterSaccadePrimaryCutOff = app.SaccadePrimaryCutOffFrequencyHzSpinner.Value;
                    filterSaccadeSecondaryCutOff = app.SaccadeSecondaryCutOffFrequencyHzSpinner.Value;
                    filterSamplingRate = app.SamplingRateHzSpinner.Value;
                    transientSampleThreshold = app.TransientSampleThresholdSpinner.Value;
                    filterPupilPrimary = app.PupilPrimaryCutOffFrequencyHzSpinner.Value;
                    blinkFillMethod = app.BlinkFillMethodDropDown.Value;
                    accommodationDataBoolean =  app.accommodationFilterBoolean;

                    parWorkers = parpool(feature('numcores'));
                    % Iterate through File Selections
                    parfor (currentFileIndex = 1:size(app.userSelectedFile,1),parWorkers)
                        % Load Table
                        tableData = loadData.loadTableData(localFilePath,localFileNameList{currentFileIndex});
                        % Filter Table
                        [filteredTableData, blinkInTransientMatrix, blinkInDataMatrix, blinkIndexPairs] = filterTable.filterTableData(tableData,filterType,filterOrder,...
                            filterVergencePrimaryCutOff,filterVergenceSecondaryCutOff, ...
                            filterSaccadePrimaryCutOff,filterSaccadeSecondaryCutOff, ...
                            filterSamplingRate, ...
                            transientSampleThreshold, ...
                            filterPupilPrimary, ...
                            blinkFillMethod, ...
                            accommodationDataBoolean);
                        % Save Filtered Table Data
                        saveFunction.saveData(filteredTableData,fullfile(filteredFilePathing,localFileNameList{currentFileIndex}),"-mat")
                        % Save Blink In Transient Boolean Data
                        saveFunction.saveData(blinkInTransientMatrix,fullfile(blinkInTransientFilePathing,localFileNameList{currentFileIndex}),"-mat");
                        % Save Blink In Data
                        saveFunction.saveData(blinkInDataMatrix,fullfile(blinkCountFilePathing,localFileNameList{currentFileIndex}),"-mat");  
                        % Save Blink Index Data
                        saveFunction.saveData(blinkIndexPairs,fullfile(blinkIndexFilePathing,localFileNameList{currentFileIndex}),"-mat");  
                    end
                    
                    % Delete Parallel Pool
                    delete(parWorkers);
                    
                    % Turn on Completion Lamp
                    app.FilteringLamp.Enable = "off";
                    app.FilteringCompleteLamp.Enable = "on";
                end
            % Else Direct User to Re-Select Data for Filtering
            else
                app.SelectFilterDataButtonPushed;
                
                % Evaluate Reselection Boolean
                if app.filterDataSelected
                    % Turn on Completion Lamp
                    app.ImportedDataAvailableLamp.Enable = "on";

                else
                    app.FilteringLamp.Enable = "off";
                    app.FilteringCompleteLamp.Enable = "off";
                end
            end
        end

        % Button pushed function: ANALYSISButton
        function ANALYSISButtonPushed(app, event)
            set(app.revampContainer,"WindowState",'minimized');
            mainAnalysisModule(app.revampContainer);
        end

        % Value changed function: PupilCalibration1Spinner_RightEye, 
        % ...and 4 other components
        function PupilCalibration1Spinner_RightEyeValueChanged(app, event)
           app.calculatePupilConversionFactor("Right");
        end

        % Value changed function: PupilCalibration1Spinner_LeftEye, 
        % ...and 4 other components
        function PupilCalibration1Spinner_LeftEyeValueChanged(app, event)
            app.calculatePupilConversionFactor("Left");
        end

        % Value changed function: PupilMeasurement1Spinner, 
        % ...and 4 other components
        function PupilMeasurement1SpinnerValueChanged(app, event)
            app.calculatePupilConversionFactor("Left");
            app.calculatePupilConversionFactor("Right");
        end

        % Key press function: revampContainer
        function revampContainerKeyPress(app, event)
            switch event.Key
                case "i"
                    app.ImportDataButtonPushed;
                case "f"
                    app.FilterDataButtonPushed;
                case "c"
                    app.CalibrateDataButtonPushed;
                case "a"
                    app.ANALYSISButtonPushed;
                case "escape"
                    app.revampContainerCloseRequest;
            end
            
        end

        % Close request function: revampContainer
        function revampContainerCloseRequest(app, event)
            userSelection = uiconfirm(app.revampContainer,strcat("Close ",app.revampContainer.Name,"?"),"Confirm Exit","Icon","question");
            switch userSelection
                case "OK"
                    delete(app)
            end            
        end

        % Button pushed function: ImportAccommodationDataButton
        function ImportAccommodationDataButtonPushed(app, event)
            set(app.revampContainer,"WindowState",'minimized');
            app.PlusOptixButton.Value = 1;
            accommodationFileConverter(app.revampContainer);
        end

        % Value changed function: PlusOptixDataButton
        function PlusOptixDataButtonValueChanged(app, event)
            app.accommodationFilterBoolean = app.PlusOptixDataButton.Value;
        end

        % Value changed function: PlusOptixButton
        function PlusOptixButtonValueChanged(app, event)
            app.PlusOptixImportBoolean = app.PlusOptixButton.Value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.revampContainer = uifigure('Visible', 'off');
            app.revampContainer.Name = 'REVAMP';
            app.revampContainer.CloseRequestFcn = createCallbackFcn(app, @revampContainerCloseRequest, true);
            app.revampContainer.KeyPressFcn = createCallbackFcn(app, @revampContainerKeyPress, true);
            app.ImportDataPanel = uipanel(app.revampContainer);
            app.ImportDataPanel.Title = 'Import Data';
            app.ImportDataButton = uibutton(app.ImportDataPanel, 'push');
            app.ImportDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportDataButtonPushed, true);
            app.ImportDataButton.Text = 'Import Data';
            app.ImportingDataLampLabel = uilabel(app.ImportDataPanel);
            app.ImportingDataLampLabel.Text = 'Importing Data';
            app.ImportingDataLamp = uilamp(app.ImportDataPanel);
            app.ImportingDataLamp.Enable = 'off';
            app.ImportCompleteLampLabel = uilabel(app.ImportDataPanel);
            app.ImportCompleteLampLabel.Text = 'Import Complete';
            app.ImportCompleteLamp = uilamp(app.ImportDataPanel);
            app.ImportCompleteLamp.Enable = 'off';
            app.ImportFailedLampLabel = uilabel(app.ImportDataPanel);
            app.ImportFailedLampLabel.Text = 'Import Failed';
            app.ImportFailedLamp = uilamp(app.ImportDataPanel);
            app.ImportFailedLamp.Enable = 'off';
            app.ImportAccommodationDataButton = uibutton(app.ImportDataPanel, 'push');
            app.ImportAccommodationDataButton.ButtonPushedFcn = createCallbackFcn(app, @ImportAccommodationDataButtonPushed, true);
            app.ImportAccommodationDataButton.Text = 'Import Accommodation Data';
            app.PlusOptixButton = uibutton(app.ImportDataPanel, 'state');
            app.PlusOptixButton.ValueChangedFcn = createCallbackFcn(app, @PlusOptixButtonValueChanged, true);
            app.PlusOptixButton.Text = 'PlusOptix Data';
            app.FilterandCalibrateDataPanel = uipanel(app.revampContainer);
            app.FilterandCalibrateDataPanel.Title = 'Filter and Calibrate Data';
            app.FilterSettingsPanel = uipanel(app.FilterandCalibrateDataPanel);
            app.FilterSettingsPanel.Title = 'Filter Settings';
            app.FilterDataButton = uibutton(app.FilterSettingsPanel, 'push');
            app.FilterDataButton.ButtonPushedFcn = createCallbackFcn(app, @FilterDataButtonPushed, true);
            app.FilterDataButton.Text = 'Filter Data';
            app.SelectFilterDataButton = uibutton(app.FilterSettingsPanel, 'push');
            app.SelectFilterDataButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFilterDataButtonPushed, true);
            app.SelectFilterDataButton.Text = 'Select Data';
            app.SamplingRateHzSpinnerLabel = uilabel(app.FilterSettingsPanel);
            app.SamplingRateHzSpinnerLabel.Text = 'Sampling Rate (Hz)';
            app.SamplingRateHzSpinner = uispinner(app.FilterSettingsPanel);
            app.SamplingRateHzSpinner.Step = 250;
            app.SamplingRateHzSpinner.Limits = [0 Inf];
            app.SamplingRateHzSpinner.Value = 500;
            app.ImportedDataAvailableLabel = uilabel(app.FilterSettingsPanel);
            app.ImportedDataAvailableLabel.Text = 'Data Available';
            app.DataAvailableLamp = uilamp(app.FilterSettingsPanel);
            app.DataAvailableLamp.Enable = 'off';
            app.FilteringCompleteLampLabel = uilabel(app.FilterSettingsPanel);
            app.FilteringCompleteLampLabel.Text = 'Filtering Complete';
            app.FilteringCompleteLamp = uilamp(app.FilterSettingsPanel);
            app.FilteringCompleteLamp.Enable = 'off';
            app.FilteringLampLabel = uilabel(app.FilterSettingsPanel);
            app.FilteringLampLabel.Text = 'Filtering';
            app.FilteringLamp = uilamp(app.FilterSettingsPanel);
            app.FilteringLamp.Enable = 'off';
            app.VergenceFilterCutOffFrequencyPanel = uipanel(app.FilterSettingsPanel);
            app.VergenceFilterCutOffFrequencyPanel.Title = 'Vergence Filter Cut-Off Frequency';
            app.PrimaryCutOffFrequencyHzLabel = uilabel(app.VergenceFilterCutOffFrequencyPanel);
            app.PrimaryCutOffFrequencyHzLabel.Text = {'(Primary) '; 'Cut-Off Frequency (Hz)'};
            app.VergencePrimaryCutOffFrequencyHzSpinner = uispinner(app.VergenceFilterCutOffFrequencyPanel);
            app.VergencePrimaryCutOffFrequencyHzSpinner.Limits = [0 999];
            app.VergencePrimaryCutOffFrequencyHzSpinner.RoundFractionalValues = 'on';
            app.VergencePrimaryCutOffFrequencyHzSpinner.Value = 40;
            app.SecondaryCutOffFrequencyHzLabel = uilabel(app.VergenceFilterCutOffFrequencyPanel);
            app.SecondaryCutOffFrequencyHzLabel.Text = {'(Secondary) '; 'Cut-Off Frequency (Hz)'};
            app.VergenceSecondaryCutOffFrequencyHzSpinner = uispinner(app.VergenceFilterCutOffFrequencyPanel);
            app.VergenceSecondaryCutOffFrequencyHzSpinner.ValueChangingFcn = createCallbackFcn(app, @VergenceSecondaryCutOffFrequencyHzSpinnerValueChanging, true);
            app.VergenceSecondaryCutOffFrequencyHzSpinner.Limits = [0 999];
            app.VergenceSecondaryCutOffFrequencyHzSpinner.RoundFractionalValues = 'on';
            app.VergenceSecondaryCutOffFrequencyHzSpinner.ValueChangedFcn = createCallbackFcn(app, @VergenceSecondaryCutOffFrequencyHzSpinnerValueChanged, true);
            app.SaccadeFilterCutOffFrequencyPanel = uipanel(app.FilterSettingsPanel);
            app.SaccadeFilterCutOffFrequencyPanel.Title = 'Saccade Filter Cut-Off Frequency';
            app.PrimaryCutOffFrequencyHzLabel_2 = uilabel(app.SaccadeFilterCutOffFrequencyPanel);
            app.PrimaryCutOffFrequencyHzLabel_2.Text = {'(Primary) '; 'Cut-Off Frequency (Hz)'};
            app.SaccadePrimaryCutOffFrequencyHzSpinner = uispinner(app.SaccadeFilterCutOffFrequencyPanel);
            app.SaccadePrimaryCutOffFrequencyHzSpinner.Limits = [0 999];
            app.SaccadePrimaryCutOffFrequencyHzSpinner.RoundFractionalValues = 'on';
            app.SaccadePrimaryCutOffFrequencyHzSpinner.Value = 100;
            app.SecondaryCutOffFrequencyHzLabel_2 = uilabel(app.SaccadeFilterCutOffFrequencyPanel);
            app.SecondaryCutOffFrequencyHzLabel_2.Text = {'(Secondary) '; 'Cut-Off Frequency (Hz)'};
            app.SaccadeSecondaryCutOffFrequencyHzSpinner = uispinner(app.SaccadeFilterCutOffFrequencyPanel);
            app.SaccadeSecondaryCutOffFrequencyHzSpinner.Limits = [0 999];
            app.SaccadeSecondaryCutOffFrequencyHzSpinner.RoundFractionalValues = 'on';
            app.PupilFilterCutOffFrequencyPanel = uipanel(app.FilterSettingsPanel);
            app.PupilFilterCutOffFrequencyPanel.Title = 'Pupil Filter Cut-Off Frequency';
            app.PrimaryCutOffFrequencyHzLabel_3 = uilabel(app.PupilFilterCutOffFrequencyPanel);
            app.PrimaryCutOffFrequencyHzLabel_3.Text = {'(Primary) '; 'Cut-Off Frequency (Hz)'};
            app.PupilPrimaryCutOffFrequencyHzSpinner = uispinner(app.PupilFilterCutOffFrequencyPanel);
            app.PupilPrimaryCutOffFrequencyHzSpinner.Limits = [0 999];
            app.PupilPrimaryCutOffFrequencyHzSpinner.RoundFractionalValues = 'on';
            app.PupilPrimaryCutOffFrequencyHzSpinner.Value = 5;
            app.FilterTypeDropDownLabel = uilabel(app.FilterSettingsPanel);
            app.FilterTypeDropDownLabel.Text = 'Filter Type';
            app.FilterTypeDropDown = uidropdown(app.FilterSettingsPanel);
            app.FilterTypeDropDown.Items = {'low', 'high', 'bandpass'};
            app.FilterTypeDropDown.Placeholder = 'low';
            app.FilterTypeDropDown.Value = 'low';
            app.FilterOrderSpinnerLabel = uilabel(app.FilterSettingsPanel);
            app.FilterOrderSpinnerLabel.Text = 'Filter Order';
            app.FilterOrderSpinner = uispinner(app.FilterSettingsPanel);
            app.FilterOrderSpinner.Step = 2;
            app.FilterOrderSpinner.Limits = [1 100];
            app.FilterOrderSpinner.RoundFractionalValues = 'on';
            app.FilterOrderSpinner.Value = 3;
            app.BlinkInTransientSampleThresholdPanel = uipanel(app.FilterSettingsPanel);
            app.BlinkInTransientSampleThresholdPanel.Title = 'Blink In Transient Sample Threshold';
            app.PrimaryCutOffFrequencyHzLabel_4 = uilabel(app.BlinkInTransientSampleThresholdPanel);
            app.PrimaryCutOffFrequencyHzLabel_4.Text = 'Sample Threshold';
            app.TransientSampleThresholdSpinner = uispinner(app.BlinkInTransientSampleThresholdPanel);
            app.TransientSampleThresholdSpinner.Limits = [1 Inf];
            app.TransientSampleThresholdSpinner.RoundFractionalValues = 'on';
            app.TransientSampleThresholdSpinner.Value = 750;
            app.BlinkFillMethodDropDownLabel = uilabel(app.BlinkInTransientSampleThresholdPanel);
            app.BlinkFillMethodDropDownLabel.Text = 'Blink Fill Method';
            app.BlinkFillMethodDropDown = uidropdown(app.BlinkInTransientSampleThresholdPanel);
            app.BlinkFillMethodDropDown.Items = {'previous', 'next', 'nearest', 'linear', 'spline', 'pchip', 'makima'};
            app.BlinkFillMethodDropDown.Value = 'linear';
            app.PlusOptixDataButton = uibutton(app.FilterSettingsPanel, 'state');
            app.PlusOptixDataButton.ValueChangedFcn = createCallbackFcn(app, @PlusOptixDataButtonValueChanged, true);
            app.PlusOptixDataButton.Text = 'PlusOptix Data';
            app.CalibrationSettingsPanel = uipanel(app.FilterandCalibrateDataPanel);
            app.CalibrationSettingsPanel.Title = 'Calibration Settings';
            app.PupilCalibration1SpinnerLabel = uilabel(app.CalibrationSettingsPanel);
            app.PupilCalibration1SpinnerLabel.Text = 'Pupil Calibration #1';
            app.PupilCalibration1Spinner_LeftEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration1Spinner_LeftEye.Step = 0.001;
            app.PupilCalibration1Spinner_LeftEye.LowerLimitInclusive = 'off';
            app.PupilCalibration1Spinner_LeftEye.UpperLimitInclusive = 'off';
            app.PupilCalibration1Spinner_LeftEye.Limits = [0 999];
            app.PupilCalibration1Spinner_LeftEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration1Spinner_LeftEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_LeftEyeValueChanged, true);
            app.PupilCalibration1Spinner_LeftEye.Value = 20.19;
            app.PupilCalibration2SpinnerLabel = uilabel(app.CalibrationSettingsPanel);
            app.PupilCalibration2SpinnerLabel.Text = 'Pupil Calibration #2';
            app.PupilCalibration2Spinner_LeftEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration2Spinner_LeftEye.Step = 0.001;
            app.PupilCalibration2Spinner_LeftEye.LowerLimitInclusive = 'off';
            app.PupilCalibration2Spinner_LeftEye.UpperLimitInclusive = 'off';
            app.PupilCalibration2Spinner_LeftEye.Limits = [0 999];
            app.PupilCalibration2Spinner_LeftEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration2Spinner_LeftEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_LeftEyeValueChanged, true);
            app.PupilCalibration2Spinner_LeftEye.Value = 52.5;
            app.PupilCalibration3SpinnerLabel = uilabel(app.CalibrationSettingsPanel);
            app.PupilCalibration3SpinnerLabel.Text = 'Pupil Calibration #3';
            app.PupilCalibration3Spinner_LeftEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration3Spinner_LeftEye.Step = 0.001;
            app.PupilCalibration3Spinner_LeftEye.LowerLimitInclusive = 'off';
            app.PupilCalibration3Spinner_LeftEye.UpperLimitInclusive = 'off';
            app.PupilCalibration3Spinner_LeftEye.Limits = [0 999];
            app.PupilCalibration3Spinner_LeftEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration3Spinner_LeftEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_LeftEyeValueChanged, true);
            app.PupilCalibration3Spinner_LeftEye.Value = 105;
            app.PupilCalibration4SpinnerLabel = uilabel(app.CalibrationSettingsPanel);
            app.PupilCalibration4SpinnerLabel.Text = 'Pupil Calibration #4';
            app.PupilCalibration4Spinner_LeftEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration4Spinner_LeftEye.Step = 0.001;
            app.PupilCalibration4Spinner_LeftEye.LowerLimitInclusive = 'off';
            app.PupilCalibration4Spinner_LeftEye.UpperLimitInclusive = 'off';
            app.PupilCalibration4Spinner_LeftEye.Limits = [0 999];
            app.PupilCalibration4Spinner_LeftEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration4Spinner_LeftEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_LeftEyeValueChanged, true);
            app.PupilCalibration4Spinner_LeftEye.Value = 157.5;
            app.PupilCalibration5SpinnerLabel = uilabel(app.CalibrationSettingsPanel);
            app.PupilCalibration5SpinnerLabel.Text = 'Pupil Calibration #5';
            app.PupilCalibration5Spinner_LeftEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration5Spinner_LeftEye.Step = 0.001;
            app.PupilCalibration5Spinner_LeftEye.LowerLimitInclusive = 'off';
            app.PupilCalibration5Spinner_LeftEye.UpperLimitInclusive = 'off';
            app.PupilCalibration5Spinner_LeftEye.Limits = [0 999];
            app.PupilCalibration5Spinner_LeftEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration5Spinner_LeftEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_LeftEyeValueChanged, true);
            app.PupilCalibration5Spinner_LeftEye.Value = 210;
            app.RecordedPixelLeftEyeLabel = uilabel(app.CalibrationSettingsPanel);
            app.RecordedPixelLeftEyeLabel.Text = {'Recorded Pixel'; 'Left Eye'};
            app.MeasuredDiametermmLabel = uilabel(app.CalibrationSettingsPanel);
            app.MeasuredDiametermmLabel.Text = {'Measured Diameter'; '(mm)'};
            app.PupilMeasurement1Spinner = uispinner(app.CalibrationSettingsPanel);
            app.PupilMeasurement1Spinner.Step = 0.001;
            app.PupilMeasurement1Spinner.LowerLimitInclusive = 'off';
            app.PupilMeasurement1Spinner.UpperLimitInclusive = 'off';
            app.PupilMeasurement1Spinner.Limits = [0 999];
            app.PupilMeasurement1Spinner.ValueDisplayFormat = '%.3f';
            app.PupilMeasurement1Spinner.ValueChangedFcn = createCallbackFcn(app, @PupilMeasurement1SpinnerValueChanged, true);
            app.PupilMeasurement1Spinner.Value = 1;
            app.PupilMeasurement2Spinner = uispinner(app.CalibrationSettingsPanel);
            app.PupilMeasurement2Spinner.Step = 0.001;
            app.PupilMeasurement2Spinner.LowerLimitInclusive = 'off';
            app.PupilMeasurement2Spinner.UpperLimitInclusive = 'off';
            app.PupilMeasurement2Spinner.Limits = [0 999];
            app.PupilMeasurement2Spinner.ValueDisplayFormat = '%.3f';
            app.PupilMeasurement2Spinner.ValueChangedFcn = createCallbackFcn(app, @PupilMeasurement1SpinnerValueChanged, true);
            app.PupilMeasurement2Spinner.Value = 2.6;
            app.PupilMeasurement3Spinner = uispinner(app.CalibrationSettingsPanel);
            app.PupilMeasurement3Spinner.Step = 0.001;
            app.PupilMeasurement3Spinner.LowerLimitInclusive = 'off';
            app.PupilMeasurement3Spinner.UpperLimitInclusive = 'off';
            app.PupilMeasurement3Spinner.Limits = [0 999];
            app.PupilMeasurement3Spinner.ValueDisplayFormat = '%.3f';
            app.PupilMeasurement3Spinner.ValueChangedFcn = createCallbackFcn(app, @PupilMeasurement1SpinnerValueChanged, true);
            app.PupilMeasurement3Spinner.Value = 5.2;
            app.PupilMeasurement4Spinner = uispinner(app.CalibrationSettingsPanel);
            app.PupilMeasurement4Spinner.Step = 0.001;
            app.PupilMeasurement4Spinner.LowerLimitInclusive = 'off';
            app.PupilMeasurement4Spinner.UpperLimitInclusive = 'off';
            app.PupilMeasurement4Spinner.Limits = [0 999];
            app.PupilMeasurement4Spinner.ValueDisplayFormat = '%.3f';
            app.PupilMeasurement4Spinner.ValueChangedFcn = createCallbackFcn(app, @PupilMeasurement1SpinnerValueChanged, true);
            app.PupilMeasurement4Spinner.Value = 7.8;
            app.PupilMeasurement5Spinner = uispinner(app.CalibrationSettingsPanel);
            app.PupilMeasurement5Spinner.Step = 0.001;
            app.PupilMeasurement5Spinner.LowerLimitInclusive = 'off';
            app.PupilMeasurement5Spinner.UpperLimitInclusive = 'off';
            app.PupilMeasurement5Spinner.Limits = [0 999];
            app.PupilMeasurement5Spinner.ValueDisplayFormat = '%.3f';
            app.PupilMeasurement5Spinner.ValueChangedFcn = createCallbackFcn(app, @PupilMeasurement1SpinnerValueChanged, true);
            app.PupilMeasurement5Spinner.Value = 10.4;
            app.CalibrateDataButton = uibutton(app.CalibrationSettingsPanel, 'push');
            app.CalibrateDataButton.ButtonPushedFcn = createCallbackFcn(app, @CalibrateDataButtonPushed, true);
            app.CalibrateDataButton.Enable = 'off';
            app.CalibrateDataButton.Text = 'Calibrate Data';
            app.ConversionFactorsLabel = uilabel(app.CalibrationSettingsPanel);
            app.ConversionFactorsLabel.Text = 'Conversion Factors';
            app.PupilConversionFactor_LeftEye = uieditfield(app.CalibrationSettingsPanel, 'numeric');
            app.PupilConversionFactor_LeftEye.ValueDisplayFormat = '%.3f';
            app.PupilConversionFactor_LeftEye.Editable = 'off';
            app.ReadyToCalibrateLampLabel = uilabel(app.CalibrationSettingsPanel);
            app.ReadyToCalibrateLampLabel.Text = 'Ready To Calibrate';
            app.ReadyToCalibrateLamp = uilamp(app.CalibrationSettingsPanel);
            app.ReadyToCalibrateLamp.Enable = 'off';
            app.PendingInputLampLabel = uilabel(app.CalibrationSettingsPanel);
            app.PendingInputLampLabel.Text = 'Pending Input';
            app.PendingInputLamp = uilamp(app.CalibrationSettingsPanel);
            app.PupilCalibration1Spinner_RightEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration1Spinner_RightEye.Step = 0.001;
            app.PupilCalibration1Spinner_RightEye.LowerLimitInclusive = 'off';
            app.PupilCalibration1Spinner_RightEye.UpperLimitInclusive = 'off';
            app.PupilCalibration1Spinner_RightEye.Limits = [0 999];
            app.PupilCalibration1Spinner_RightEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration1Spinner_RightEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_RightEyeValueChanged, true);
            app.PupilCalibration1Spinner_RightEye.Value = 20.19;
            app.PupilCalibration2Spinner_RightEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration2Spinner_RightEye.Step = 0.001;
            app.PupilCalibration2Spinner_RightEye.LowerLimitInclusive = 'off';
            app.PupilCalibration2Spinner_RightEye.UpperLimitInclusive = 'off';
            app.PupilCalibration2Spinner_RightEye.Limits = [0 999];
            app.PupilCalibration2Spinner_RightEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration2Spinner_RightEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_RightEyeValueChanged, true);
            app.PupilCalibration2Spinner_RightEye.Value = 52.5;
            app.PupilCalibration3Spinner_RightEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration3Spinner_RightEye.Step = 0.001;
            app.PupilCalibration3Spinner_RightEye.LowerLimitInclusive = 'off';
            app.PupilCalibration3Spinner_RightEye.UpperLimitInclusive = 'off';
            app.PupilCalibration3Spinner_RightEye.Limits = [0 999];
            app.PupilCalibration3Spinner_RightEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration3Spinner_RightEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_RightEyeValueChanged, true);
            app.PupilCalibration3Spinner_RightEye.Value = 105;
            app.PupilCalibration4Spinner_RightEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration4Spinner_RightEye.Step = 0.001;
            app.PupilCalibration4Spinner_RightEye.LowerLimitInclusive = 'off';
            app.PupilCalibration4Spinner_RightEye.UpperLimitInclusive = 'off';
            app.PupilCalibration4Spinner_RightEye.Limits = [0 999];
            app.PupilCalibration4Spinner_RightEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration4Spinner_RightEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_RightEyeValueChanged, true);
            app.PupilCalibration4Spinner_RightEye.Value = 157.5;
            app.PupilCalibration5Spinner_RightEye = uispinner(app.CalibrationSettingsPanel);
            app.PupilCalibration5Spinner_RightEye.Step = 0.001;
            app.PupilCalibration5Spinner_RightEye.LowerLimitInclusive = 'off';
            app.PupilCalibration5Spinner_RightEye.UpperLimitInclusive = 'off';
            app.PupilCalibration5Spinner_RightEye.Limits = [0 999];
            app.PupilCalibration5Spinner_RightEye.ValueDisplayFormat = '%.3f';
            app.PupilCalibration5Spinner_RightEye.ValueChangedFcn = createCallbackFcn(app, @PupilCalibration1Spinner_RightEyeValueChanged, true);
            app.PupilCalibration5Spinner_RightEye.Value = 210;
            app.RecordedPixelRightEyeLabel = uilabel(app.CalibrationSettingsPanel);
            app.RecordedPixelRightEyeLabel.Text = {'Recorded Pixel'; 'Right Eye'};
            app.PupilConversionFactor_RightEye = uieditfield(app.CalibrationSettingsPanel, 'numeric');
            app.PupilConversionFactor_RightEye.ValueDisplayFormat = '%.3f';
            app.PupilConversionFactor_RightEye.Editable = 'off';
            app.LeftLabel = uilabel(app.CalibrationSettingsPanel);
            app.LeftLabel.Text = 'Left';
            app.RightLabel = uilabel(app.CalibrationSettingsPanel);
            app.RightLabel.Text = 'Right';
            app.ANALYSISButton = uibutton(app.revampContainer, 'push');
            app.ANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @ANALYSISButtonPushed, true);
            app.ANALYSISButton.Text = 'ANALYSIS';
            % Show the figure after all components are created
            app.revampContainer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = revampModule

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.revampContainer)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.revampContainer)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.revampContainer)
        end
    end
end