classdef mainAnalysisModule < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        mainAnalysisContainer     matlab.ui.Figure
        EXPORTANALYSISButton      matlab.ui.control.Button
        SamplingRateSpinner       matlab.ui.control.Spinner
        SamplingRateSpinnerLabel  matlab.ui.control.Label
        FFTAnalysisButton         matlab.ui.control.Button
        ANALYSISMODULELabel       matlab.ui.control.Label
        SACCADEANALYSISButton     matlab.ui.control.Button
        RAMPANALYSISButton        matlab.ui.control.Button
        STEPANALYSISButton        matlab.ui.control.Button
    end

    
    properties (Access = private)
        % Store Figure Handle to Call On-Close
        previousFigureHandle 
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, previousFigureHandle)
            app.mainAnalysisContainer.Name = "Analysis Home Page";
            movegui(app.mainAnalysisContainer,'center');
            app.previousFigureHandle = previousFigureHandle;
        end

        % Button pushed function: STEPANALYSISButton
        function STEPANALYSISButtonPushed(app, event)
            set(app.mainAnalysisContainer,"WindowState",'minimized');
            stepAnalysisModule(app.mainAnalysisContainer);
        end

        % Button pushed function: RAMPANALYSISButton
        function RAMPANALYSISButtonPushed(app, event)
            set(app.mainAnalysisContainer,"WindowState",'minimized');
            rampAnalysisModule;
        end

        % Button pushed function: SACCADEANALYSISButton
        function SACCADEANALYSISButtonPushed(app, event)
            set(app.mainAnalysisContainer,"WindowState",'minimized');
            saccadeAnalysisModule;
        end

        % Button pushed function: FFTAnalysisButton
        function FFTAnalysisButtonPushed(app, event)
            set(app.mainAnalysisContainer,"WindowState",'minimized');
            fftAnalysisModule;
        end

        % Button pushed function: EXPORTANALYSISButton
        function EXPORTANALYSISButtonPushed(app, event)
            exportMetrics.exportMetricData;
        end

        % Close request function: mainAnalysisContainer
        function mainAnalysisContainerCloseRequest(app, event)
            userSelection = uiconfirm(app.mainAnalysisContainer,strcat("Close ",app.mainAnalysisContainer.Name,"?"),"Confirm Exit","Icon","question");
            switch userSelection
                case "OK"
                    try
                        set(app.previousFigureHandle,"WindowState",'normal');
                        delete(app)
                    catch
                        delete(app)
                    end
            end            
        end

        % Key press function: mainAnalysisContainer
        function mainAnalysisContainerKeyPress(app, event)
            switch event.Key
                case "escape"
                    app.mainAnalysisContainerCloseRequest;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.mainAnalysisContainer = uifigure('Visible', 'off');
            app.mainAnalysisContainer.Name = 'MATLAB App';
            app.mainAnalysisContainer.CloseRequestFcn = createCallbackFcn(app, @mainAnalysisContainerCloseRequest, true);
            app.mainAnalysisContainer.KeyPressFcn = createCallbackFcn(app, @mainAnalysisContainerKeyPress, true);
            app.STEPANALYSISButton = uibutton(app.mainAnalysisContainer, 'push');
            app.STEPANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @STEPANALYSISButtonPushed, true);
            app.STEPANALYSISButton.Text = 'STEP ANALYSIS';
            app.RAMPANALYSISButton = uibutton(app.mainAnalysisContainer, 'push');
            app.RAMPANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @RAMPANALYSISButtonPushed, true);
            app.RAMPANALYSISButton.Text = 'RAMP ANALYSIS';
            app.SACCADEANALYSISButton = uibutton(app.mainAnalysisContainer, 'push');
            app.SACCADEANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @SACCADEANALYSISButtonPushed, true);
            app.SACCADEANALYSISButton.Text = 'SACCADE ANALYSIS';
            app.ANALYSISMODULELabel = uilabel(app.mainAnalysisContainer);
            app.ANALYSISMODULELabel.Text = 'ANALYSIS MODULE';
            app.FFTAnalysisButton = uibutton(app.mainAnalysisContainer, 'push');
            app.FFTAnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @FFTAnalysisButtonPushed, true);
            app.FFTAnalysisButton.Text = 'FFT Analysis';
            app.SamplingRateSpinnerLabel = uilabel(app.mainAnalysisContainer);
            app.SamplingRateSpinnerLabel.Text = 'Sampling Rate';
            app.SamplingRateSpinner = uispinner(app.mainAnalysisContainer);
            app.SamplingRateSpinner.Limits = [0 10000];
            app.SamplingRateSpinner.RoundFractionalValues = 'on';
            app.SamplingRateSpinner.Value = 500;
            app.EXPORTANALYSISButton = uibutton(app.mainAnalysisContainer, 'push');
            app.EXPORTANALYSISButton.ButtonPushedFcn = createCallbackFcn(app, @EXPORTANALYSISButtonPushed, true);
            app.EXPORTANALYSISButton.Text = 'EXPORT ANALYSIS';
            % Show the figure after all components are created
            app.mainAnalysisContainer.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = mainAnalysisModule(varargin)

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.mainAnalysisContainer)

                % Execute the startup function
                runStartupFcn(app, @(app)startupFcn(app, varargin{:}))
            else

                % Focus the running singleton app
                figure(runningApp.mainAnalysisContainer)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.mainAnalysisContainer)
        end
    end
end